classdef Aim2_EpochView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        LOADSleepFileMenu       matlab.ui.container.Menu
        ReadPreParamsMenu       matlab.ui.container.Menu
        SaveParametersMenu      matlab.ui.container.Menu
        TabGroup                matlab.ui.container.TabGroup
        MainWindowTab           matlab.ui.container.Tab
        DataViewButtonGroup     matlab.ui.container.ButtonGroup
        SpectrumButton          matlab.ui.control.ToggleButton
        PSDButton               matlab.ui.control.ToggleButton
        VoltageButton           matlab.ui.control.ToggleButton
        EpochForward            matlab.ui.control.Button
        EpochBackward           matlab.ui.control.Button
        EpochViewPanel          matlab.ui.container.Panel
        ChannelSwitch           matlab.ui.control.Switch
        ChannelSwitchLabel      matlab.ui.control.Label
        EpochPlot               matlab.ui.control.UIAxes
        WASOTab                 matlab.ui.container.Tab
        NNTab                   matlab.ui.container.Tab
        AccelTab                matlab.ui.container.Tab
        ArtifactTab             matlab.ui.container.Tab
        StimulationTab          matlab.ui.container.Tab
        DatawindowPanel         matlab.ui.container.Panel
        ARTIFACTEditField       matlab.ui.control.EditField
        ARTIFACTEditFieldLabel  matlab.ui.control.Label
        Accel_id                matlab.ui.control.EditField
        ACCELIDEditFieldLabel   matlab.ui.control.Label
        NN_id                   matlab.ui.control.EditField
        NNIDEditFieldLabel      matlab.ui.control.Label
        WASO_id                 matlab.ui.control.EditField
        WASOIDEditField_2Label  matlab.ui.control.Label
        WASOEditField           matlab.ui.control.NumericEditField
        WASOEditFieldLabel      matlab.ui.control.Label
        NightViewPanel          matlab.ui.container.Panel
        GridLayout              matlab.ui.container.GridLayout
        rawNIGHT_2              matlab.ui.control.UIAxes
        rawNIGHT                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainFileLoc % Description
        MainFile
        keepTable
        curEpoch
        fullNightBD01
        fullNightBD23
    end
    
    methods (Access = private)
        
        function updateVoltPlot(app)

            % Check which contact 
            checkSelect = app.ChannelSwitch.Value;

            switch checkSelect
                case 'B01' % B01
                    raw2use = app.keepTable.BiData01;
                    clean2use = app.keepTable.corrBD01;
                    % Need the numerical key
                case 'B23'
                    raw2use = app.keepTable.BiData23;
                    clean2use = app.keepTable.corrBD23;
            end

            if isempty(app.keepTable.NNscore{app.curEpoch})
                NN2use = 0;
                waso2use = 0;
            else
                NN2use = app.keepTable.NNscore{app.curEpoch}(1);
                waso2use = app.keepTable.WASOscore{app.curEpoch}(1);
            end

            if isstruct(app.keepTable.Accel_State{app.curEpoch})
                accel2use = app.keepTable.Accel_State{app.curEpoch}.min;
            else
                accel2use = 0;
            end

            % Check if empty

            if isempty(clean2use{app.curEpoch})

                cla(app.EpochPlot)
                xlim(app.EpochPlot,[0 7500])
                ylim(app.EpochPlot,[-1 1]);
                yline(app.EpochPlot,0,'-','NO DATA','LineWidth',2,...
                    'Color','k','FontSize',20,'LabelHorizontalAlignment','center')

                app.NN_id.Value = char(NN2use);
                app.WASO_id.Value = num2str(waso2use);
                app.Accel_id.Value = num2str(accel2use);

            else

                plot(app.EpochPlot,raw2use{app.curEpoch},'k')
                hold(app.EpochPlot,"on")
                plot(app.EpochPlot,clean2use{app.curEpoch},'r')
                hold(app.EpochPlot,"off")

                xlim(app.EpochPlot,[0 7500])
                minYlim = min([raw2use{app.curEpoch} ; clean2use{app.curEpoch}]);
                maxYlim = max([raw2use{app.curEpoch} ; clean2use{app.curEpoch}]);
                rangeYlim = range([raw2use{app.curEpoch} ; clean2use{app.curEpoch}]);
                fivePer = rangeYlim*0.05;
                ylim(app.EpochPlot,[minYlim - fivePer maxYlim + fivePer]);

                app.NN_id.Value = char(NN2use);

            end

            title(app.EpochPlot,['EPOCH #: ', num2str(app.curEpoch)],'FontSize',10)

            app.WASO_id.Value = num2str(waso2use);
            app.Accel_id.Value = num2str(accel2use);

            legend(app.EpochPlot,{'Raw','Clean'},...
                'Location','northwest','NumColumns',2)

            xticks(app.EpochPlot,[])
            xticklabels(app.EpochPlot,[])

            warning('off','all')

        end
        
        function updatePSDplot(app)

            cla(app.EpochPlot)

            % Check which contact
            checkSelect = app.ChannelSwitch.Value;

            switch checkSelect
                case 'B01' % B01
                    clean2use = app.keepTable.corrBD01;
                case 'B23'
                    clean2use = app.keepTable.corrBD23;
            end

            if isempty(app.keepTable.NNscore{app.curEpoch})
                NN2use = 0;
                waso2use = 0;
            else
                NN2use = app.keepTable.NNscore{app.curEpoch}(1);
                waso2use = app.keepTable.WASOscore{app.curEpoch}(1);
            end

            if isstruct(app.keepTable.Accel_State{app.curEpoch})
                accel2use = app.keepTable.Accel_State{app.curEpoch}.min;
            else
                accel2use = 0;
            end

            if isempty(clean2use{app.curEpoch}) ||...
                sum(isnan(clean2use{app.curEpoch}))/7500 > 0.75

                cla(app.EpochPlot)
                xlim(app.EpochPlot,[0 90])
                ylim(app.EpochPlot,[-1 1]);
                yline(app.EpochPlot,0,'-','NO DATA','LineWidth',2,...
                    'Color','k','FontSize',20,'LabelHorizontalAlignment','center')

                app.NN_id.Value = char(NN2use);
                app.WASO_id.Value = num2str(waso2use);
                app.Accel_id.Value = num2str(accel2use);

            else

                % compute PSD and plot
                tmpRAWlfp = clean2use{app.curEpoch};
                tmpRAWlfpNaN = tmpRAWlfp(~isnan(tmpRAWlfp));
                [pxx,fxx] = pspectrum(tmpRAWlfpNaN,250,"power",...
                    "FrequencyLimits",[0 90],"FrequencyResolution",...
                    2);
                
                convertPOWER = pow2db(pxx);
                plot(app.EpochPlot,fxx,convertPOWER,'k','LineWidth',3)
                set(app.EpochPlot, 'YDir', 'normal');

                xlim(app.EpochPlot,[0 90])

                minYlim = min(convertPOWER);
                maxYlim = max(convertPOWER);
                rangeYlim = range(convertPOWER);
                fivePer = rangeYlim*0.05;
                ylim(app.EpochPlot,[minYlim - fivePer maxYlim + fivePer]);

                % add x-axis for frequency
                xticks(app.EpochPlot,0:5:90)
                xticklabels(app.EpochPlot,0:5:90)
            end

            title(app.EpochPlot,['EPOCH #: ', num2str(app.curEpoch)],'FontSize',10)

            app.WASO_id.Value = num2str(waso2use);
            app.Accel_id.Value = num2str(accel2use);

            
        end
        
        function updateSPECplot(app)

            cla(app.EpochPlot)

            % Check which contact
            checkSelect = app.ChannelSwitch.Value;

            switch checkSelect
                case 'B01' % B01
                    clean2use = app.keepTable.corrBD01;
                case 'B23'
                    clean2use = app.keepTable.corrBD23;
            end

            if isempty(app.keepTable.NNscore{app.curEpoch})
                NN2use = 0;
                waso2use = 0;
            else
                NN2use = app.keepTable.NNscore{app.curEpoch}(1);
                waso2use = app.keepTable.WASOscore{app.curEpoch}(1);
            end

            if isstruct(app.keepTable.Accel_State{app.curEpoch})
                accel2use = app.keepTable.Accel_State{app.curEpoch}.min;
            else
                accel2use = 0;
            end


            if isempty(clean2use{app.curEpoch}) ||...
                    sum(isnan(clean2use{app.curEpoch}))/7500 > 0.75

                cla(app.EpochPlot)
                xlim(app.EpochPlot,[0 90])
                ylim(app.EpochPlot,[-1 1]);
                yline(app.EpochPlot,0,'-','NO DATA','LineWidth',2,...
                    'Color','k','FontSize',20,'LabelHorizontalAlignment','center')

                app.NN_id.Value = char(NN2use);
                app.WASO_id.Value = num2str(waso2use);
                app.Accel_id.Value = num2str(accel2use);

            else

                                % compute PSD and plot
                tmpRAWlfp = clean2use{app.curEpoch};
                tmpRAWlfpNaN = tmpRAWlfp(~isnan(tmpRAWlfp));
                [pxx,fxx,txx] = pspectrum(tmpRAWlfpNaN,250,"spectrogram",...
                    "FrequencyLimits",[0 90],"FrequencyResolution",...
                    2,"Leakage",0.8);
                
                % convertPOWER = pow2db(pxx);
                kernel_size = [4, 4];
                sigma = 2;
                kernel = fspecial('gaussian', kernel_size, sigma);

                % Apply the Gaussian filter
                smoothed_pxx = imfilter(pow2db(pxx), kernel, 'replicate');

                imagesc(app.EpochPlot, txx, fxx, smoothed_pxx)
                set(app.EpochPlot, 'YDir', 'normal');

                % plot(app.EpochPlot,fxx,convertPOWER,'k','LineWidth',3)

                xlim(app.EpochPlot,[min(txx) max(txx)])
                ylim(app.EpochPlot,[0 90]);

                % add x-axis for frequency
                yticks(app.EpochPlot,0:10:90)
                yticklabels(app.EpochPlot,90:-10:0)
            end

            legend(app.EpochPlot,'hide')

            title(app.EpochPlot,['EPOCH #: ', num2str(app.curEpoch)],'FontSize',10)

            app.WASO_id.Value = num2str(waso2use);
            app.Accel_id.Value = num2str(accel2use);


        end
        
        function updateVoltFN(app)

            cla(app.rawNIGHT)

            plotCOLOR = ([219, 39, 99])/255;

            % Check which contact
            checkSelect = app.ChannelSwitch.Value;

            switch checkSelect
                case 'B01' % B01
                    clean2use = app.fullNightBD01;
                case 'B23'
                    clean2use = app.fullNightBD23;
            end

            plot(app.rawNIGHT,clean2use,'Color',plotCOLOR,...
                'LineWidth',0.5);

            title(app.rawNIGHT,['Voltage: ', app.MainFile],...
                'FontSize',10,'Interpreter','none')

            xlim(app.rawNIGHT,[0 length(clean2use)])

            lineCOLOR = ([15, 200, 200])/255;

            xline(app.rawNIGHT,round(app.curEpoch*7000),'--','Epoch',...
                'Color',lineCOLOR,'LineWidth',2)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: LOADSleepFileMenu
        function LOADSleepFileMenuSelected(app, event)
            
            % Uiget dir to load a single night - consider additional night
            % files
            app.MainFileLoc = uigetdir();

            cd(app.MainFileLoc)

            matDir = dir('*.mat');
            matDir2 = {matDir.name};

            shortEST = cellfun(@(x) length(x), matDir2,'UniformOutput',true);

            [~ , shortLOC] = min(shortEST);
            app.MainFile = matDir2{shortLOC};

            load(app.MainFile,'FileMade')

            app.keepTable = FileMade(:,{'BiData01','BiData23','corrBD01','corrBD23','NNscore','WASOscore','PowAvg',...
                'Accel_State','Verdict','MissingA'});

            app.curEpoch = 1;

            % create full night
            app.fullNightBD01 = cell2mat(app.keepTable.corrBD01);
            app.fullNightBD23 = cell2mat(app.keepTable.corrBD23);

            updateVoltPlot(app)

            updateVoltFN(app)


        end

        % Button pushed function: EpochForward
        function EpochForwardButtonPushed(app, event)
            
            newEpochNum = app.curEpoch + 1;
            if newEpochNum > height(app.keepTable)
                return
                % create message
            else
                % change epoch number
                app.curEpoch = app.curEpoch + 1;
                % plot new epoch
                plotTYPE = app.DataViewButtonGroup.SelectedObject.Text;
                switch plotTYPE
                    case 'Voltage'
                        updateVoltPlot(app)
                    case 'PSD'
                        updatePSDplot(app)
                    case 'Spectrum'
                        updateSPECplot(app)
                end
                updateVoltFN(app)
            end

        end

        % Button pushed function: EpochBackward
        function EpochBackwardButtonPushed(app, event)
            newEpochNum = app.curEpoch - 1;
            if newEpochNum < 1
                return
                % create message
            else
                % change epoch number
                app.curEpoch = app.curEpoch - 1;
                % plot new epoch

                plotTYPE = app.DataViewButtonGroup.SelectedObject.Text;
                switch plotTYPE
                    case 'Voltage'
                        updateVoltPlot(app)
                    case 'PSD'
                        updatePSDplot(app)
                    case 'Spectrum'
                        updateSPECplot(app)
                end
                updateVoltFN(app)
            end
        end

        % Value changed function: ChannelSwitch
        function ChannelSwitchValueChanged(app, event)
                plotTYPE = app.DataViewButtonGroup.SelectedObject.Text;
                switch plotTYPE
                    case 'Voltage'
                        updateVoltPlot(app)
                    case 'PSD'
                        updatePSDplot(app)
                    case 'Spectrum'
                        updateSPECplot(app)
                end
                updateVoltFN(app)
                
        end

        % Selection changed function: DataViewButtonGroup
        function DataViewButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataViewButtonGroup.SelectedObject;

            switch selectedButton.Text
                case 'Voltage'
                    updateVoltPlot(app)
                case 'PSD'
                    updatePSDplot(app)
                case 'Spectrum'
                    updateSPECplot(app)
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LOADSleepFileMenu = uimenu(app.UIFigure);
            app.LOADSleepFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LOADSleepFileMenuSelected, true);
            app.LOADSleepFileMenu.Text = 'LOAD SleepFile';
            app.ReadPreParamsMenu = uimenu(app.UIFigure);
            app.ReadPreParamsMenu.Text = 'Read PreParams';
            app.SaveParametersMenu = uimenu(app.UIFigure);
            app.SaveParametersMenu.Text = 'Save Parameters';
            app.NightViewPanel = uipanel(app.UIFigure);
            app.NightViewPanel.Title = 'Night View';
            app.GridLayout = uigridlayout(app.NightViewPanel);
            app.rawNIGHT = uiaxes(app.GridLayout);
            app.rawNIGHT.XTick = [];
            app.rawNIGHT.YTick = [];
            app.rawNIGHT_2 = uiaxes(app.GridLayout);
            app.rawNIGHT_2.XTick = [];
            app.rawNIGHT_2.YTick = [];
            app.DatawindowPanel = uipanel(app.UIFigure);
            app.DatawindowPanel.Title = 'Data window';
            app.WASOEditFieldLabel = uilabel(app.DatawindowPanel);
            app.WASOEditFieldLabel.Text = 'WASO #';
            app.WASOEditField = uieditfield(app.DatawindowPanel, 'numeric');
            app.WASOIDEditField_2Label = uilabel(app.DatawindowPanel);
            app.WASOIDEditField_2Label.Text = 'WASO ID';
            app.WASO_id = uieditfield(app.DatawindowPanel, 'text');
            app.NNIDEditFieldLabel = uilabel(app.DatawindowPanel);
            app.NNIDEditFieldLabel.Text = 'NN ID';
            app.NN_id = uieditfield(app.DatawindowPanel, 'text');
            app.NN_id.Editable = 'off';
            app.ACCELIDEditFieldLabel = uilabel(app.DatawindowPanel);
            app.ACCELIDEditFieldLabel.Text = 'ACCEL ID';
            app.Accel_id = uieditfield(app.DatawindowPanel, 'text');
            app.ARTIFACTEditFieldLabel = uilabel(app.DatawindowPanel);
            app.ARTIFACTEditFieldLabel.Text = 'ARTIFACT';
            app.ARTIFACTEditField = uieditfield(app.DatawindowPanel, 'text');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainWindowTab = uitab(app.TabGroup);
            app.MainWindowTab.Title = 'Main Window';
            app.EpochViewPanel = uipanel(app.MainWindowTab);
            app.EpochViewPanel.Title = 'Epoch View';
            app.EpochPlot = uiaxes(app.EpochViewPanel);
            app.EpochPlot.XTick = [];
            app.EpochPlot.YTick = [];
            app.ChannelSwitchLabel = uilabel(app.EpochViewPanel);
            app.ChannelSwitchLabel.Text = 'Channel';
            app.ChannelSwitch = uiswitch(app.EpochViewPanel, 'slider');
            app.ChannelSwitch.Items = {'B01', 'B23'};
            app.ChannelSwitch.ValueChangedFcn = createCallbackFcn(app, @ChannelSwitchValueChanged, true);
            app.ChannelSwitch.Value = 'B01';
            app.EpochBackward = uibutton(app.MainWindowTab, 'push');
            app.EpochBackward.ButtonPushedFcn = createCallbackFcn(app, @EpochBackwardButtonPushed, true);
            app.EpochBackward.Text = '<<< Epoch';
            app.EpochForward = uibutton(app.MainWindowTab, 'push');
            app.EpochForward.ButtonPushedFcn = createCallbackFcn(app, @EpochForwardButtonPushed, true);
            app.EpochForward.Text = 'Epoch >>>';
            app.DataViewButtonGroup = uibuttongroup(app.MainWindowTab);
            app.DataViewButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataViewButtonGroupSelectionChanged, true);
            app.DataViewButtonGroup.Title = 'Data View';
            app.VoltageButton = uitogglebutton(app.DataViewButtonGroup);
            app.VoltageButton.Text = 'Voltage';
            app.VoltageButton.Value = true;
            app.PSDButton = uitogglebutton(app.DataViewButtonGroup);
            app.PSDButton.Text = 'PSD';
            app.SpectrumButton = uitogglebutton(app.DataViewButtonGroup);
            app.SpectrumButton.Text = 'Spectrum';
            app.WASOTab = uitab(app.TabGroup);
            app.WASOTab.Title = 'WASO';
            app.NNTab = uitab(app.TabGroup);
            app.NNTab.Title = 'NN';
            app.AccelTab = uitab(app.TabGroup);
            app.AccelTab.Title = 'Accel';
            app.ArtifactTab = uitab(app.TabGroup);
            app.ArtifactTab.Title = 'Artifact';
            app.StimulationTab = uitab(app.TabGroup);
            app.StimulationTab.Title = 'Stimulation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Aim2_EpochView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end