classdef SleepDataTTcreateByHour_v4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ChannelMenu        matlab.ui.container.Menu
        OptionsPanel       matlab.ui.container.Panel
        SmoothSwitchLabel  matlab.ui.control.Label
        smoothOp           matlab.ui.control.Switch
        LOAD               matlab.ui.control.Button
        PROCESSButton      matlab.ui.control.Button
        INFOLabel          matlab.ui.control.Label
        CONCATButton       matlab.ui.control.Button
        StatusPanel        matlab.ui.container.Panel
        tasK               matlab.ui.control.Label
        datE               matlab.ui.control.Label
    end

    
    properties (Access = private)
        fileLOC    % Sleep data Folder directory
        fileLIST   % List of .mat files for entire night 
        chanlist   % AO names for channels on ECoG module
        labelTab     % Label Table
        eegTTraw      % Time table for EEG 
        eogTTraw      % Time table for EOG
        emgTTraw      % Time table for EMG
        eegTTpp
        eogTTpp
        emgTTpp
        eegMODsf
        saveLOC 
        fname
        fnameMAT
        eegProc
        chanNUMb
        chansleep
        varlist
    end
    
    methods (Access = private)
        
        
        function outTTab = createBINS(app,chan,chanLABS,SF)
            
            switch chan
                case 'emg'
                    rawDAT = app.emgTTpp;
                case 'eog'
                    rawDAT = app.eogTTpp;
                case 'eeg'
                    rawDAT = app.eegTTpp;
                    
            end
            
            binMG = uiprogressdlg(app.UIFigure,'Title',['Creating Bins ',chan],...
                'Indeterminate','on');
            drawnow

            num30bins = floor(height(rawDAT)/SF/30);
            tstart = seconds(0);
            tend = seconds(30);
            outBINS = cell(num30bins,size(rawDAT,2));
            for si = 1:num30bins
                
                timeLimits = timerange(tstart,tend);
                
                tmpT = rawDAT(timeLimits,:);
                tt2t = timetable2table(tmpT);
                t2c = table2cell(tt2t);
                cleanCel = cell2mat(t2c(:,2:end));
                
                for cchi = 1:size(rawDAT,2)
                    outBINS{si,cchi} = cleanCel(:,cchi);
                end
                
                tstart = tend;
                tend = tstart + seconds(30);
                
                disp([num2str(si) ,' out of ' , num2str(num30bins)])
                
            end
            
            % Create new TimeTable
            % Time
            timeBINS = transpose(seconds(0:30:30*num30bins-1));
            % Data
            finalTT = cell2table(outBINS);
            % Variable names
            varNAMES = chanLABS;
            % Create Table
            finalTT.Properties.VariableNames = varNAMES;
            finalTT.Time = timeBINS;
            
            outTTab = table2timetable(finalTT);
            
            close(binMG)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LOAD
        function LOADButtonPushed(app, event)
            app.fileLOC = uigetdir();
            
            cd(app.fileLOC)
            
            mdir = dir('*.mat');
            mdir2 = {mdir.name};
            app.fileLIST = mdir2;

            % Use Matfile function to import the appropriate files           
            matob = matfile(app.fileLIST{1});
            app.varlist = who(matob);
            
            % DETERIME ID for EEG , EOG , EMG
            chanLab = {'EEG','EEG','EEG','EEG','EEG','EEG','EEG','EEG','EEG',...
                'EEG','EEG','EOG','EOG','EMG','EMG','EMG'};
            chanID = {'F3','Fz','F4','A1','C3','Cz','C4','A2','O1','Oz','O2',...
                'EOG1','EOG2','Chin1','Chin2','ChinZ'};
            aoID = {'F3','Fz','F4','A1','C3','Cz','C4','A2','O1','Oz','O2',...
                'CEOG_1','CEOG_2','CChin_1','CChin_2','CChin_Z'};

            app.labelTab = table(transpose(aoID),transpose(chanLab),...
                transpose(chanID),'VariableNames',{'AO_ID',...
                'Label','ID'});
            
            % Convert to Time tables with appropriate sample frequencies
            app.chansleep = {'EEG','EOG','EMG'};
            app.chanNUMb = zeros(1,3);
            for cNi = 1:length(app.chansleep)
                app.chanNUMb(cNi) = sum(ismember(app.labelTab.Label,app.chansleep{cNi}));
            end
            
            app.eegMODsf = 1375;
            
            app.INFOLabel.Text = "File LOADED";
            
            fileLOCparts = strsplit(app.fileLOC,'\');
            foldName = fileLOCparts{end};
            app.datE.Text = foldName;
            
        end

        % Button pushed function: PROCESSButton
        function PROCESSButtonPushed(app, event)
              % Initiate progress bar
              
            app.tasK.Text = 'FULL';   
              
            progBAR = uiprogressdlg(app.UIFigure,'Title','Combining Files...',...
                'Message','1');
            drawnow
            
            tmpEEGarray = zeros(60*60*14*app.eegMODsf,app.chanNUMb(1)); % 14 Hours
            tmpEOGarray = zeros(60*60*14*app.eegMODsf,app.chanNUMb(2)); % 14 Hours
            tmpEMGarray = zeros(60*60*14*app.eegMODsf,app.chanNUMb(3)); % 14 Hours
            chanTabLabs = cell(1,3);
            aoTabLabs = cell(1,3);
            for fi = 1:length(app.fileLIST)
                
                % Update progress, report current estimate
                progBAR.Value = fi/length(app.fileLIST);
                progBAR.Message = sprintf('%1.0f',fi);
                
                % file name
                curFile = app.fileLIST{fi};
                % Load all channels [MAY NEED TO UPDATE with MICRODRIVE]
                load(curFile,app.varlist{:});
                
                for ci = 1:length(app.chansleep)
                    
                    chanChoose = app.chansleep{ci};
                    chanTabLabs{ci} = app.labelTab.ID(ismember(app.labelTab.Label,chanChoose));
                    aoTabLabs{ci} = app.labelTab.AO_ID(ismember(app.labelTab.Label,chanChoose));
                    % Extract variables relevant to channel
                    % Raw data and sampling frequency
                    % Get channel list        
                    for chi = 1:length(aoTabLabs{ci})

                        tmpChan = aoTabLabs{ci}(ismember(aoTabLabs{ci},aoTabLabs{ci}(chi)));
                        tmpRows = app.varlist(contains(app.varlist,tmpChan));
                        rawRow = tmpRows{1};
                        
                        chanNums = 1:length(chanTabLabs{ci});
                        
                        dat1 = transpose(eval(rawRow));
                        switch chanChoose
                            case 'EEG'
                                
                                lastLOC = find(tmpEEGarray(:,length(chanNums)),1,'last');
                                
                                if isempty(lastLOC)
                                    lastLOC = 0 + 1;
                                else
                                    lastLOC = lastLOC + 1;
                                end
                                
                                % trim off sample points prior to TTL
                                if fi == 1
                                    
                                    timeOFFset = CDIG_IN_1_TimeBegin - CDBS_0_TimeBegin;
                                    ttLSamp = round(timeOFFset*1375);
                                    dat1 = dat1(ttLSamp:numel(dat1));
                                    
                                end
                                
                                curLeng = length(dat1)-1;
                                tmpEEGarray(lastLOC:lastLOC+curLeng ,chi) = dat1;
                                
                            case 'EOG'
                                
                                lastLOC = find(tmpEOGarray(:,length(chanNums)),1,'last');
                                
                                if isempty(lastLOC)
                                    lastLOC = 0 + 1;
                                else
                                    lastLOC = lastLOC + 1;
                                end
                                
                                % trim off sample points prior to TTL
                                if fi == 1

                                    timeOFFset = CDIG_IN_1_TimeBegin - CDBS_0_TimeBegin;
                                    ttLSamp = round(timeOFFset*1375);
                                    dat1 = dat1(ttLSamp:numel(dat1));

                                end
                                
                                curLeng = length(dat1)-1;
                                tmpEOGarray(lastLOC:lastLOC+curLeng ,chi) = dat1;
                                
                            case 'EMG'
                                
                                lastLOC = find(tmpEMGarray(:,length(chanNums)),1,'last');
                                
                                if isempty(lastLOC)
                                    lastLOC = 0 + 1;
                                else
                                    lastLOC = lastLOC + 1;
                                end
                                
                                % trim off sample points prior to TTL
                                if fi == 1

                                    timeOFFset = CDIG_IN_1_TimeBegin - CDBS_0_TimeBegin;
                                    ttLSamp = round(timeOFFset*1375);
                                    dat1 = dat1(ttLSamp:numel(dat1));

                                end
                                
                                curLeng = length(dat1)-1;
                                tmpEMGarray(lastLOC:lastLOC+curLeng ,chi) = dat1;
   
                        end
                    end
                end
            end
            % Close the dialog box
            close(progBAR)

            % Clean up array EEG
            lastZEEG = find(tmpEEGarray(:,1),1,'last') + 1;
            tmpEEGarray = tmpEEGarray(1:lastZEEG,:);
            
            % Clean up array EOG
            lastZEOG = find(tmpEOGarray(:,1),1,'last') + 1;
            tmpEOGarray = tmpEOGarray(1:lastZEOG,:);
            
            % Clean up array EMG
            lastZEMG = find(tmpEMGarray(:,1),1,'last') + 1;
            tmpEMGarray = tmpEMGarray(1:lastZEMG,:);
            
            % Create Timetables
            
            % EEG
            eegPB = uiprogressdlg(app.UIFigure,'Title','Processing EEG',...
                'Indeterminate','on');
            drawnow
           
            tmpEEGtab = array2table(tmpEEGarray);
            tmpEEGtab.Properties.VariableNames = chanTabLabs{1};
            app.eegTTraw = table2timetable(tmpEEGtab,'SampleRate',app.eegMODsf);
            app.eegProc = app.eegTTraw;

            hourS = ceil((size(app.eegTTraw,1)/1375)/60/60);

            stHR = 0;
            enHR = 3599;
            for hi = 1:hourS
   
                tmTR = timerange(seconds(stHR),seconds(enHR));
                
                tmpEEGhblk = app.eegTTraw(tmTR,:);
                
                tmTR2 = timerange(tmpEEGhblk.Time(1),tmpEEGhblk.Time(end) + seconds(1));

                % Normalize
                eegPB.Message = sprintf('%s',['Normalize Hour ' , num2str(hi)]);
                eegTTrawN = normalize(tmpEEGhblk);
                % Band Stop notch
                eegPB.Message = sprintf('%s',['Notch Filter Hour ' , num2str(hi)]);
                eegNOTCH = bandstop(eegTTrawN,[59 61]);
                % Low Filter
                eegPB.Message = sprintf('%s',['Low Pass Filter Hour ' , num2str(hi)]);
                eegTTppLp = lowpass(eegNOTCH,35);
                % High Filter
                eegPB.Message = sprintf('%s',['High Pass Filter Hour ' num2str(hi)]);
                eegTTppHp = highpass(eegTTppLp,0.3);
                
                % Check smooth option
                if strcmp(app.smoothOp.Value,'On')
                    % Smooth Data
                    eegPB.Message = sprintf('%s',['Smooth Data Hour ' num2str(hi)]);
                    eegTTppSm = smoothdata(eegTTppHp,'gaussian',seconds(0.25));
                else
                    eegTTppSm = eegTTppHp;
                end
                
                if height(tmpEEGhblk) < 4948000
                    app.eegProc(tmTR2,:) = eegTTppSm;
                else
                    app.eegProc(tmTR,:) = eegTTppSm;
                end
                
                stHR = enHR + 1;
                enHR = stHR + 3599;
                
                beep
                
            end
            
            
            % Downsample
            eegPB.Message = sprintf('%s','Downsample 100Hz');
            eegTTppDs = retime(app.eegProc,'regular','mean','SampleRate',100);
            % Preprocess Signal
            app.eegTTpp = eegTTppDs;

            close(eegPB)

            % EOG
            eogPB = uiprogressdlg(app.UIFigure,'Title','Processing EOG',...
                'Indeterminate','on');
            drawnow
            
            tmpEOGtab = array2table(tmpEOGarray);
            tmpEOGtab.Properties.VariableNames = chanTabLabs{2};
            app.eogTTraw = table2timetable(tmpEOGtab,'SampleRate',app.eegMODsf);
            
            % Normalize
            eogPB.Message = sprintf('%s','Normalize');
            eogTTrawN = normalize(app.eogTTraw);
            % Band Stop notch
            eogPB.Message = sprintf('%s','Notch Filter');
            eogNOTCH = bandstop(eogTTrawN,[59 61]);
            % Low Filter
            eogPB.Message = sprintf('%s','Low Pass Filter');
            eogTTppLp = lowpass(eogNOTCH,35);
            % High Filter
            eogPB.Message = sprintf('%s','High Pass Filter');
            eogTTppHp = highpass(eogTTppLp,0.3);
            
            % Check smooth option
            if strcmp(app.smoothOp.Value,'On')
                % Smooth Data
                eogPB.Message = sprintf('%s','Smooth Data');
                eogTTppSm = smoothdata(eogTTppHp,'gaussian',seconds(0.25));
            else
                eogTTppSm = eogTTppHp;
            end
            
            
            % Downsample
            eogPB.Message = sprintf('%s','Downsample 100Hz');
            eogTTppDs = retime(eogTTppSm,'regular','mean','SampleRate',100);
            % Preprocess Signal
            app.eogTTpp = eogTTppDs;

            close(eogPB)

            % EMG
            emgPB = uiprogressdlg(app.UIFigure,'Title','Processing EMG',...
                'Indeterminate','on');
            drawnow

            tmpEMGtab = array2table(tmpEMGarray);
            tmpEMGtab.Properties.VariableNames = chanTabLabs{3};
            app.emgTTraw = table2timetable(tmpEMGtab,'SampleRate',app.eegMODsf);

            % Normalize
            emgPB.Message = sprintf('%s','Normalize');
            emgTTrawN = normalize(app.emgTTraw);
            % Band Stop notch
            emgPB.Message = sprintf('%s','Notch Filter');
            emgNOTCH = bandstop(emgTTrawN,[59 61]);
            % Low Filter
            emgPB.Message = sprintf('%s','Low Pass Filter');
            emgTTppLp = lowpass(emgNOTCH,100);
            % High Filter
            emgPB.Message = sprintf('%s','High Pass Filter');
            emgTTppHp = highpass(emgTTppLp,10);
            
            % Check smooth option
            if strcmp(app.smoothOp.Value,'On')
                % Smooth Data
                emgPB.Message = sprintf('%s','Smooth Data');
                emgTTppSm = smoothdata(emgTTppHp,'gaussian',seconds(0.25));
            else
                emgTTppSm = emgTTppHp;
            end
            
            % Downsample
            emgPB.Message = sprintf('%s','Downsample 100Hz');
            emgTTppDs = retime(emgTTppSm,'regular','mean','SampleRate',100);
            % Preprocess Signal
            app.emgTTpp = emgTTppDs;
            
            close(emgPB)
            
            eegTABf = createBINS(app,'eeg',chanTabLabs{1},100);
            eogTABf = createBINS(app,'eog',chanTabLabs{2},100);
            emgTABf = createBINS(app,'emg',chanTabLabs{3},100);
            
            TT = [eegTABf,eogTABf,emgTABf];
            % Add STNF
            TT.STNF = repmat({''},height(TT),1);
            % Add UNMC
            TT.UNMC = repmat({''},height(TT),1);
            % Add ActTime % USE AO CONVERTER TO GET START TIME OF FIRST
            % FILE
            promptT = {'Create Act Time'};
            dlgtitleT = {'Year', 'Month','Day','Hour','min','sec'};
            definputT = {'2021','1','1','14','32','54'};
            dimsT = [1 15; 1 15; 1 15 ; 1 15 ; 1 15; 1 15];
            actTIME = inputdlg(dlgtitleT,promptT{1},dimsT,definputT);
            actNm = cellfun(@(x) str2double(x), actTIME);
            recSTART = datetime(actNm(1),actNm(2),actNm(3),actNm(4),actNm(5),actNm(6));
            
            actimeVec = transpose(0:seconds(30):seconds((height(TT)-1)*30)) + recSTART;

            TT.ActTime = actimeVec;

            % SAVE OUT 
            app.saveLOC = uigetdir();
            
            cd(app.saveLOC)
            
            prompt = {'Enter file name'};
            dlgtitle = {'PatientID', 'Institution','Night#'};
            definput = {'1','UNMC','1'};
            dims = [1 50; 1 50; 1 50];
            outP = inputdlg(dlgtitle,prompt{1},dims,definput);
            
            app.fname = [outP{1},'_',outP{2},'_',outP{3}];
            app.fnameMAT = [app.fname,'.mat'];
            
            save(app.fnameMAT,'TT')
            
            app.INFOLabel.Text = "DONE";
            app.tasK.Text = 'DONE';
            
        end

        % Button pushed function: CONCATButton
        function CONCATButtonPushed(app, event)
            
            app.tasK.Text = 'EDF';
            % Initiate progress bar
            progBAR = uiprogressdlg(app.UIFigure,'Title','Combining Files...',...
                'Message','1');
            drawnow
            
            tmpEEGarray = zeros(60*60*12*app.eegMODsf,app.chanNUMb(1)); % 12 Hours
            tmpEOGarray = zeros(60*60*12*app.eegMODsf,app.chanNUMb(2)); % 12 Hours
            tmpEMGarray = zeros(60*60*12*app.eegMODsf,app.chanNUMb(3)); % 12 Hours
            chanTabLabs = cell(1,3);
            aoTabLabs = cell(1,3);
            for fi = 1:length(app.fileLIST)
                
                % Update progress, report current estimate
                progBAR.Value = fi/length(app.fileLIST);
                progBAR.Message = sprintf('%1.0f',fi);
                
                % file name
                curFile = app.fileLIST{fi};
                % Load all channels [MAY NEED TO UPDATE with MICRODRIVE]
                load(curFile,app.varlist{:});
                
                for ci = 1:length(app.chansleep)
                    
                    chanChoose = app.chansleep{ci};
                    chanTabLabs{ci} = app.labelTab.ID(ismember(app.labelTab.Label,chanChoose));
                    aoTabLabs{ci} = app.labelTab.AO_ID(ismember(app.labelTab.Label,chanChoose));
                    % Extract variables relevant to channel
                    % Raw data and sampling frequency
                    % Get channel list
                    for chi = 1:length(aoTabLabs{ci})
                        
                        tmpChan = aoTabLabs{ci}(ismember(aoTabLabs{ci},aoTabLabs{ci}(chi)));
                        tmpRows = app.varlist(contains(app.varlist,tmpChan));
                        rawRow = tmpRows{1};
                        
                        chanNums = 1:length(chanTabLabs{ci});
                        
                        dat1 = transpose(eval(rawRow));
                        switch chanChoose
                            case 'EEG'
                                
                                lastLOC = find(tmpEEGarray(:,length(chanNums)),1,'last');
                                
                                if isempty(lastLOC)
                                    lastLOC = 0 + 1;
                                else
                                    lastLOC = lastLOC + 1;
                                end
                                
                                curLeng = length(dat1)-1;
                                tmpEEGarray(lastLOC:lastLOC+curLeng ,chi) = dat1;
                                
                            case 'EOG'
                                
                                lastLOC = find(tmpEOGarray(:,length(chanNums)),1,'last');
                                
                                if isempty(lastLOC)
                                    lastLOC = 0 + 1;
                                else
                                    lastLOC = lastLOC + 1;
                                end
                                
                                curLeng = length(dat1)-1;
                                tmpEOGarray(lastLOC:lastLOC+curLeng ,chi) = dat1;
                                
                            case 'EMG'
                                
                                lastLOC = find(tmpEMGarray(:,length(chanNums)),1,'last');
                                
                                if isempty(lastLOC)
                                    lastLOC = 0 + 1;
                                else
                                    lastLOC = lastLOC + 1;
                                end
                                
                                curLeng = length(dat1)-1;
                                tmpEMGarray(lastLOC:lastLOC+curLeng ,chi) = dat1;
                                
                        end
                    end
                end
            end
            % Close the dialog box
            close(progBAR)
            
            % Clean up array EEG
            lastZEEG = find(tmpEEGarray(:,1),1,'last') + 1;
            tmpEEGarray = tmpEEGarray(1:lastZEEG,:);
            
            % Clean up array EOG
            lastZEOG = find(tmpEOGarray(:,1),1,'last') + 1;
            tmpEOGarray = tmpEOGarray(1:lastZEOG,:);
            
            % Clean up array EMG
            lastZEMG = find(tmpEMGarray(:,1),1,'last') + 1;
            tmpEMGarray = tmpEMGarray(1:lastZEMG,:);
            
            disp('DONE with CLEAN UP')
            
            % Create Timetables      
            tmpEEGtab = array2table(tmpEEGarray);
            tmpEEGtab.Properties.VariableNames = chanTabLabs{1};
            app.eegTTraw = table2timetable(tmpEEGtab,'SampleRate',app.eegMODsf);           
            eegTTppDs = retime(app.eegTTraw,'regular','mean','SampleRate',100);
            app.eegTTpp = eegTTppDs;
            
            disp('DONE with EEG TimeTable')
            
            tmpEOGtab = array2table(tmpEOGarray);
            tmpEOGtab.Properties.VariableNames = chanTabLabs{2};
            app.eogTTraw = table2timetable(tmpEOGtab,'SampleRate',app.eegMODsf);
            eogTTppDs = retime(app.eogTTraw,'regular','mean','SampleRate',100);
            app.eogTTpp = eogTTppDs;
            
            disp('DONE with EOG TimeTable')
            
            tmpEMGtab = array2table(tmpEMGarray);
            tmpEMGtab.Properties.VariableNames = chanTabLabs{3};
            app.emgTTraw = table2timetable(tmpEMGtab,'SampleRate',app.eegMODsf);
            emgTTppDs = retime(app.emgTTraw,'regular','mean','SampleRate',100);
            app.emgTTpp = emgTTppDs;
            
            eegTABf = createBINS(app,'eeg',chanTabLabs{1},100);
            eogTABf = createBINS(app,'eog',chanTabLabs{2},100);
            emgTABf = createBINS(app,'emg',chanTabLabs{3},100);
            
            TT = [eegTABf,eogTABf,emgTABf];
            promptT = {'Create Act Time'};
            dlgtitleT = {'Year', 'Month','Day','Hour','min','sec'};
            definputT = {'2021','1','1','14','32','54'};
            dimsT = [1 15; 1 15; 1 15 ; 1 15 ; 1 15; 1 15];
            actTIME = inputdlg(dlgtitleT,promptT{1},dimsT,definputT);
            actNm = cellfun(@(x) str2double(x), actTIME);
            recSTART = datetime(actNm(1),actNm(2),actNm(3),actNm(4),actNm(5),actNm(6));
            
            actimeVec = transpose(0:seconds(30):seconds((height(TT)-1)*30)) + recSTART;
            
            TT.ActTime = actimeVec;
            
            % SAVE OUT
            app.saveLOC = uigetdir();
            
            cd(app.saveLOC)
            
            prompt = {'Enter file name'};
            dlgtitle = {'PatientID', 'Institution','Night#'};
            definput = {'1','UNMC','1'};
            dims = [1 50; 1 50; 1 50];
            outP = inputdlg(dlgtitle,prompt{1},dims,definput);
            
            app.fname = [outP{1},'_',outP{2},'_',outP{3}];
            app.fnameMAT = [app.fname,'.mat'];
            
            save(app.fnameMAT,'TT')
            
            app.INFOLabel.Text = "DONE";
            app.tasK.Text = 'DONE';

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChannelMenu = uimenu(app.UIFigure);
            app.ChannelMenu.Text = 'Channel';
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.SmoothSwitchLabel = uilabel(app.OptionsPanel);
            app.SmoothSwitchLabel.Text = 'Smooth';
            app.smoothOp = uiswitch(app.OptionsPanel, 'slider');
            app.LOAD = uibutton(app.UIFigure, 'push');
            app.LOAD.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOAD.Text = 'LOAD';
            app.PROCESSButton = uibutton(app.UIFigure, 'push');
            app.PROCESSButton.ButtonPushedFcn = createCallbackFcn(app, @PROCESSButtonPushed, true);
            app.PROCESSButton.Text = 'PROCESS';
            app.INFOLabel = uilabel(app.UIFigure);
            app.INFOLabel.Text = 'INFO';
            app.CONCATButton = uibutton(app.UIFigure, 'push');
            app.CONCATButton.ButtonPushedFcn = createCallbackFcn(app, @CONCATButtonPushed, true);
            app.CONCATButton.Text = 'CONCAT';
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusPanel.Title = 'Status';
            app.tasK = uilabel(app.StatusPanel);
            app.datE = uilabel(app.StatusPanel);
            app.datE.Text = 'Label2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SleepDataTTcreateByHour_v4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end