classdef SleepStage_02072021 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SETD                     matlab.ui.container.Menu
        NewSub                   matlab.ui.container.Menu
        EXITMenu                 matlab.ui.container.Menu
        TabGroup                 matlab.ui.container.TabGroup
        Settings                 matlab.ui.container.Tab
        EEGElectrodesPanel       matlab.ui.container.Panel
        eeg1dd                   matlab.ui.control.DropDown
        eeg3dd                   matlab.ui.control.DropDown
        eeg4dd                   matlab.ui.control.DropDown
        eeg2dd                   matlab.ui.control.DropDown
        subjectLpanel            matlab.ui.container.Panel
        subjectL                 matlab.ui.control.ListBox
        stScore                  matlab.ui.control.Button
        resetElp                 matlab.ui.control.Button
        instimage                matlab.ui.control.Image
        smoothPanel              matlab.ui.container.Panel
        Plot1DropDownLabel       matlab.ui.control.Label
        smP1                     matlab.ui.control.DropDown
        Plot2DropDownLabel       matlab.ui.control.Label
        smP2                     matlab.ui.control.DropDown
        Plot3DropDownLabel       matlab.ui.control.Label
        smP3                     matlab.ui.control.DropDown
        Plot4DropDownLabel       matlab.ui.control.Label
        smP4                     matlab.ui.control.DropDown
        SleepScore               matlab.ui.container.Tab
        SCORINGPanel             matlab.ui.container.Panel
        epochB                   matlab.ui.control.Button
        epochF                   matlab.ui.control.Button
        EPOCHSpinnerLabel        matlab.ui.control.Label
        EPOCHSpin                matlab.ui.control.Spinner
        WB                       matlab.ui.control.Button
        RB                       matlab.ui.control.Button
        N1B                      matlab.ui.control.Button
        N2B                      matlab.ui.control.Button
        N3B                      matlab.ui.control.Button
        totEp                    matlab.ui.control.Label
        HypnoP                   matlab.ui.container.Panel
        hypSw                    matlab.ui.control.Switch
        EEG_extra                matlab.ui.container.Panel
        NotesTextAreaLabel       matlab.ui.control.Label
        notesN1                  matlab.ui.control.TextArea
        ArousalSpinnerLabel      matlab.ui.control.Label
        arouseN1                 matlab.ui.control.Spinner
        ZoominSecondsGaugeLabel  matlab.ui.control.Label
        zoomIN                   matlab.ui.control.LinearGauge
        Panel                    matlab.ui.container.Panel
        GridLayout               matlab.ui.container.GridLayout
        EEG1                     matlab.ui.control.UIAxes
        EEG2                     matlab.ui.control.UIAxes
        EEG3                     matlab.ui.control.UIAxes
        EEG4                     matlab.ui.control.UIAxes
        EOG                      matlab.ui.control.UIAxes
        HYPg                     matlab.ui.control.UIAxes
    end

    %%%% WORKING ON
    %% 
    %% 2. FIX tick marks
    %% 3. Add slider to top of hypnogram - CONSIDER for later version
    
    properties (Access = private)
        inDlcs % Internal directory locations
        subSel % Selected subject to work on
        subData % Primary sleep data
        dataParams % structure to characterize data
        curEpoch % Current Epoch
        BlindHpy
        GenHpy
        zoomNOW
        zoomINDex
    end
    
    methods (Access = private)
        
        function updateParams(app , stage)
            
            % Determine how to update parameters
            switch stage
                % Initialize parameters - ADD stuff to table if needed
                case 1
                    % Number of 30 second epochs
                    app.dataParams.epochS = height(app.subData);
                    % Set up Hypnogram plot
                    app.HYPg.XLim = [1, app.dataParams.epochS];
                    % ADD TEXT to EPOCH spinner
                    app.totEp.Text = [num2str(app.dataParams.epochS), ' Epochs'];
                    % Number of EEG electrodes
                    standardEEG = {'F3','Fz','A1','C3','Cz','A2','O1','EOG1','EOG2','EMG1','EMG2','EMG3'};
                    tmpCols = app.subData.Properties.VariableNames;
                    app.dataParams.EEGnum = sum(contains(tmpCols,standardEEG));
                    app.dataParams.EEGid = tmpCols(contains(tmpCols,standardEEG));
                    % ID of EEG electrodes
                    eegID = cell(app.dataParams.EEGnum,1);
                    eegIND = zeros(app.dataParams.EEGnum,1);
                    for ei = 1:app.dataParams.EEGnum
                        tmpEEGid = app.dataParams.EEGid{ei};
                        tmpEEGind = ismember(tmpCols,tmpEEGid);
                        eegID{ei} = tmpCols{tmpEEGind};
                        eegIND(ei) = find(tmpEEGind);
                    end
                    app.dataParams.EEGtab = table(eegIND, eegID);
                    app.dataParams.SleepStg = app.subData.(app.GenHpy);
                    app.dataParams.HpyComp = app.subData.(app.BlindHpy);
                    app.dataParams.sampPts = height(app.subData.(eegID{1}){1});
                    
                    % CHECK if BlindHpy is empty or not and fix toggle
                    if ~isempty(app.dataParams.HpyComp{1})
                        app.HypnoP.Enable = 'on';
                    end
                    
                    % Actual Time
                    app.dataParams.Atime =  app.subData.ActTime;
                    
                    % CHECK if Arousal column is available
                    if ~ismember('arousaln',app.subData.Properties.VariableNames)
                        app.subData.arousaln = zeros(height(app.subData),1);
                        app.dataParams.aroCt = app.subData.arousaln;
                    else
                        app.dataParams.aroCt = app.subData.arousaln;
                    end
                    
                    % CHECK if notes column is avialable
                    if ~ismember('notes',app.subData.Properties.VariableNames)
                        app.subData.notes = repmat({''},height(app.subData),1);
                        app.dataParams.notes = app.subData.notes;
                    else
                        app.dataParams.notes = app.subData.notes;
                    end
                    
                    % Initialize ZoomIndex
                    app.zoomINDex = 1;
            end


        end
        
        function updateEEGplot(app , stage)
            
            switch stage
                case 1 % Initialize
                    app.curEpoch = 1; % Reset current epoch to 1
                    app.EPOCHSpin.Value = app.curEpoch; 
                    for st1 = 1:4  % NUMBER of EEG PLOTS - WAS app.dataParams.EEGnum %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        vecCell = app.subData{app.curEpoch,app.dataParams.EEGtab.eegIND(st1)};
                        eegVec = vecCell{1};
                        
                        ylabel(app.(['EEG',num2str(st1)]),app.dataParams.EEGtab.eegID{st1})
                        
                        % ADD SMOOTH PARAMETER by PLOT
                        smEegVec = smoothdata(eegVec);
                        smEegVec2 = smoothdata(smEegVec , 'movmean', str2double(app.(['smP',num2str(st1)]).Value));
                        
                        % Fix time vector
                        timeVEC = linspace(1,app.dataParams.sampPts/100,app.dataParams.sampPts);
                        
                        plot(app.(['EEG',num2str(st1)]),timeVEC,smEegVec2)
                        [yTmax , yTmin] = getYlimVs(app,smEegVec2,st1);
                        
                        if  contains(app.dataParams.EEGtab.eegID{st1},'EMG')
                            udpateXticktime(app , st1 , stage, yTmax, yTmin)
                            continue
                        else
                            updatePltTxt(app,yTmin,yTmax,st1,1);
                        end
                        
                        % Update x-axis tick-marks
                        udpateXticktime(app , st1 , stage, yTmax, yTmin)
                        
                    end
                    
                case 2 % Move back or forward
                    for st2 = 1:4 % NUMBER of EEG PLOTS - WAS app.dataParams.EEGnum %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        vecCell = app.subData{app.curEpoch,app.dataParams.EEGtab.eegIND(st2)};
                        eegVec = vecCell{1};
                        
                        smEegVec = smoothdata(eegVec);
                        smEegVec2 = smoothdata(smEegVec , 'movmean', str2double(app.(['smP',num2str(st2)]).Value));
                        
                        % Fix time vector
                        timeVEC = linspace(1,app.dataParams.sampPts/100,app.dataParams.sampPts);
                        
                        plot(app.(['EEG',num2str(st2)]),timeVEC, smEegVec2)
                        [yTmax , yTmin] = getYlimVs(app,smEegVec2,st2);
                        
                        if  contains(app.dataParams.EEGtab.eegID{st2},'EMG')
                            udpateXticktime(app , st2 , stage, yTmax, yTmin)
                            continue
                        else
                            updatePltTxt(app,yTmin,yTmax,st2,1);
                        end
                        
                        % Update x-axis tick-marks
                        udpateXticktime(app , st2, stage, yTmax, yTmin)
                        
                    end
                    
                case 3 % Show more than on epoch
                    numEpochs2u = round(app.zoomIN.Value/30) - 1;
                    actEpochs2u = app.curEpoch + numEpochs2u;
                    
                    for st3 = 1:4 % NUMBER of EEG PLOTS - WAS app.dataParams.EEGnum %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        
                        aggVec = [];
                        for ag = app.curEpoch:actEpochs2u
                            aggVec = [aggVec ; app.subData{ag ,app.dataParams.EEGtab.eegIND(st3)}{:}];
                        end
                        
                        vecCell = aggVec;
                        
                        smEegVec = smoothdata(vecCell);
                        smEegVec2 = smoothdata(smEegVec , 'movmean', str2double(app.(['smP',num2str(st3)]).Value));
                        
                        numEpochs = round(app.zoomIN.Value/30);
                        zoomPoints = (app.dataParams.sampPts*numEpochs);
                        timeVEC = linspace(1,zoomPoints/100,zoomPoints);
                        
                        plot(app.(['EEG',num2str(st3)]),timeVEC, smEegVec2)
                        [yTmax , yTmin] = getYlimVs(app,smEegVec2,st3);
                        
                        if  contains(app.dataParams.EEGtab.eegID{st3},'EMG')
                            udpateXticktime(app , st3 , stage, yTmax, yTmin)
                            continue
                        else
                            updatePltTxt(app,yTmin,yTmax,st3,2);
                        end
                        
                        % Update x-axis tick-marks
                        udpateXticktime(app , st3 , stage, yTmax, yTmin)
                        
                    end
                    
            end
            
        end
        
        function [yMaxR , yMinR] = getYlimVs(app,vecOfint,si)
            
            yMax = max(vecOfint);
            yMin = abs(min(vecOfint));
            if yMax > yMin
                yMaxR = yMax;
                yMinR = yMax*-1;
                ylim(app.(['EEG',num2str(si)]),[-1*yMax , yMax])
            else
                yMaxR = yMin;
                yMinR = yMin*-1;
                ylim(app.(['EEG',num2str(si)]),[-1*yMin , yMin])
            end
            
        end
        
        function updatePltTxt(app,yTmin,yTmax,si,stage)
            
            switch stage
                
                case 1
                    % Update Epoch Text
                    text(app.(['EEG',num2str(si)]),...
                        27,...
                        yTmin + round((yTmin*2*-1)*0.1),...
                        ['Epoch ',num2str(app.curEpoch)],'Color','r')
                    
                    % Update Sleep Stage Text
                    if isempty(app.dataParams.SleepStg{app.curEpoch})
                        curSlepStg = 'NONE';
                    else
                        curSlepStg = app.dataParams.SleepStg{app.curEpoch};
                    end
                    
                    text(app.(['EEG',num2str(si)]),...
                        1,...
                        yTmax - round((yTmax*2)*0.1),...
                        curSlepStg,'Color','k',"FontWeight","bold","FontSize",15)
                    
                    % Update Time
                    strTime = datestr(timeofday(app.dataParams.Atime(app.curEpoch)));
                    
                    text(app.(['EEG',num2str(si)]),...
                        1.5,...
                        yTmin + round((yTmin*2*-1)*0.1),...
                        strTime,'Color','r',"FontWeight","bold")
                    
                case 2
                    % Update Epoch Text
                    text(app.(['EEG',num2str(si)]),...
                        1,...
                        yTmax - round((yTmax*2)*0.1),...
                        ['Epoch ',num2str(app.curEpoch)],'Color','r',"FontWeight","bold")
                    
            end
            
        end
        
        function updateButtonAct(app , stageB)
            % Update Sleep Stage Cell Array
            app.dataParams.SleepStg{app.curEpoch} = stageB;
            % Update Hypnogram plot
            updateHYPlot(app)
            % Advance Epoch
            app.curEpoch = app.curEpoch + 1;
            app.EPOCHSpin.Value = app.curEpoch;
            % Update EEG plot
            updateEEGplot(app,2)
            % Update EEG extra
            updateEEGextra(app,1)
            
        end
        
        function updateHYPlot(app)
            
            stageS = {'W','R','N1','N2','N3'};
            if strcmp(app.hypSw.Value,'On')
                tmpHypBL = nan(app.dataParams.epochS,1);
                for i = 1:5
                    tmpHypBL(ismember(app.dataParams.HpyComp,stageS{i})) = i;
                end
                xPoints = transpose(1:app.dataParams.epochS);
                scatter(app.HYPg,xPoints,tmpHypBL,5,"red","filled")
            else
                cla(app.HYPg)
            end
            
            tmpHyp = nan(app.dataParams.epochS,1);
            
            for i = 1:5
                tmpHyp(ismember(app.dataParams.SleepStg,stageS{i})) = i;
            end
            xPoints = transpose(1:app.dataParams.epochS);
            hold(app.HYPg,'on')
            scatter(app.HYPg,xPoints,tmpHyp,5,"black","filled")
            hold(app.HYPg,'off')
            line(app.HYPg,[app.curEpoch app.curEpoch], [0 6], 'Color','g','LineStyle','--')


        end
        
        % UPDATE ANNOTATION PLOT
        %%% Five states it needs to work
        % Done#1. Button press left and right
        % Done#2. Key press arrow left and right
        % Done#3. Sleep Stage button press - forward
        % Done#4. Sleep key press - forward
        % 5. Change epoch in spinner
        function updateEEGextra(app , FoB)
            
            % FoB = 1 = forward
            % FoB = ~1 = backward
            
            if FoB
                lastEpoch = app.curEpoch - 1;
            else
                lastEpoch = app.curEpoch + 1;
            end
            % Save current state
            app.dataParams.aroCt(lastEpoch) = app.arouseN1.Value;
            app.dataParams.notes(lastEpoch) = app.notesN1.Value;
            % Show current state
            app.arouseN1.Value = app.dataParams.aroCt(app.curEpoch);
            app.notesN1.Value = app.dataParams.notes(app.curEpoch);
        end
        
        function updateZoom(app , UD)
            zoomVals = [30, 60, 120, 180, 240];
            curIND = app.zoomINDex;
            switch UD
                case 1
                    newIND = curIND + 1;
                    
                    if newIND > 5
                        return
                    else
                        app.zoomINDex = newIND;
                        app.zoomIN.Value = zoomVals(newIND);
                        % Update zoom plot
                        updateEEGplot(app , 3)
                    end
                    
                case 2
                    newIND = curIND - 1;
                    
                    if newIND < 1
                        return
                    else
                        app.zoomINDex = newIND;
                        app.zoomIN.Value = zoomVals(newIND);
                        % Update zoom plot
                        updateEEGplot(app , 3)
                    end
            end
            
            if app.zoomINDex ~= 1
                % turn stuff off
                app.EEG_extra.Enable = 'off';
                app.SCORINGPanel.Enable = 'off';
            else
                % turn stuff on
                app.EEG_extra.Enable = 'on';
                app.SCORINGPanel.Enable = 'on';
            end
            
            
        end
        
        function udpateXticktime(app , si , stage , yTmax, yTmin)
            
            xLIMmax = app.zoomIN.Value;
            
            % Update Xlim
            xlim(app.(['EEG',num2str(si)]),...
                [1 xLIMmax])

            switch stage
                case {1,2}
                    % Update Xticks
                    xticks(app.(['EEG',num2str(si)]),...
                        floor(linspace(1,xLIMmax,4))) % for 10 second blocks
                    
                    numOFlines = length(floor(linspace(1,xLIMmax,4)));
                    
                    line(app.(['EEG',num2str(si)]),...
                        [floor(linspace(1,xLIMmax,4));...
                        floor(linspace(1,xLIMmax,4))],...
                        [repmat(yTmin,1,numOFlines);repmat(yTmax,1,numOFlines)],...
                        'Color',[0.5 0.5 0.5],'LineStyle','--','LineWidth',0.5)
                    
                case 3
                    numEpochs = round(app.zoomIN.Value/30);

                    % Update Xticks
                    xticks(app.(['EEG',num2str(si)]),...
                        floor(linspace(1,xLIMmax,1 + (2*numEpochs)))) % for 15 second blocks
                    
                    numOFlines = length(floor(linspace(1,xLIMmax,1 + (2*numEpochs))));
                    
                    line(app.(['EEG',num2str(si)]),...
                        [floor(linspace(1,xLIMmax,1 + (2*numEpochs)));...
                        floor(linspace(1,xLIMmax,1 + (2*numEpochs)))],...
                        [repmat(yTmin,1,numOFlines);repmat(yTmax,1,numOFlines)],...
                        'Color',[0.5 0.5 0.5],'LineStyle','--','LineWidth',0.5)
    
            end
            
            
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            msg = 'Choose Local Institution.';
            title = 'Confirm Institution';
            selection = uiconfirm(app.UIFigure,msg,title,...
                'Options',{'UNMC','Stanford'},...
                'DefaultOption',1,'Icon','question');
            
            switch selection
                case 'UNMC'
                    app.instimage.ImageSource = 'unmcIMG400500.png';
                    app.HypnoP.Title = 'Stanford-Hypnogram';
                    app.BlindHpy = 'STNF';
                    app.GenHpy = 'UNMC';
                case 'Stanford'
                    app.instimage.ImageSource = 'stanIMG400500.png';
                    app.HypnoP.Title = 'UNMC-Hypnogram';
                    app.BlindHpy = 'UNMC';
                    app.GenHpy = 'STNF';
            end
            
            % CHECK if BlindHpy is empty or not and fix toggle
            
            
        end

        % Menu selected function: SETD
        function SETDMenuSelected(app, event)
            % Disenable Set directory menu
            app.SETD.Enable = 'off';
            
            tmpAppLoc = which('sleepstagejat11082020.mlapp');
            app.inDlcs.dsMLAppLoc = fileparts(tmpAppLoc);
            % cd to mlapp location
            cd(app.inDlcs.dsMLAppLoc);
            % Add all files/folders to path
            addpath(genpath(app.inDlcs.dsMLAppLoc))
            % locate hard coded DS directory
            tmpDSLoc = which('UH3NS11376A1_dsLOC.txt');
            app.inDlcs.dsLOC1 = fileparts(tmpDSLoc);
            
            % Navigate to DS and populate List
            cd(app.inDlcs.dsLOC1)
            % Idenitify available recordings
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % [FUTURE - use FILE NAME to populate info] - WILL BE .MAT %%%%
            subDir = dir('*.mat');
            subDirn = {subDir.name};
            app.subjectL.Items = ['...', subDirn];
            
            app.subjectLpanel.Enable = 'on';
            
        end

        % Value changed function: subjectL
        function subjectLValueChanged(app, event)
            app.subSel = app.subjectL.Value;
            
            if strcmp(app.subSel,'...')
                return
            end
            
            %  app.subData = edfread('SC4002E0-PSG.edf');
            tmpLoad = load(app.subjectL.Value,'TT');
            app.subData = tmpLoad.TT;
            
            updateParams(app , 1)
            
            app.EEGElectrodesPanel.Enable = 'on';
            % Update EEG Panel buttons
            for ebi = 1:4
                app.(['eeg',num2str(ebi),'dd']).Enable = 'on';
                app.(['eeg',num2str(ebi),'dd']).Items = table2array(app.dataParams.EEGtab(:,2));
                app.(['eeg',num2str(ebi),'dd']).Value = app.dataParams.EEGtab{ebi,2};
            end
            
            % Turn on New Subject
            app.NewSub.Enable = 'on';
            % Dis-enable Subject List
            app.subjectL.Enable = 'off';
            % Turn on Start Score button
            app.stScore.Enable = 'on';
            % Current Epoch start
            app.curEpoch = 1;
            
            
        end

        % Menu selected function: NewSub
        function NewSubMenuSelected(app, event)
            
            % Reset EEG list
            % Clear plots
            for ei = 1:4
                app.(['eeg',num2str(ei),'dd']).Items = {'EEG1','EEG2','EEG3','EEG4'};
                app.(['eeg',num2str(ei),'dd']).Value = ['EEG',num2str(ei)];
                app.(['eeg',num2str(ei),'dd']).Enable = 'off';
                app.(['EEG',num2str(ei)]).Visible = 'off';
                cla(app.(['EEG',num2str(ei)]))
            end
            % Turn subject list back on
            app.subjectL.Enable = 'on';
            % Turn off new subject
            app.NewSub.Enable = 'off';
            
            %% Sleep Score Panel
            % Turn off Scoring Panel
            app.SCORINGPanel.Enable = 'off';
            % Turn off Hypnogram
            app.HypnoP.Enable = 'off';
            
        end

        % Menu selected function: EXITMenu
        function EXITMenuSelected(app, event)
            
            selection = uiconfirm(app.UIFigure,'Close Program?','Confirm Close',...
                'Icon','warning');
            switch selection
                case 'OK'
                    delete(app)
                case 'Cancel'
                    return
            end
            
        end

        % Button pushed function: stScore
        function stScoreButtonPushed(app, event)
            
            app.resetElp.Enable = 'on';
            % Update EEG Panel buttons
            for ebi = 1:4 % NUMBER of EEG PLOTS - WAS app.dataParams.EEGnum
                app.(['eeg',num2str(ebi),'dd']).Enable = 'off';
                app.(['EEG',num2str(ebi)]).Visible = 'on';
            end
            
            % Plot First Epoch of Data
            updateEEGplot(app, 1)
            
            % Open Panels
            app.SCORINGPanel.Enable = 'on';
            app.EEG_extra.Enable = 'on';
            
            % Turn off start scoring button
            app.stScore.Enable = 'off';
            
            % Turn on smooth plot
            app.smoothPanel.Enable = 'on';
            
            for si = 1:4 % NUMBER of EEG PLOTS - WAS app.dataParams.EEGnum %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                app.(['smP',num2str(si)]).Enable = 'on';
            end
            
        end

        % Button pushed function: epochB
        function epochBButtonPushed(app, event)
            app.curEpoch = app.curEpoch - 1;
            if app.curEpoch < 1
                app.curEpoch = 1;
                return
            else
                app.EPOCHSpin.Value = app.curEpoch;
                updateEEGplot(app, 2)
                
                updateHYPlot(app)
                
                % Update EEG extra data
                updateEEGextra(app,0)
            end
        end

        % Button pushed function: epochF
        function epochFButtonPushed(app, event)
            app.curEpoch = app.curEpoch + 1;
            if app.curEpoch > app.dataParams.epochS
                app.curEpoch = app.dataParams.epochS;
                return
            else
                app.EPOCHSpin.Value = app.curEpoch;
                updateEEGplot(app, 2)
                
                updateHYPlot(app)
                
                % Update EEG extra data
                updateEEGextra(app,1)
            end
        end

        % Value changed function: EPOCHSpin
        function EPOCHSpinValueChanged(app, event)
            lastEpoch = app.curEpoch;
            app.curEpoch = app.EPOCHSpin.Value;
            if app.curEpoch >= app.dataParams.epochS || app.curEpoch <= 1
                return
            else
                app.EPOCHSpin.Value = app.curEpoch;
                
                updateEEGplot(app, 2)
                
                updateHYPlot(app)
                
                if lastEpoch < app.curEpoch
                    updateEEGextra(app,2)
                else
                    updateEEGextra(app,1)
                end
            end
            
        end

        % Button down function: HYPg
        function HYPgButtonDown(app, event)
            %             cp=get(app.HYPg,'CurrentPoint');
            %             x=cp(1,1)
            %             y=cp(1,2)
        end

        % Value changed function: smP1
        function smP1ValueChanged(app, event)
            updateEEGplot(app , 2)
            
        end

        % Value changed function: smP2
        function smP2ValueChanged(app, event)
            updateEEGplot(app , 2)
            
        end

        % Value changed function: smP3
        function smP3ValueChanged(app, event)
            updateEEGplot(app , 2)
            
        end

        % Value changed function: smP4
        function smP4ValueChanged(app, event)
            updateEEGplot(app , 2)
        end

        % Button pushed function: WB
        function WBButtonPushed(app, event)
            updateButtonAct(app,'W')
        end

        % Button pushed function: RB
        function RBButtonPushed(app, event)
            updateButtonAct(app,'R')
        end

        % Button pushed function: N1B
        function N1BButtonPushed(app, event)
            updateButtonAct(app,'N1')
        end

        % Button pushed function: N2B
        function N2BButtonPushed(app, event)
            updateButtonAct(app,'N2')
        end

        % Button pushed function: N3B
        function N3BButtonPushed(app, event)
            updateButtonAct(app,'N3')
        end

        % Value changed function: hypSw
        function hypSwValueChanged(app, event)
            
            updateHYPlot(app)
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            if ~strcmp(app.TabGroup.SelectedTab.Title,'SleepScore')
                return
            else
                
                switch key
                    case '1'
                        if app.zoomINDex == 1
                            updateButtonAct(app,'N1')
                        else
                            return
                        end
                    case '2'
                        if app.zoomINDex == 1
                            updateButtonAct(app,'N2')
                        else
                            return
                        end
                    case '3'
                        if app.zoomINDex == 1
                            updateButtonAct(app,'N3')
                        else
                            return
                        end
                    case 'r'
                        if app.zoomINDex == 1
                            updateButtonAct(app,'R')
                        else
                            return
                        end
                    case 'w'
                        if app.zoomINDex == 1
                            updateButtonAct(app,'W')
                        else
                            return
                        end
                    case 'uparrow'
                        updateZoom(app , 1)
                    case 'downarrow'
                        updateZoom(app , 2)
                    case 'leftarrow'
                        
                        
                        app.curEpoch = app.curEpoch - 1;
                        if app.curEpoch < 1
                            app.curEpoch = 1;
                            return
                        else
                            if app.zoomINDex == 1
                                app.EPOCHSpin.Value = app.curEpoch;
                                updateEEGplot(app, 2)
                                updateHYPlot(app)
                                updateEEGextra(app,0)
                            else
                                app.EPOCHSpin.Value = app.curEpoch;
                                updateEEGplot(app, 3)
                            end
                        end
                        
                        
                    case 'rightarrow'
                        
                        app.curEpoch = app.curEpoch + 1;
                        if app.curEpoch > app.dataParams.epochS
                            app.curEpoch = app.dataParams.epochS;
                            return
                        else
                            if app.zoomINDex == 1
                                app.EPOCHSpin.Value = app.curEpoch;
                                updateEEGplot(app, 2)
                                updateHYPlot(app)
                                updateEEGextra(app,1)
                            else
                                app.EPOCHSpin.Value = app.curEpoch;
                                updateEEGplot(app, 3)
                                
                            end
                        end
                        
                    otherwise
                        return
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.SETD = uimenu(app.UIFigure);
            app.SETD.MenuSelectedFcn = createCallbackFcn(app, @SETDMenuSelected, true);
            app.SETD.Text = 'SET Directory';
            app.NewSub = uimenu(app.UIFigure);
            app.NewSub.MenuSelectedFcn = createCallbackFcn(app, @NewSubMenuSelected, true);
            app.NewSub.Enable = 'off';
            app.NewSub.Text = 'New Subject';
            app.EXITMenu = uimenu(app.UIFigure);
            app.EXITMenu.MenuSelectedFcn = createCallbackFcn(app, @EXITMenuSelected, true);
            app.EXITMenu.Text = 'EXIT';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Settings = uitab(app.TabGroup);
            app.Settings.Title = 'Settings';
            app.EEGElectrodesPanel = uipanel(app.Settings);
            app.EEGElectrodesPanel.Enable = 'off';
            app.EEGElectrodesPanel.Title = 'EEG Electrodes';
            app.eeg1dd = uidropdown(app.EEGElectrodesPanel);
            app.eeg1dd.Items = {'EEG1', 'EEG2', 'EEG3', 'EEG4'};
            app.eeg1dd.Enable = 'off';
            app.eeg1dd.Value = 'EEG1';
            app.eeg3dd = uidropdown(app.EEGElectrodesPanel);
            app.eeg3dd.Items = {'EEG1', 'EEG2', 'EEG3', 'EEG4'};
            app.eeg3dd.Enable = 'off';
            app.eeg3dd.Value = 'EEG3';
            app.eeg4dd = uidropdown(app.EEGElectrodesPanel);
            app.eeg4dd.Items = {'EEG1', 'EEG2', 'EEG3', 'EEG4'};
            app.eeg4dd.Enable = 'off';
            app.eeg4dd.Value = 'EEG4';
            app.eeg2dd = uidropdown(app.EEGElectrodesPanel);
            app.eeg2dd.Items = {'EEG1', 'EEG2', 'EEG3', 'EEG4'};
            app.eeg2dd.Enable = 'off';
            app.eeg2dd.Value = 'EEG2';
            app.subjectLpanel = uipanel(app.Settings);
            app.subjectLpanel.Enable = 'off';
            app.subjectLpanel.Title = 'Subject List';
            app.subjectL = uilistbox(app.subjectLpanel);
            app.subjectL.Items = {'Subjects...', ''};
            app.subjectL.ValueChangedFcn = createCallbackFcn(app, @subjectLValueChanged, true);
            app.subjectL.Value = 'Subjects...';
            app.stScore = uibutton(app.Settings, 'push');
            app.stScore.ButtonPushedFcn = createCallbackFcn(app, @stScoreButtonPushed, true);
            app.stScore.Enable = 'off';
            app.stScore.Text = 'Start Scoring';
            app.resetElp = uibutton(app.Settings, 'push');
            app.resetElp.Enable = 'off';
            app.resetElp.Text = 'Reset Electrode Positions';
            app.instimage = uiimage(app.Settings);
            app.smoothPanel = uipanel(app.Settings);
            app.smoothPanel.Enable = 'off';
            app.smoothPanel.Title = 'Smooth Signals: Moving Ave.';
            app.Plot1DropDownLabel = uilabel(app.smoothPanel);
            app.Plot1DropDownLabel.Enable = 'off';
            app.Plot1DropDownLabel.Text = 'Plot 1';
            app.smP1 = uidropdown(app.smoothPanel);
            app.smP1.Items = {'1', '5', '10', '20'};
            app.smP1.ValueChangedFcn = createCallbackFcn(app, @smP1ValueChanged, true);
            app.smP1.Enable = 'off';
            app.smP1.Value = '1';
            app.Plot2DropDownLabel = uilabel(app.smoothPanel);
            app.Plot2DropDownLabel.Enable = 'off';
            app.Plot2DropDownLabel.Text = 'Plot 2';
            app.smP2 = uidropdown(app.smoothPanel);
            app.smP2.Items = {'1', '5', '10', '20'};
            app.smP2.ValueChangedFcn = createCallbackFcn(app, @smP2ValueChanged, true);
            app.smP2.Enable = 'off';
            app.smP2.Value = '1';
            app.Plot3DropDownLabel = uilabel(app.smoothPanel);
            app.Plot3DropDownLabel.Enable = 'off';
            app.Plot3DropDownLabel.Text = 'Plot 3';
            app.smP3 = uidropdown(app.smoothPanel);
            app.smP3.Items = {'1', '5', '10', '20'};
            app.smP3.ValueChangedFcn = createCallbackFcn(app, @smP3ValueChanged, true);
            app.smP3.Enable = 'off';
            app.smP3.Value = '1';
            app.Plot4DropDownLabel = uilabel(app.smoothPanel);
            app.Plot4DropDownLabel.Enable = 'off';
            app.Plot4DropDownLabel.Text = 'Plot 4';
            app.smP4 = uidropdown(app.smoothPanel);
            app.smP4.Items = {'1', '5', '10', '20'};
            app.smP4.ValueChangedFcn = createCallbackFcn(app, @smP4ValueChanged, true);
            app.smP4.Enable = 'off';
            app.smP4.Value = '1';
            app.SleepScore = uitab(app.TabGroup);
            app.SleepScore.Title = 'SleepScore';
            app.SCORINGPanel = uipanel(app.SleepScore);
            app.SCORINGPanel.Enable = 'off';
            app.SCORINGPanel.Title = 'SCORING Panel';
            app.epochB = uibutton(app.SCORINGPanel, 'push');
            app.epochB.ButtonPushedFcn = createCallbackFcn(app, @epochBButtonPushed, true);
            app.epochB.Text = '<- Epoch';
            app.epochF = uibutton(app.SCORINGPanel, 'push');
            app.epochF.ButtonPushedFcn = createCallbackFcn(app, @epochFButtonPushed, true);
            app.epochF.Text = 'Epoch ->';
            app.EPOCHSpinnerLabel = uilabel(app.SCORINGPanel);
            app.EPOCHSpinnerLabel.Text = 'EPOCH';
            app.EPOCHSpin = uispinner(app.SCORINGPanel);
            app.EPOCHSpin.ValueChangedFcn = createCallbackFcn(app, @EPOCHSpinValueChanged, true);
            app.EPOCHSpin.Value = 1;
            app.WB = uibutton(app.SCORINGPanel, 'push');
            app.WB.ButtonPushedFcn = createCallbackFcn(app, @WBButtonPushed, true);
            app.WB.Text = 'W';
            app.RB = uibutton(app.SCORINGPanel, 'push');
            app.RB.ButtonPushedFcn = createCallbackFcn(app, @RBButtonPushed, true);
            app.RB.Text = 'R';
            app.N1B = uibutton(app.SCORINGPanel, 'push');
            app.N1B.ButtonPushedFcn = createCallbackFcn(app, @N1BButtonPushed, true);
            app.N1B.Text = 'N1';
            app.N2B = uibutton(app.SCORINGPanel, 'push');
            app.N2B.ButtonPushedFcn = createCallbackFcn(app, @N2BButtonPushed, true);
            app.N2B.Text = 'N2';
            app.N3B = uibutton(app.SCORINGPanel, 'push');
            app.N3B.ButtonPushedFcn = createCallbackFcn(app, @N3BButtonPushed, true);
            app.N3B.Text = 'N3';
            app.totEp = uilabel(app.SCORINGPanel);
            app.totEp.Text = 'TOTAL';
            app.HypnoP = uipanel(app.SleepScore);
            app.HypnoP.Enable = 'off';
            app.HypnoP.Title = 'Hypnogram - ';
            app.hypSw = uiswitch(app.HypnoP, 'slider');
            app.hypSw.ValueChangedFcn = createCallbackFcn(app, @hypSwValueChanged, true);
            app.EEG_extra = uipanel(app.SleepScore);
            app.EEG_extra.Enable = 'off';
            app.EEG_extra.Title = 'Additional information';
            app.NotesTextAreaLabel = uilabel(app.EEG_extra);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.notesN1 = uitextarea(app.EEG_extra);
            app.ArousalSpinnerLabel = uilabel(app.EEG_extra);
            app.ArousalSpinnerLabel.Text = 'Arousal #';
            app.arouseN1 = uispinner(app.EEG_extra);
            app.ZoominSecondsGaugeLabel = uilabel(app.SleepScore);
            app.ZoominSecondsGaugeLabel.Text = 'Zoom in Seconds';
            app.zoomIN = uigauge(app.SleepScore, 'linear');
            app.zoomIN.Limits = [30 240];
            app.zoomIN.MajorTicks = [30 60 120 180 240];
            app.zoomIN.MinorTicks = [];
            app.zoomIN.Value = 30;
            app.Panel = uipanel(app.SleepScore);
            app.Panel.Title = 'Panel';
            app.GridLayout = uigridlayout(app.Panel);
            app.EEG1 = uiaxes(app.GridLayout);
            app.EEG1.XTick = [];
            app.EEG1.YTick = [];
            app.EEG2 = uiaxes(app.GridLayout);
            app.EEG2.XTick = [];
            app.EEG2.YTick = [];
            app.EEG3 = uiaxes(app.GridLayout);
            app.EEG3.XTick = [];
            app.EEG3.YTick = [];
            app.EEG4 = uiaxes(app.GridLayout);
            app.EEG4.XTick = [];
            app.EEG4.YTick = [];
            app.EOG = uiaxes(app.GridLayout);
            app.EOG.XTick = [];
            app.EOG.YTick = [];
            app.HYPg = uiaxes(app.SleepScore);
            app.HYPg.YLim = [0 6];
            app.HYPg.XTick = [];
            app.HYPg.XTickLabel = '';
            app.HYPg.YTick = [1 2 3 4 5];
            app.HYPg.YTickLabel = {'W'; 'R'; 'N1'; 'N2'; 'N3'};
            app.HYPg.ButtonDownFcn = createCallbackFcn(app, @HYPgButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SleepStage_02072021

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end