classdef SleepStage_Consensus_v6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SETD                     matlab.ui.container.Menu
        NewSub                   matlab.ui.container.Menu
        EXITMenu                 matlab.ui.container.Menu
        TabGroup                 matlab.ui.container.TabGroup
        SleepScore               matlab.ui.container.Tab
        MicrovoltsPanel          matlab.ui.container.Panel
        mv5                      matlab.ui.control.Spinner
        YaxisZoomSpinnerLabel_5  matlab.ui.control.Label
        mv4                      matlab.ui.control.Spinner
        YaxisZoomSpinnerLabel_4  matlab.ui.control.Label
        mv3                      matlab.ui.control.Spinner
        YaxisZoomSpinnerLabel_3  matlab.ui.control.Label
        mv2                      matlab.ui.control.Spinner
        YaxisZoomSpinnerLabel_2  matlab.ui.control.Label
        mv1                      matlab.ui.control.Spinner
        YaxisZoomSpinnerLabel    matlab.ui.control.Label
        SAVEDATA                 matlab.ui.control.Button
        useKEYS                  matlab.ui.control.Switch
        USEKEYSSwitchLabel       matlab.ui.control.Label
        stScore                  matlab.ui.control.Button
        subjectLpanel            matlab.ui.container.Panel
        subjectL                 matlab.ui.control.ListBox
        EEGElectrodesPanel       matlab.ui.container.Panel
        eeg5dd                   matlab.ui.control.DropDown
        eeg2dd                   matlab.ui.control.DropDown
        eeg4dd                   matlab.ui.control.DropDown
        eeg3dd                   matlab.ui.control.DropDown
        eeg1dd                   matlab.ui.control.DropDown
        Electrophysiology        matlab.ui.container.Panel
        GridLayout               matlab.ui.container.GridLayout
        EEG5                     matlab.ui.control.UIAxes
        EEG4                     matlab.ui.control.UIAxes
        EEG3                     matlab.ui.control.UIAxes
        EEG2                     matlab.ui.control.UIAxes
        EEG1                     matlab.ui.control.UIAxes
        zoomIN                   matlab.ui.control.LinearGauge
        ZoominSecondsGaugeLabel  matlab.ui.control.Label
        EEG_extra                matlab.ui.container.Panel
        arouseN1                 matlab.ui.control.Spinner
        ArousalSpinnerLabel      matlab.ui.control.Label
        notesN1                  matlab.ui.control.TextArea
        NotesTextAreaLabel       matlab.ui.control.Label
        HypnoP                   matlab.ui.container.Panel
        hypSw                    matlab.ui.control.Switch
        SCORINGPanel             matlab.ui.container.Panel
        totEp                    matlab.ui.control.Label
        N3B                      matlab.ui.control.Button
        N2B                      matlab.ui.control.Button
        N1B                      matlab.ui.control.Button
        RB                       matlab.ui.control.Button
        WB                       matlab.ui.control.Button
        EPOCHSpin                matlab.ui.control.Spinner
        EPOCHSpinnerLabel        matlab.ui.control.Label
        epochF                   matlab.ui.control.Button
        epochB                   matlab.ui.control.Button
        HYPg                     matlab.ui.control.UIAxes
        VIDEOPlayBackTab         matlab.ui.container.Tab
        PSGvPanel                matlab.ui.container.Panel
        PSGvSel                  matlab.ui.control.DropDown
        PSGSIGNALDropDownLabel   matlab.ui.control.Label
        playSpeed                matlab.ui.container.ButtonGroup
        thrX                     matlab.ui.control.ToggleButton
        twoX                     matlab.ui.control.ToggleButton
        oneX                     matlab.ui.control.ToggleButton
        PlaybackPanel            matlab.ui.container.Panel
        StopButton               matlab.ui.control.Button
        PlayButton               matlab.ui.control.Button
        vidEpochPanel            matlab.ui.container.Panel
        curVIDepoch              matlab.ui.control.Spinner
        EPOCHSpinnerLabel_2      matlab.ui.control.Label
        VIDEEGaxes               matlab.ui.control.UIAxes
        vidAXES                  matlab.ui.control.UIAxes
        SummaryData              matlab.ui.container.Tab
        FinalSummaryButton       matlab.ui.control.Button
        FINALSummaryPanel        matlab.ui.container.Panel
        f_n3n                    matlab.ui.control.Label
        f_n3p                    matlab.ui.control.Label
        N3Label_10               matlab.ui.control.Label
        N3Label_9                matlab.ui.control.Label
        f_n2n                    matlab.ui.control.Label
        f_n2p                    matlab.ui.control.Label
        N2Label_10               matlab.ui.control.Label
        N2Label_9                matlab.ui.control.Label
        f_n1n                    matlab.ui.control.Label
        f_n1p                    matlab.ui.control.Label
        N1Label_10               matlab.ui.control.Label
        N1Label_9                matlab.ui.control.Label
        f_rn                     matlab.ui.control.Label
        f_rp                     matlab.ui.control.Label
        RLabel_10                matlab.ui.control.Label
        RLabel_9                 matlab.ui.control.Label
        f_wn                     matlab.ui.control.Label
        f_wp                     matlab.ui.control.Label
        WLabel_10                matlab.ui.control.Label
        WLabel_9                 matlab.ui.control.Label
        MSSummaryPanel           matlab.ui.container.Panel
        ms_n3n                   matlab.ui.control.Label
        ms_n3p                   matlab.ui.control.Label
        N3Label_8                matlab.ui.control.Label
        N3Label_7                matlab.ui.control.Label
        ms_n2n                   matlab.ui.control.Label
        ms_n2p                   matlab.ui.control.Label
        N2Label_8                matlab.ui.control.Label
        N2Label_7                matlab.ui.control.Label
        ms_n1n                   matlab.ui.control.Label
        ms_n1p                   matlab.ui.control.Label
        N1Label_8                matlab.ui.control.Label
        N1Label_7                matlab.ui.control.Label
        ms_rn                    matlab.ui.control.Label
        ms_rp                    matlab.ui.control.Label
        RLabel_8                 matlab.ui.control.Label
        RLabel_7                 matlab.ui.control.Label
        ms_wn                    matlab.ui.control.Label
        ms_wp                    matlab.ui.control.Label
        WLabel_8                 matlab.ui.control.Label
        WLabel_7                 matlab.ui.control.Label
        CKSummaryPanel           matlab.ui.container.Panel
        ck_n3n                   matlab.ui.control.Label
        ck_n3p                   matlab.ui.control.Label
        N3Label_6                matlab.ui.control.Label
        N3Label_5                matlab.ui.control.Label
        ck_n2n                   matlab.ui.control.Label
        ck_n2p                   matlab.ui.control.Label
        N2Label_6                matlab.ui.control.Label
        N2Label_5                matlab.ui.control.Label
        ck_n1n                   matlab.ui.control.Label
        ck_n1p                   matlab.ui.control.Label
        N1Label_6                matlab.ui.control.Label
        N1Label_5                matlab.ui.control.Label
        ck_rn                    matlab.ui.control.Label
        ck_rp                    matlab.ui.control.Label
        RLabel_6                 matlab.ui.control.Label
        RLabel_5                 matlab.ui.control.Label
        ck_wn                    matlab.ui.control.Label
        ck_wp                    matlab.ui.control.Label
        WLabel_6                 matlab.ui.control.Label
        WLabel_5                 matlab.ui.control.Label
        STSummaryPanel           matlab.ui.container.Panel
        st_n3n                   matlab.ui.control.Label
        st_n3p                   matlab.ui.control.Label
        N3Label_4                matlab.ui.control.Label
        N3Label_3                matlab.ui.control.Label
        st_n2n                   matlab.ui.control.Label
        st_n2p                   matlab.ui.control.Label
        N2Label_4                matlab.ui.control.Label
        N2Label_3                matlab.ui.control.Label
        st_n1n                   matlab.ui.control.Label
        st_n1p                   matlab.ui.control.Label
        N1Label_4                matlab.ui.control.Label
        N1Label_3                matlab.ui.control.Label
        st_rn                    matlab.ui.control.Label
        st_rp                    matlab.ui.control.Label
        RLabel_4                 matlab.ui.control.Label
        RLabel_3                 matlab.ui.control.Label
        st_wn                    matlab.ui.control.Label
        st_wp                    matlab.ui.control.Label
        WLabel_4                 matlab.ui.control.Label
        WLabel_3                 matlab.ui.control.Label
        LWSummaryPanel           matlab.ui.container.Panel
        lw_n3n                   matlab.ui.control.Label
        lw_n3p                   matlab.ui.control.Label
        N3Label_2                matlab.ui.control.Label
        N3Label                  matlab.ui.control.Label
        lw_n2n                   matlab.ui.control.Label
        lw_n2p                   matlab.ui.control.Label
        N2Label_2                matlab.ui.control.Label
        N2Label                  matlab.ui.control.Label
        lw_n1n                   matlab.ui.control.Label
        lw_n1p                   matlab.ui.control.Label
        N1Label_2                matlab.ui.control.Label
        N1Label                  matlab.ui.control.Label
        lw_rn                    matlab.ui.control.Label
        lw_rp                    matlab.ui.control.Label
        RLabel_2                 matlab.ui.control.Label
        RLabel                   matlab.ui.control.Label
        lw_wn                    matlab.ui.control.Label
        lw_wp                    matlab.ui.control.Label
        WLabel_2                 matlab.ui.control.Label
        WLabel                   matlab.ui.control.Label
        sumSleep                 matlab.ui.control.UIAxes
    end

    %%%% WORKING ON
    % 1. Check VIDEO IS working
    % 2. FIX SLEEP SCORE BUtton (i.e., 'W') - it advances too far
    % 3. Check different SCORE agreements (e.g., 3 agreements and 1
    % disagree)

    properties (Access = private)
        inDlcs % Internal directory locations
        subSel % Selected subject to work on
        subData % Primary sleep data
        dataParams % structure to characterize data
        curEpoch % Current Epoch
        BlindHpy
        GenHpy
        zoomNOW
        zoomINDex
        EEGbutIDS
        videoLOC
        VidSelname
        VidSelLOC
        VidSelIndLcs
        VidNumInd
        vidEpoch
        vEEGbutID
        curVIDdat
        CURframe
        disAgEpoch
        AgEpoch
        AgEpochID
        ALLscores
        VidAvailable
        finalScores
        curDISepoch
        disAgNUM
        hypPLOTs
    end

    methods (Access = private)

        function updateParams(app , stage)

            % Determine how to update parameters
            switch stage
                % Initialize parameters - ADD stuff to table if needed
                case 1
                    % Number of 30 second epochs
                    app.dataParams.epochS = height(app.subData);
                    % Set up Hypnogram plot
                    app.HYPg.XLim = [1, app.dataParams.epochS];
                    % ADD TEXT to EPOCH spinner
                    app.totEp.Text = [num2str(app.dataParams.epochS), ' Epochs'];
                    % Number of EEG electrodes
                    standardEEG = {'F3','Fz','A1','C3','Cz','A2','O1','EOG1','EOG2','Chin1','Chin2','ChinZ'};
                    tmpCols = app.subData.Properties.VariableNames;
                    app.dataParams.EEGnum = sum(contains(tmpCols,standardEEG));
                    app.dataParams.EEGid = tmpCols(contains(tmpCols,standardEEG));
                    % ID of EEG electrodes
                    eegID = cell(app.dataParams.EEGnum,1);
                    eegIND = zeros(app.dataParams.EEGnum,1);
                    for ei = 1:app.dataParams.EEGnum
                        tmpEEGid = app.dataParams.EEGid{ei};
                        tmpEEGind = ismember(tmpCols,tmpEEGid);
                        eegID{ei} = tmpCols{tmpEEGind};
                        eegIND(ei) = find(tmpEEGind);
                    end
                    app.dataParams.EEGtab = table(eegIND, eegID);
                    %%%% FIND discordant epochs
                    fullSET = [app.subData.LW,app.subData.ST,app.subData.CK,app.subData.MS];
                    app.ALLscores = fullSET; % all Scores [4 sets]
                    app.disAgEpoch = false(height(fullSET),1); % Logical array of disagree
                    % Check for FINALSCORES
                    if ismember(app.subData.Properties.VariableNames,'FINALSCORE')

                        % Do something
                        app.finalScores = app.subData.FINALSCORE;
                        for tmpScore = 1:height(fullSET)
                            tpS = app.finalScores(tmpScore);
                            if isempty(tpS)
                                app.disAgEpoch(tmpScore) = true;
                            end
                        end
                        app.AgEpoch = ~(app.disAgEpoch);
                        app.AgEpochID = app.finalScores(app.AgEpoch);
                    else
                        app.finalScores = cell(height(fullSET),1); % This will get updated throughout
                        % CREATE DISAGREE LOGICAL ARRAY
                        for tmpScore = 1:height(fullSET)

                            tmpRow = fullSET(tmpScore,:);
                            % Determine if there are any empty cells
                            empCk = any(cellfun(@(x) isempty(x), tmpRow, 'UniformOutput',true));
                            % Get unique vals
                            uVs = numel(unique(tmpRow));
                            if empCk
                                app.disAgEpoch(tmpScore) = true;
                            elseif uVs > 1
                                app.disAgEpoch(tmpScore) = true;
                            end
                        end
                        app.AgEpoch = ~(app.disAgEpoch);
                        app.AgEpochID = app.subData.LW(app.AgEpoch);
                        % For Final Scores
                        % 1. Fill agreed epoches with score
                        % 2. Fill non-agreed with empty cell
                        app.finalScores(app.AgEpoch,1) = app.ALLscores(app.AgEpoch,1);
                    end

                    app.disAgNUM = find(app.disAgEpoch);
                    app.curDISepoch = 1;

                    % FOR HYPNOGRAM PLOT ^^^^^^^^^^^^^^^^^^^^^^^^^^^^^^^^^
                    sleePids = {'N3','N2','N1','R','W'};
                    plotNUMs = nan(height(app.ALLscores),5);
                    scoreVals = app.ALLscores(:,1);
                    allScores = app.ALLscores;
                    for pi = 1:size(plotNUMs,2)
                        for si = 1:length(sleePids)

                            switch pi
                                case 1

                                    logLoc = ismember(scoreVals,sleePids{si}) & ~app.disAgEpoch;
                                    plotNUMs(logLoc,pi) = si;

                                case {2,3,4,5}

                                    logLoc = ismember(allScores(:,pi-1),sleePids{si}) & app.disAgEpoch;
                                    plotNUMs(logLoc,pi) = si;

                            end

                        end
                    end
                    app.hypPLOTs = plotNUMs;
                    % FOR HYPNOGRAM PLOT ^^^^^^^^^^^^^^^^^^^^^^^^^^^^^^^^^

                    app.dataParams.sampPts = height(app.subData.(eegID{1}){1});

                    % CHECK if BlindHpy is empty or not and fix toggle
                    app.HypnoP.Enable = 'on';

                    % Actual Time
                    app.dataParams.Atime =  app.subData.ActTime;

                    % CHECK if Arousal column is available
                    if ~ismember('arousaln',app.subData.Properties.VariableNames)
                        app.subData.arousaln = zeros(height(app.subData),1);
                        app.dataParams.aroCt = app.subData.arousaln;
                    else
                        app.dataParams.aroCt = app.subData.arousaln;
                    end

                    % CHECK if notes column is avialable
                    if ~ismember('notes',app.subData.Properties.VariableNames)
                        app.subData.notes = repmat({''},height(app.subData),1);
                        app.dataParams.notes = app.subData.notes;
                    else
                        app.dataParams.notes = app.subData.notes;
                    end

                    % POPULATE Summary Table
                    scoreIDS = {'LW','ST','CK','MS'};
                    sleEps = {'W','R','N1','N2','N3'};
                    for sci = 1:length(scoreIDS)
                        noTempty = cellfun(@(x) ~isempty(x) , app.ALLscores(:,sci),...
                            'UniformOutput',true);
                        tmpTot = sum(noTempty);
                        for sli = 1:length(sleEps)
                            tmpCount = sum(ismember(app.ALLscores(:,sci),sleEps{sli}) & noTempty);
                            tmpPer = round((tmpCount/tmpTot)*100,1);

                            scoreIDDD = [lower(scoreIDS{sci}),'_'];
                            sleEEPid = lower(sleEps{sli});

                            app.([scoreIDDD,sleEEPid,'p']).Text = num2str(tmpPer);
                            app.([scoreIDDD,sleEEPid,'n']).Text = num2str(tmpCount);

                        end
                    end

                    % PLOT SUMMARY DATA
                    % Will need to UPDATE data in Final and hypPLOTS

                    plotNUMsL = nan(height(app.ALLscores),4);
                    for piL = 1:size(plotNUMsL,2)
                        for siiL = 1:length(sleePids)
                            logLocL = ismember(allScores(:,piL),sleePids{siiL});
                            plotNUMsL(logLocL,piL) = siiL;
                        end
                    end
                    xPoints = transpose(1:app.dataParams.epochS);
                    colORS = 'rgbk';
                    cla(app.sumSleep)
                    hold(app.sumSleep,'on')
                    for iL = 1:4
                        plot(app.sumSleep, xPoints,plotNUMsL(:,iL),"Color",colORS(iL))
                    end
                    legend(app.sumSleep,scoreIDS)
                    hold(app.sumSleep,'off')
                    ylim(app.sumSleep,[0 6])

                    % Initialize ZoomIndex
                    app.zoomINDex = 1;
            end


        end

        function updateEEGplot(app , stage)

            switch stage
                case 1 % Initialize

                    % SET to first disAGREE
                    app.curEpoch = find(app.disAgEpoch,1,'first'); % Reset current epoch to 1
                    app.curVIDepoch.Value = find(app.disAgEpoch,1,'first');
                    app.EPOCHSpin.Value = app.curEpoch;
                    for st1 = 1:5  % NUMBER of EEG PLOTS
                        % Determine how to select the correct column vector
                        % based on the EEG electrode from dropdown
                        getEEGbuttons(app)

                        colID1 = app.EEGbutIDS(st1);
                        colID2 = app.dataParams.EEGtab.eegIND(ismember(app.dataParams.EEGtab.eegID,colID1));

                        vecCell = app.subData{app.curEpoch,colID2};
                        eegVec = vecCell{1};

                        ylabel(app.(['EEG',num2str(st1)]),colID1{1})
                        smEegVec2 = eegVec;

                        % Fix time vector
                        timeVEC = linspace(1,app.dataParams.sampPts/100,app.dataParams.sampPts);

                        % Modify y-axis
                        plot(app.(['EEG',num2str(st1)]),timeVEC,smEegVec2)
                        [yTmax , yTmin] = getYlimVs(app,smEegVec2,st1);

                        yTmax = yTmax*app.(['mv',num2str(st1)]).Value;
                        yTmin = yTmin*app.(['mv',num2str(st1)]).Value;

                        ylim(app.(['EEG',num2str(st1)]),[yTmin , yTmax])

                        % Modify y-axis
                        if  contains(app.dataParams.EEGtab.eegID{st1},'EMG')
                            udpateXticktime(app , st1 , stage, yTmax, yTmin)
                            continue
                        else
                            % NEW SUBtitle
                            if app.disAgEpoch(app.curEpoch)
                                curSlepStg = 'NONE';
                            else
                                curSlepStg = app.ALLscores{app.curEpoch,1};
                            end

                            epochNN = num2str(app.curEpoch);
                            tIME = datestr(timeofday(app.dataParams.Atime(app.curEpoch)));
                            txt = ['Stage:  ',curSlepStg,'         EPOCH: ',epochNN...
                                '          TIME: ' ,tIME];
                            subtitle(app.(['EEG',num2str(st1)]),txt)
                            app.(['EEG',num2str(st1)]).TitleHorizontalAlignment = 'left';

                        end

                        % Update x-axis tick-marks
                        udpateXticktime(app , st1 , stage, yTmax, yTmin)

                    end

                case 2 % Move back or forward
                    for st2 = 1:5 % NUMBER of EEG PLOTS
                        getEEGbuttons(app)

                        colID1 = app.EEGbutIDS(st2);
                        colID2 = app.dataParams.EEGtab.eegIND(ismember(app.dataParams.EEGtab.eegID,colID1));

                        vecCell = app.subData{app.curEpoch,colID2};
                        eegVec = vecCell{1};

                        ylabel(app.(['EEG',num2str(st2)]),colID1{1})

                        smEegVec2 = eegVec;

                        % Fix time vector
                        timeVEC = linspace(1,app.dataParams.sampPts/100,app.dataParams.sampPts);

                        plot(app.(['EEG',num2str(st2)]),timeVEC, smEegVec2)
                        [yTmax , yTmin] = getYlimVs(app,smEegVec2,st2);

                        yTmax = yTmax*app.(['mv',num2str(st2)]).Value;
                        yTmin = yTmin*app.(['mv',num2str(st2)]).Value;

                        ylim(app.(['EEG',num2str(st2)]),[yTmin , yTmax])

                        if  contains(app.dataParams.EEGtab.eegID{st2},'EMG')
                            udpateXticktime(app , st2 , stage, yTmax, yTmin)
                            continue
                        else

                            % NEW SUBtitle
                            if ismember(app.curEpoch,app.disAgNUM)
                                curSlepStg = 'NONE';
                            else
                                curSlepStg = app.ALLscores{app.curEpoch,1};
                            end

                            epochNN = num2str(app.curEpoch);
                            tIME = datestr(timeofday(app.dataParams.Atime(app.curEpoch)));
                            txt = ['Stage:  ',curSlepStg,'         EPOCH: ',epochNN...
                                '          TIME: ' ,tIME];
                            subtitle(app.(['EEG',num2str(st2)]),txt)
                            app.(['EEG',num2str(st2)]).TitleHorizontalAlignment = 'left';

                        end

                        % Update x-axis tick-marks
                        udpateXticktime(app , st2, stage, yTmax, yTmin)

                    end

                case 3 % Show more than one epoch
                    numEpochs2u = round(app.zoomIN.Value/30) - 1;
                    actEpochs2u = app.curEpoch + numEpochs2u;

                    getEEGbuttons(app)

                    for st3 = 1:5 % NUMBER of EEG PLOTS - WAS app.dataParams.EEGnum %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                        colID1 = app.EEGbutIDS(st3);
                        colID2 = app.dataParams.EEGtab.eegIND(ismember(app.dataParams.EEGtab.eegID,colID1));

                        aggVec = [];
                        for ag = app.curEpoch:actEpochs2u
                            aggVec = [aggVec ; app.subData{ag ,colID2}{:}];
                        end

                        vecCell = aggVec;

                        %                         smEegVec = smoothdata(vecCell);
                        smEegVec2 = smoothdata(vecCell , 'movmean', str2double(app.(['smP',num2str(st3)]).Value));

                        ylabel(app.(['EEG',num2str(st3)]),colID1{1})

                        numEpochs = round(app.zoomIN.Value/30);
                        zoomPoints = (app.dataParams.sampPts*numEpochs);
                        timeVEC = linspace(1,zoomPoints/100,zoomPoints);

                        plot(app.(['EEG',num2str(st3)]),timeVEC, smEegVec2)
                        [yTmax , yTmin] = getYlimVs(app,smEegVec2,st3);

                        yTmax = yTmax*app.(['mv',num2str(st3)]).Value;
                        yTmin = yTmin*app.(['mv',num2str(st3)]).Value;

                        ylim(app.(['EEG',num2str(st3)]),[yTmin , yTmax])

                        if  contains(app.dataParams.EEGtab.eegID{st3},'EMG')
                            udpateXticktime(app , st3 , stage, yTmax, yTmin)
                            continue
                        else

                            epochNN = num2str(app.curEpoch);

                            txt = ['EPOCH: ',epochNN];
                            subtitle(app.(['EEG',num2str(st3)]),txt)
                            app.(['EEG',num2str(st3)]).TitleHorizontalAlignment = 'left';

                        end

                        % Update x-axis tick-marks
                        udpateXticktime(app , st3 , stage, yTmax, yTmin)

                    end

            end

        end

        function [yMaxR , yMinR] = getYlimVs(app,vecOfint,si)

            yMax = max(vecOfint);
            yMin = abs(min(vecOfint));
            if yMax > yMin
                yMaxR = yMax;
                yMinR = yMax*-1;
                ylim(app.(['EEG',num2str(si)]),[-1*yMax , yMax])
            else
                yMaxR = yMin;
                yMinR = yMin*-1;
                ylim(app.(['EEG',num2str(si)]),[-1*yMin , yMin])
            end

        end

        function updatePltTxt(app,yTmin,yTmax,si,stage)

            switch stage

                case 1
                    % Update Epoch Text
                    text(app.(['EEG',num2str(si)]),...
                        27,...
                        yTmin + round((yTmin*2*-1)*0.1),...
                        ['Epoch ',num2str(app.curEpoch)],'Color','r')

                    % Update Sleep Stage Text
                    if isempty(app.dataParams.SleepStg{app.curEpoch})
                        curSlepStg = 'NONE';
                    else
                        curSlepStg = app.dataParams.SleepStg{app.curEpoch};
                    end

                    text(app.(['EEG',num2str(si)]),...
                        1,...
                        yTmax - round((yTmax*2)*0.1),...
                        curSlepStg,'Color','k',"FontWeight","bold","FontSize",15)

                    % Update Time
                    strTime = datestr(timeofday(app.dataParams.Atime(app.curEpoch)));

                    text(app.(['EEG',num2str(si)]),...
                        1.5,...
                        yTmin + round((yTmin*2*-1)*0.1),...
                        strTime,'Color','r',"FontWeight","bold")

                case 2
                    % Update Epoch Text
                    text(app.(['EEG',num2str(si)]),...
                        1,...
                        yTmax - round((yTmax*2)*0.1),...
                        ['Epoch ',num2str(app.curEpoch)],'Color','r',"FontWeight","bold")

            end

        end

        function updateButtonAct(app , stageB)

            % UPDATE for DISAGREE - UPDATE FINAL SCORE
            % UPDATE for DISAGREE - UPDATE Disagree LOG index
            % Update Sleep Stage Cell Array - Final Score
            app.finalScores{app.curEpoch} = stageB;
            %             app.hypPLOTs(:,1) = app.finalScores;
            % UPDATE FINAL SCORE PLOT
            updateFINALhyp(app)
            % Update Disagree LOG index
            app.disAgEpoch(app.curEpoch) = false;
            app.disAgNUM = find(app.disAgEpoch);

            if isempty(app.disAgNUM)
                app.curEpoch = 5000;
            else
                app.curEpoch = app.disAgNUM(app.curDISepoch);
            end
            updateEPOCH(app , 'up' , 'spin' , 'b')

        end


        function updateHYPlot(app)

            cla(app.HYPg)
            % Consensus INDEX
            % Will need to UPDATE data in Final and hypPLOTS
            xPoints = transpose(1:app.dataParams.epochS);
            hold(app.HYPg,'on')
            scatter(app.HYPg, xPoints, app.hypPLOTs(:,1), 20, 'MarkerFaceColor','none','MarkerEdgeColor',...
                [0.5 0.5 0.5],'MarkerFaceAlpha',.2,'MarkerEdgeAlpha',.2)

            % Marker Line
            %             line(app.HYPg,[app.curEpoch app.curEpoch], [0 6], 'Color','g','LineStyle','--')
            xline(app.HYPg, app.curEpoch,'LineStyle','-','LineWidth',3,'Color','g')

            % Score IDS
            idInds = {'LW','ST','CK','MS'};
            epocVals = app.hypPLOTs(app.curEpoch,2:5);
            uniScores = unique(epocVals);

            % Modify for showing names along - right unless 100 epochs from
            % the end, then show to the left

            for u = 1:length(uniScores)

                numIndsS = idInds(epocVals == uniScores(u));

                alltScores = join(numIndsS,' ');

                if app.curEpoch < app.dataParams.epochS - 200
                    text(app.HYPg, app.curEpoch,uniScores(u), alltScores,...
                        'FontSize', 24,'FontWeight', 'Bold',...
                        'HorizontalAlignment', 'left');
                else
                    text(app.HYPg, app.curEpoch,uniScores(u), alltScores,...
                        'FontSize', 24,'FontWeight', 'Bold',...
                        'HorizontalAlignment', 'right');
                end

            end
            hold(app.HYPg,'off')

        end

        % UPDATE ANNOTATION PLOT
        %%% Five states it needs to work
        % Done#1. Button press left and right
        % Done#2. Key press arrow left and right
        % Done#3. Sleep Stage button press - forward
        % Done#4. Sleep key press - forward
        % 5. Change epoch in spinner
        function updateEEGextra(app , FoB)

            % FoB = 1 = forward
            % FoB = ~1 = backward

            % Check if first or last disepoch id
            if (app.curEpoch == app.disAgNUM(1) || app.curEpoch == app.disAgNUM(sum(app.disAgEpoch)))
                FoB = -1;
            end

            if FoB == 1
                % IF DISAGREE IND
                if matches(app.hypSw.Value,'On')
                    lastEpoch = app.curEpoch - 1;
                elseif matches(app.hypSw.Value,'Off')
                    lastEpoch = app.disAgNUM(app.curDISepoch - 1);
                end
            elseif FoB == 0
                % IF DISAGREE IND
                if matches(app.hypSw.Value,'On')
                    lastEpoch = app.curEpoch + 1;
                elseif matches(app.hypSw.Value,'Off')
                    lastEpoch = app.disAgNUM(app.curDISepoch + 1);
                end
            else
                lastEpoch = app.curEpoch;
            end
            % Save current state
            app.dataParams.aroCt(lastEpoch) = app.arouseN1.Value;
            app.dataParams.notes{lastEpoch} = app.notesN1.Value;
            % Show current state
            app.arouseN1.Value = app.dataParams.aroCt(app.curEpoch);
            app.notesN1.Value = app.dataParams.notes{app.curEpoch};
        end

        function updateZoom(app , UD)
            zoomVals = [30, 60, 120, 180, 240];
            curIND = app.zoomINDex;
            switch UD
                case 1
                    newIND = curIND + 1;

                    if newIND > 5
                        return
                    else
                        app.zoomINDex = newIND;
                        app.zoomIN.Value = zoomVals(newIND);
                        % Update zoom plot
                        updateEEGplot(app , 3)
                    end

                case 2
                    newIND = curIND - 1;

                    if newIND < 1
                        return
                    else
                        app.zoomINDex = newIND;
                        app.zoomIN.Value = zoomVals(newIND);
                        % Update zoom plot
                        updateEEGplot(app , 3)
                    end
            end

            if app.zoomINDex ~= 1
                % turn stuff off
                app.EEG_extra.Enable = 'off';
                app.SCORINGPanel.Enable = 'off';
            else
                % turn stuff on
                app.EEG_extra.Enable = 'on';
                app.SCORINGPanel.Enable = 'on';
            end


        end

        function udpateXticktime(app , si , stage , yTmax, yTmin)

            xLIMmax = app.zoomIN.Value;

            % Update Xlim
            xlim(app.(['EEG',num2str(si)]),...
                [1 xLIMmax])

            switch stage
                case {1,2}
                    % Update Xticks
                    xticks(app.(['EEG',num2str(si)]),...
                        floor(linspace(1,xLIMmax,4))) % for 10 second blocks

                    numOFlines10 = round(30/10) - 1;
                    sec10ind = floor(linspace(1,30,4));
                    sec10vals = sec10ind((1:numOFlines10) + 1);

                    numOFlines5 = round(30/5) - 1;
                    sec5ind = floor(linspace(1,30,7));
                    sec5vals = sec5ind((1:numOFlines5) + 1);
                    sec5valsRM = sec5vals(~ismember(sec5vals,sec10vals));
                    fnumOfl5vals = length(sec5valsRM);

                    numOFlines1 = round(30/1) - 1;
                    sec1ind = floor(linspace(1,30,30));
                    sec1vals = sec1ind((1:numOFlines1-1) + 1);
                    sec1valsRM = sec1vals(~ismember(sec1vals,[sec10vals sec5vals]));
                    fnumOfl1vals = length(sec1valsRM);

                    % 1 second
                    line(app.(['EEG',num2str(si)]),...
                        [sec1valsRM;...
                        sec1valsRM],...
                        [repmat(yTmin,1,fnumOfl1vals);repmat(yTmax,1,fnumOfl1vals)],...
                        'Color',[0.85 0.85 0.85],'LineStyle','-','LineWidth',0.25)
                    % 5 seconds
                    line(app.(['EEG',num2str(si)]),...
                        [sec5valsRM; sec5valsRM],...
                        [repmat(yTmin,1,fnumOfl5vals);repmat(yTmax,1,fnumOfl5vals)],...
                        'Color',[0.25 0.25 0.25],'LineStyle','--','LineWidth',0.5)
                    % 10 seconds
                    line(app.(['EEG',num2str(si)]),...
                        [sec10vals; sec10vals],...
                        [repmat(yTmin,1,numOFlines10);repmat(yTmax,1,numOFlines10)],...
                        'Color',[0 0 0],'LineStyle','-.','LineWidth',0.75)

                case 3
                    numEpochs = round(app.zoomIN.Value/30);

                    % Update Xticks
                    xticks(app.(['EEG',num2str(si)]),...
                        floor(linspace(1,xLIMmax,1 + (2*numEpochs)))) % for 15 second blocks

                    % 10 second
                    numOFlines10 = round(app.zoomIN.Value/10) - 1;
                    sec10ind = floor(linspace(1,app.zoomIN.Value,round(app.zoomIN.Value/10) + 1));
                    sec10vals = sec10ind((1:numOFlines10) + 1);
                    % 5 seconds
                    numOFlines5 = round(app.zoomIN.Value/5) - 1;
                    sec5ind = floor(linspace(1,app.zoomIN.Value,round(app.zoomIN.Value/5) + 1));
                    sec5vals = sec5ind((1:numOFlines5) + 1);
                    sec5valsRM = sec5vals(~ismember(sec5vals,sec10vals));
                    fnumOfl5vals = length(sec5valsRM);
                    % 1 seconds
                    numOFlines1 = round(app.zoomIN.Value/1) - 1;
                    sec1ind = floor(linspace(1,app.zoomIN.Value,round(app.zoomIN.Value/1)));
                    sec1vals = sec1ind((1:numOFlines1-1) + 1);
                    sec1valsRM = sec1vals(~ismember(sec1vals,[sec10vals sec5vals]));
                    fnumOfl1vals = length(sec1valsRM);

                    % 10 seconds
                    line(app.(['EEG',num2str(si)]),...
                        [sec10vals; sec10vals],...
                        [repmat(yTmin,1,numOFlines10);repmat(yTmax,1,numOFlines10)],...
                        'Color',[0 0 0],'LineStyle','-.','LineWidth',0.75)
                    % 5 seconds
                    line(app.(['EEG',num2str(si)]),...
                        [sec5valsRM; sec5valsRM],...
                        [repmat(yTmin,1,fnumOfl5vals);repmat(yTmax,1,fnumOfl5vals)],...
                        'Color',[0.25 0.25 0.25],'LineStyle','--','LineWidth',0.5)
                    % 1 second
                    line(app.(['EEG',num2str(si)]),...
                        [sec1valsRM;sec1valsRM],...
                        [repmat(yTmin,1,fnumOfl1vals);repmat(yTmax,1,fnumOfl1vals)],...
                        'Color',[0.85 0.85 0.85],'LineStyle','-','LineWidth',0.25)

            end


        end


        function getEEGbuttons(app)

            app.EEGbutIDS = cell(5,1);

            for ei = 1:5
                app.EEGbutIDS{ei} = app.(['eeg',num2str(ei),'dd']).Value;
            end

        end

        function vidEpoch = grabVidEpoch(app)

            epochNIND = app.VidNumInd == app.curVIDepoch.Value;

            vidEpoch = load(app.VidSelIndLcs{epochNIND});

        end

        function updateVIDep(app)

            app.vidEpoch = grabVidEpoch(app);
            image(app.vidAXES, app.vidEpoch.mov(1).cdata)
            colormap(app.vidAXES,'gray')
            axis(app.vidAXES, 'equal')

            tIND = 1 + (210*(app.curEpoch - 1));
            % Obtain timestamp for Epoch
            strTime = datestr(timeofday(app.dataParams.Atime(app.curEpoch)));

            % Plot Frame number
            text(app.vidAXES, 260, 7, ['Frame ' num2str(tIND)],...
                'FontSize', 20,'Color','r','FontWeight',"normal")
            % Plot Time [Epoch]
            text(app.vidAXES, 260, 15, strTime,'Color','r',...
                "FontWeight","normal","FontSize",20)

        end

        function updateVIDEEGplot(app , STAGE)

            switch STAGE
                case 1
                    if app.VidAvailable

                        app.vEEGbutID = app.PSGvSel.Value;

                        colID1 = app.vEEGbutID;
                        colID2 = app.dataParams.EEGtab.eegIND(ismember(app.dataParams.EEGtab.eegID,colID1));

                        vecCell = app.subData{app.curEpoch,colID2};
                        eegVec = vecCell{1};
                        ylabel(app.VIDEEGaxes,colID1)
                        % Fix time vector
                        timeVEC = linspace(1,app.dataParams.sampPts/100,app.dataParams.sampPts);
                        plot(app.VIDEEGaxes ,timeVEC, eegVec)
                        % CHECK %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        yMax = max(eegVec);
                        yMin = abs(min(eegVec));
                        if yMax > yMin
                            yMaxR = yMax;
                            yMinR = yMax*-1;
                            ylim(app.VIDEEGaxes,[-1*yMax , yMax])
                        else
                            yMaxR = yMin;
                            yMinR = yMin*-1;
                            ylim(app.VIDEEGaxes,[-1*yMin , yMin])
                        end
                        % CHECK %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                        ylim(app.VIDEEGaxes,[yMinR , yMaxR])

                        % NEW SUBtitle
                        if app.disAgEpoch(app.curEpoch)
                            curSlepStg = 'NONE';
                        else
                            curSlepStg = app.ALLscores{app.curEpoch,1};
                        end

                        epochNN = num2str(app.curEpoch);
                        tIME = datestr(timeofday(app.dataParams.Atime(app.curEpoch)));
                        txt = ['Stage:  ',curSlepStg,'         EPOCH: ',epochNN...
                            '          TIME: ' ,tIME];

                        subtitle(app.VIDEEGaxes,txt)
                        app.VIDEEGaxes.TitleHorizontalAlignment = 'left';

                        % Update x-axis tick-marks
                        % Update Xlim
                        xlim(app.VIDEEGaxes,[1 30])

                        % Update Xticks
                        xticks(app.VIDEEGaxes,...
                            floor(linspace(1,30,4))) % for 10 second blocks

                        numOFlines10 = round(30/10) - 1;
                        sec10ind = floor(linspace(1,30,4));
                        sec10vals = sec10ind((1:numOFlines10) + 1);

                        numOFlines5 = round(30/5) - 1;
                        sec5ind = floor(linspace(1,30,7));
                        sec5vals = sec5ind((1:numOFlines5) + 1);
                        sec5valsRM = sec5vals(~ismember(sec5vals,sec10vals));
                        fnumOfl5vals = length(sec5valsRM);

                        numOFlines1 = round(30/1) - 1;
                        sec1ind = floor(linspace(1,30,30));
                        sec1vals = sec1ind((1:numOFlines1-1) + 1);
                        sec1valsRM = sec1vals(~ismember(sec1vals,[sec10vals sec5vals]));
                        fnumOfl1vals = length(sec1valsRM);

                        % 1 second
                        line(app.VIDEEGaxes,...
                            [sec1valsRM;...
                            sec1valsRM],...
                            [repmat(yMinR,1,fnumOfl1vals);repmat(yMaxR,1,fnumOfl1vals)],...
                            'Color',[0.85 0.85 0.85],'LineStyle','-','LineWidth',0.25)
                        % 5 seconds
                        line(app.VIDEEGaxes,...
                            [sec5valsRM; sec5valsRM],...
                            [repmat(yMinR,1,fnumOfl5vals);repmat(yMaxR,1,fnumOfl5vals)],...
                            'Color',[0.25 0.25 0.25],'LineStyle','--','LineWidth',0.5)
                        % 10 seconds
                        line(app.VIDEEGaxes,...
                            [sec10vals; sec10vals],...
                            [repmat(yMinR,1,numOFlines10);repmat(yMaxR,1,numOFlines10)],...
                            'Color',[0 0 0],'LineStyle','-.','LineWidth',0.75)

                        app.curVIDdat = eegVec;

                        curLINE = round((app.CURframe/210)*30,2) + 1;

                        xline(app.VIDEEGaxes,curLINE,"LineWidth",2,"LineStyle",'--',...
                            "Color","m")
                    else
                        return
                    end

                case 2
                    % DO STUFF
                    % Find index for seconds on x-axis
                    % Plot PINK DOTTED LINE with xline


            end

        end


        function updateFINALhyp(app)

            sleePids = {'N3','N2','N1','R','W'};
            finalNUMs = nan(height(app.finalScores),1);
            scoreVals = app.finalScores;
            doubleEm = cellfun(@(x) isempty(x), scoreVals, 'UniformOutput',true);
            scoreVch = scoreVals;
            scoreVch(doubleEm) = {' '};

            for si = 1:length(sleePids)

                logLoc = ismember(scoreVch,sleePids{si}) & ~app.disAgEpoch;
                finalNUMs(logLoc) = si;

            end

            app.hypPLOTs(:,1) = finalNUMs;
        end


        function updateEPOCH(app , direction , changeLoc , stage)

            % Find the nearest offset if changed

            if matches(stage,'a')
                if app.EPOCHSpin.Value ~= app.curEpoch && (app.curEpoch ~= app.disAgNUM(1) &&...
                        app.curEpoch ~= app.disAgNUM(sum(app.disAgEpoch)))
                    changingValue = app.EPOCHSpin.Value; % E.g. 1100

                    [~,newLoc] = min(abs(changingValue -  app.disAgNUM));

                    if newLoc == app.curDISepoch
                        if matches(direction, 'up')
                            app.curDISepoch = app.curDISepoch + 1;
                        else
                            app.curDISepoch = app.curDISepoch - 1;
                        end
                    else
                        app.curDISepoch = newLoc;
                        app.curEpoch = app.disAgNUM(app.curDISepoch);
                    end

                else
                    changeLoc = 'button';

                end


                if matches(direction , 'up')

                    % If all epoch button pressed do this
                    if matches(app.hypSw.Value,'On')
                        if matches(changeLoc,'button')
                            app.curEpoch = app.curEpoch + 1;
                        end

                        if app.curEpoch > app.dataParams.epochS
                            app.curEpoch = app.dataParams.epochS;
                            updateEEGextra(app,-1)
                            app.EPOCHSpin.Value = app.curEpoch;
                            return
                        end
                    elseif matches(app.hypSw.Value, 'Off')
                        if matches(changeLoc,'button')
                            app.curDISepoch = app.curDISepoch + 1;
                        end

                        if app.curDISepoch > length(app.disAgNUM)
                            app.curDISepoch = length(app.disAgNUM);
                            app.curEpoch = app.disAgNUM(app.curDISepoch);
                            updateEEGextra(app,-1)
                            app.EPOCHSpin.Value = app.curEpoch;
                            return
                        else
                            app.curEpoch = app.disAgNUM(app.curDISepoch);
                        end

                    end

                else

                    % If all epoch button pressed do this
                    if matches(app.hypSw.Value,'On')
                        if matches(changeLoc,'button')
                            app.curEpoch = app.curEpoch - 1;
                        end

                        if app.curEpoch < 1
                            app.curEpoch = 1;
                            updateEEGextra(app,-1)
                            app.EPOCHSpin.Value = app.curEpoch;
                            return
                        end
                    elseif matches(app.hypSw.Value, 'Off')
                        if matches(changeLoc,'button')
                            app.curDISepoch = app.curDISepoch - 1;
                        end

                        if app.curDISepoch <= 1
                            app.curDISepoch = 1;
                            app.curEpoch = app.disAgNUM(app.curDISepoch);
                            updateEEGextra(app,-1)
                            app.EPOCHSpin.Value = app.curEpoch;
                            return
                        else
                            app.curEpoch = app.disAgNUM(app.curDISepoch);
                        end

                    end

                end


                % If at the first curDISepoch and used button
                app.EPOCHSpin.Value = app.curEpoch;

                updateEEGplot(app, 2)

                % Update Hypnogram
                updateHYPlot(app)

                % Update EEG extra data
                updateEEGextra(app,1)

                if app.VidAvailable
                    app.curVIDepoch.Value = app.curEpoch;
                    % Update Video view
                    updateVIDep(app)

                    % Update VID EEG plot
                    updateVIDEEGplot(app, 1)
                end

            end

            if matches(stage,'b')

                % do stuff
                if app.curEpoch == 5000
                    uialert(app.UIFigure,'DONE','Done')
                    return
                else
                    updateEEGextra(app,-1)
                    updateEEGplot(app, 2)
                    updateHYPlot(app)
                    app.EPOCHSpin.Value = app.curEpoch;

                    if app.VidAvailable
                        app.curVIDepoch.Value = app.curEpoch;
                        % Update Video view
                        updateVIDep(app)

                        % Update VID EEG plot
                        updateVIDEEGplot(app, 1)
                    end
                end

            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %             screenSize = get(groot,'ScreenSize');
            %             screenWidth = screenSize(3);
            %             screenHeight = screenSize(4);
            %             left = screenWidth*0.1;
            %             bottom = screenHeight*0.1;
            %             width = screenWidth*0.8;
            %             height = screenHeight*0.8;
            %             drawnow;
            %             app.UIFigure.Position = [left bottom width height];


        end

        % Menu selected function: SETD
        function SETDMenuSelected(app, event)
            % Disenable Set directory menu
            app.SETD.Enable = 'off';

            % Select folder with .txt file
            fileFIND = 1;

            while fileFIND
                [txtFile,txtFold] = uigetfile('*.txt','LOCATE UH3NS11376A1_dsLOC.txt');
                if strcmp(txtFile,'UH3NS11376A1_dsLOC.txt')
                    uialert(app.UIFigure,"You have successfully located the data folder!","Data Folder","Icon","info")
                    fileFIND = 0;
                end
            end

            % locate hard coded DS directory
            app.inDlcs.dsLOC1 = txtFold;

            % Navigate to DS and populate List
            cd(app.inDlcs.dsLOC1)
            % Idenitify available recordings
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % [FUTURE - use FILE NAME to populate info] - WILL BE .MAT %%%%
            subDir = dir('*.mat');
            subDirn = {subDir.name};
            app.subjectL.Items = ['...', subDirn];
            % Set VideoDir
            app.videoLOC = [app.inDlcs.dsLOC1 , 'VIDEOdata'];
            app.subjectLpanel.Enable = 'on';

        end

        % Menu selected function: NewSub
        function NewSubMenuSelected(app, event)

            % Reset EEG list
            % Clear plots
            for ei = 1:4
                app.(['eeg',num2str(ei),'dd']).Items = {'EEG1','EEG2','EEG3','EEG4'};
                app.(['eeg',num2str(ei),'dd']).Value = ['EEG',num2str(ei)];
                app.(['EEG',num2str(ei)]).Visible = 'off';
                cla(app.(['EEG',num2str(ei)]))
            end
            % Turn subject list back on
            app.subjectL.Enable = 'on';
            % Turn off new subject
            app.NewSub.Enable = 'off';

            % Sleep Score Panel
            % Turn off Scoring Panel
            app.SCORINGPanel.Enable = 'off';
            % Turn off Hypnogram
            app.HypnoP.Enable = 'off';

        end

        % Menu selected function: EXITMenu
        function EXITMenuSelected(app, event)

            selection = uiconfirm(app.UIFigure,'Close Program?','Confirm Close',...
                'Icon','warning');
            switch selection
                case 'OK'
                    delete(app)
                case 'Cancel'
                    return
            end

        end

        % Button pushed function: epochB
        function epochBButtonPushed(app, event)
            % DEAL WITH DISAGREE

            % If all epoch button pressed do this
            updateEPOCH(app , 'down' , 'button' , 'a')

        end

        % Button pushed function: epochF
        function epochFButtonPushed(app, event)
            % DEAL WITH DISAGREE

            % If all epoch button pressed do this
            updateEPOCH(app , 'up' , 'button', 'a')

        end

        % Value changed function: EPOCHSpin
        function EPOCHSpinValueChanged(app, event)

            lastEpoch = app.curEpoch;
            newEpoch =  app.EPOCHSpin.Value;

            if (lastEpoch - newEpoch) < 0
                direCtion = 'up';
            else
                direCtion = 'down';
            end

            updateEPOCH(app, direCtion , 'spin' , 'a')

        end

        % Button down function: HYPg
        function HYPgButtonDown(app, event)
            %             cp=get(app.HYPg,'CurrentPoint');
            %             x=cp(1,1)
            %             y=cp(1,2)
        end

        % Callback function
        function smP1ValueChanged(app, event)
            updateEEGplot(app , 2)

        end

        % Callback function
        function smP2ValueChanged(app, event)
            updateEEGplot(app , 2)

        end

        % Callback function
        function smP3ValueChanged(app, event)
            updateEEGplot(app , 2)

        end

        % Callback function
        function smP4ValueChanged(app, event)
            updateEEGplot(app , 2)
        end

        % Button pushed function: WB
        function WBButtonPushed(app, event)
            updateButtonAct(app,'W')
        end

        % Button pushed function: RB
        function RBButtonPushed(app, event)
            updateButtonAct(app,'R')
        end

        % Button pushed function: N1B
        function N1BButtonPushed(app, event)
            updateButtonAct(app,'N1')
        end

        % Button pushed function: N2B
        function N2BButtonPushed(app, event)
            updateButtonAct(app,'N2')
        end

        % Button pushed function: N3B
        function N3BButtonPushed(app, event)
            updateButtonAct(app,'N3')
        end

        % Value changed function: hypSw
        function hypSwValueChanged(app, event)

            updateHYPlot(app)

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;

            if strcmp(app.useKEYS.Value,'Off')
                return
            else

                switch key
                    case '1'
                        if app.zoomINDex == 1
                            updateButtonAct(app,'N1')
                        else
                            return
                        end
                    case '2'
                        if app.zoomINDex == 1
                            updateButtonAct(app,'N2')
                        else
                            return
                        end
                    case '3'
                        if app.zoomINDex == 1
                            updateButtonAct(app,'N3')
                        else
                            return
                        end
                    case 'r'
                        if app.zoomINDex == 1
                            updateButtonAct(app,'R')
                        else
                            return
                        end
                    case 'w'
                        if app.zoomINDex == 1
                            updateButtonAct(app,'W')
                        else
                            return
                        end
                    case 'uparrow'
                        updateZoom(app , 1)
                    case 'downarrow'
                        updateZoom(app , 2)
                    case 'leftarrow'


                        app.curEpoch = app.curEpoch - 1;
                        if app.curEpoch < 1
                            app.curEpoch = 1;
                            return
                        else
                            if app.zoomINDex == 1
                                app.EPOCHSpin.Value = app.curEpoch;
                                app.curVIDepoch.Value = app.curEpoch;
                                updateEEGplot(app, 2)
                                updateHYPlot(app)
                                updateEEGextra(app,0)
                                % Update Video view
                                updateVIDep(app)
                                % Update VID EEG plot
                                updateVIDEEGplot(app, 1)
                            else
                                app.EPOCHSpin.Value = app.curEpoch;
                                app.curVIDepoch.Value = app.curEpoch;
                                updateEEGplot(app, 3)
                                % Update Video view
                                updateVIDep(app)
                                % Update VID EEG plot
                                updateVIDEEGplot(app ,1)
                            end
                        end


                    case 'rightarrow'

                        app.curEpoch = app.curEpoch + 1;
                        if app.curEpoch > app.dataParams.epochS
                            app.curEpoch = app.dataParams.epochS;
                            return
                        else
                            if app.zoomINDex == 1
                                app.EPOCHSpin.Value = app.curEpoch;
                                app.curVIDepoch.Value = app.curEpoch;
                                updateEEGplot(app, 2)
                                updateHYPlot(app)
                                updateEEGextra(app,1)
                                % Update Video view
                                updateVIDep(app)
                                % Update VID EEG plot
                                updateVIDEEGplot(app, 1)
                            else
                                app.EPOCHSpin.Value = app.curEpoch;
                                app.curVIDepoch.Value = app.curEpoch;
                                updateEEGplot(app, 3)
                                % Update Video view
                                updateVIDep(app)
                                % Update VID EEG plot
                                updateVIDEEGplot(app, 1)
                            end
                        end

                    otherwise
                        return
                end
            end
        end

        % Value changed function: subjectL
        function subjectLValueChanged(app, event)
            app.subSel = app.subjectL.Value;

            if strcmp(app.subSel,'...')
                return
            end

            % ADD progress bar
            subSelprog = uiprogressdlg(app.UIFigure,'Title','LOADING SUBJECT',...
                'Indeterminate','on');
            drawnow

            %  app.subData = edfread('SC4002E0-PSG.edf');
            tmpLoad = load(app.subjectL.Value,'TT');
            app.subData = tmpLoad.TT;
            % Get and Load Video Folder for subject
            % Extract case name
            app.VidSelname = extractBefore(app.subSel,".");
            % IF MORE THAN ONE .mat in outer folder
            %             app.VidSelLOC = [app.videoLOC , filesep, app.VidSelname];
            app.VidSelLOC = app.videoLOC;
            % CHECK for VIDEO DATA
            % If does not exist
            % Turn off Sleep Video tab
            if exist(app.VidSelLOC,"dir")
                % CD to folder
                cd(app.VidSelLOC)
                % Create cell array of file names
                vidMatD = dir('*.mat');
                vidMatDn = transpose({vidMatD.name});
                % Create cell array of folder locs
                app.VidNumInd = cellfun(@(x) str2double(x),...
                    extractAfter(extractBefore(vidMatDn,'.'),'_'),'UniformOutput',true);
                app.VidSelIndLcs = join([repmat(app.VidSelLOC, numel(vidMatDn) ,1) ,...
                    join([repmat(string(filesep),numel(vidMatDn),1) , string(vidMatDn)],'')],'');

                app.VidAvailable = 1;
            else
                app.VidAvailable = 0;
            end

            % SET FLAG for VIDEO or NO VIDEO


            updateParams(app , 1)

            app.EEGElectrodesPanel.Enable = 'on';
            % Update EEG Panel buttons
            for ebi = 1:5
                app.(['eeg',num2str(ebi),'dd']).Enable = 'on';
                app.(['eeg',num2str(ebi),'dd']).Items = table2array(app.dataParams.EEGtab(:,2));
                app.(['eeg',num2str(ebi),'dd']).Value = app.dataParams.EEGtab{ebi,2};
            end

            % Update VIDeeg DropDown button options
            app.PSGvSel.Items = table2array(app.dataParams.EEGtab(:,2));
            app.PSGvSel.Value = app.dataParams.EEGtab{1,2};

            % Set button list
            getEEGbuttons(app)
            % Turn on New Subject
            app.NewSub.Enable = 'on';
            % Dis-enable Subject List
            app.subjectL.Enable = 'off';
            % Turn on Start Score button
            app.stScore.Enable = 'on';
            % Current Epoch start
            app.curEpoch = 1;

            % CLOSE PROGRESS
            close(subSelprog)

        end

        % Button pushed function: stScore
        function stScoreButtonPushed(app, event)
            % Update EEG Panel buttons
            for ebi = 1:5 % NUMBER of EEG PLOTS - WAS app.dataParams.EEGnum  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                app.(['EEG',num2str(ebi)]).Visible = 'on';
            end

            % Plot First Epoch of Data
            updateEEGplot(app, 1)

            % Plot First Epoch of VidEEG
            if app.VidAvailable
                app.CURframe = 1;
                updateVIDEEGplot(app , 1)
            end

            % Open Panels
            app.SCORINGPanel.Enable = 'on';
            app.EEG_extra.Enable = 'on';

            % Turn off start scoring button
            app.stScore.Enable = 'off';

            % If any hypodata is present - added 2/9/2021
            updateHYPlot(app)

            % Update EEG extra data If any hypodata is present - added 2/9/2021
            %             updateEEGextra(app,0)

            if app.VidAvailable
                % Open Video Panels
                app.PlaybackPanel.Enable = 'on';
                app.vidEpochPanel.Enable = 'on';
                app.playSpeed.Enable = 'on';
                app.PSGvPanel.Enable = 'on';

                % Plot first Video view
                updateVIDep(app)
            end

        end

        % Button pushed function: SAVEDATA
        function SAVEDATAButtonPushed(app, event)

            saveDLG = uiprogressdlg(app.UIFigure,'Title','SAVING DATA...Please Wait!',...
                'Indeterminate','on');
            drawnow

            % Get Folder Loc
            cd(app.inDlcs.dsLOC1)
            % Save out data
            app.subData.FINALSCORE = app.finalScores;

            %             app.subData.(app.GenHpy) = app.dataParams.SleepStg; % has sleep stages
            TT = app.subData;
            save(app.subSel,"TT");

            % Message that data has been saved
            close(saveDLG)
            uialert(app.UIFigure,'Data SAVED!','Save File Confirm','Icon',"success");

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            for i = 1:length(app.vidEpoch.mov)
                app.CURframe = i;
                % Plot image
                image(app.vidAXES, app.vidEpoch.mov(i).cdata)
                % Obtain frame number
                tIND = i + (210*(app.curEpoch - 1));
                % Obtain timestamp for Epoch
                strTime = datestr(timeofday(app.dataParams.Atime(app.curEpoch)));
                % Obtain seconds
                secsText = [num2str(round((i/210)*30,2),2) ,' secs'];

                % Plot Frame number
                text(app.vidAXES, 260, 7, ['Frame ' num2str(tIND)],...
                    'FontSize', 20,'Color','r','FontWeight',"normal")
                % Plot Time [Epoch]
                text(app.vidAXES, 260, 15, strTime,'Color','r',...
                    "FontWeight","normal","FontSize",20)
                % Plot seconds
                text(app.vidAXES, 260, 23, secsText,'Color','r',...
                    "FontWeight","normal","FontAngle","italic","FontSize",...
                    20)

                colormap(app.vidAXES,'gray')

                % Play line
                updateVIDEEGplot(app, 1)

                % Update using SPEED BUTTONS [7 fps , 14 fps , 21 fps]
                switch app.playSpeed.SelectedObject.Text
                    case '1x'
                        pause(1/7)
                    case '2x'
                        pause(1/14)
                    case '3x'
                        pause(1/21)
                end


            end
            app.CURframe = 1;

        end

        % Value changed function: curVIDepoch
        function curVIDepochValueChanged(app, event)
            lastEpoch = app.curEpoch;
            app.curEpoch = app.curVIDepoch.Value;
            if app.curEpoch >= app.dataParams.epochS || app.curEpoch <= 1
                return
            else
                app.curVIDepoch.Value = app.curEpoch;
                app.EPOCHSpin.Value = app.curEpoch;

                updateEEGplot(app, 2)

                updateHYPlot(app)

                % Update Video view
                updateVIDep(app)

                % Update VID EEG plot
                updateVIDEEGplot(app, 1)

                if lastEpoch < app.curEpoch
                    updateEEGextra(app,2)
                else
                    updateEEGextra(app,1)
                end
            end

        end

        % Selection changed function: playSpeed
        function playSpeedSelectionChanged(app, event)
            %             selectedButton = app.playSpeed.SelectedObject;

        end

        % Value changed function: PSGvSel
        function PSGvSelValueChanged(app, event)

            updateVIDEEGplot(app, 1)

        end

        % Button pushed function: FinalSummaryButton
        function FinalSummaryButtonPushed(app, event)

            sleEps = {'W','R','N1','N2','N3'};

            noTempty = cellfun(@(x) ~isempty(x) , app.finalScores,...
                'UniformOutput',true);
            tmpTot = sum(noTempty);

            finalScores2p = app.finalScores;

            finalScores2p(~noTempty) = repmat({' '},sum(~noTempty),1);

            for sli = 1:length(sleEps)
                tmpCount = sum(ismember(finalScores2p,sleEps{sli}) & noTempty);
                tmpPer = round((tmpCount/tmpTot)*100,1);

                sleEEPid = lower(sleEps{sli});

                app.(['f_',sleEEPid,'p']).Text = num2str(tmpPer);
                app.(['f_',sleEEPid,'n']).Text = num2str(tmpCount);

            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.SETD = uimenu(app.UIFigure);
            app.SETD.MenuSelectedFcn = createCallbackFcn(app, @SETDMenuSelected, true);
            app.SETD.Text = 'SET Directory';
            app.NewSub = uimenu(app.UIFigure);
            app.NewSub.MenuSelectedFcn = createCallbackFcn(app, @NewSubMenuSelected, true);
            app.NewSub.Enable = 'off';
            app.NewSub.Text = 'New Subject';
            app.EXITMenu = uimenu(app.UIFigure);
            app.EXITMenu.MenuSelectedFcn = createCallbackFcn(app, @EXITMenuSelected, true);
            app.EXITMenu.Text = 'EXIT';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SleepScore = uitab(app.TabGroup);
            app.SleepScore.Title = 'SleepScore';
            app.HYPg = uiaxes(app.SleepScore);
            app.HYPg.YLim = [0 6];
            app.HYPg.XTick = [];
            app.HYPg.XTickLabel = '';
            app.HYPg.YTick = [1 2 3 4 5];
            app.HYPg.YTickLabel = {'N3'; 'N2'; 'N1'; 'R'; 'W'};
            app.HYPg.ButtonDownFcn = createCallbackFcn(app, @HYPgButtonDown, true);
            app.SCORINGPanel = uipanel(app.SleepScore);
            app.SCORINGPanel.Enable = 'off';
            app.SCORINGPanel.Title = 'SCORING Panel';
            app.epochB = uibutton(app.SCORINGPanel, 'push');
            app.epochB.ButtonPushedFcn = createCallbackFcn(app, @epochBButtonPushed, true);
            app.epochB.Text = '<- Epoch';
            app.epochF = uibutton(app.SCORINGPanel, 'push');
            app.epochF.ButtonPushedFcn = createCallbackFcn(app, @epochFButtonPushed, true);
            app.epochF.Text = 'Epoch ->';
            app.EPOCHSpinnerLabel = uilabel(app.SCORINGPanel);
            app.EPOCHSpinnerLabel.Text = 'EPOCH';
            app.EPOCHSpin = uispinner(app.SCORINGPanel);
            app.EPOCHSpin.ValueChangedFcn = createCallbackFcn(app, @EPOCHSpinValueChanged, true);
            app.EPOCHSpin.Value = 1;
            app.WB = uibutton(app.SCORINGPanel, 'push');
            app.WB.ButtonPushedFcn = createCallbackFcn(app, @WBButtonPushed, true);
            app.WB.Text = 'W';
            app.RB = uibutton(app.SCORINGPanel, 'push');
            app.RB.ButtonPushedFcn = createCallbackFcn(app, @RBButtonPushed, true);
            app.RB.Text = 'R';
            app.N1B = uibutton(app.SCORINGPanel, 'push');
            app.N1B.ButtonPushedFcn = createCallbackFcn(app, @N1BButtonPushed, true);
            app.N1B.Text = 'N1';
            app.N2B = uibutton(app.SCORINGPanel, 'push');
            app.N2B.ButtonPushedFcn = createCallbackFcn(app, @N2BButtonPushed, true);
            app.N2B.Text = 'N2';
            app.N3B = uibutton(app.SCORINGPanel, 'push');
            app.N3B.ButtonPushedFcn = createCallbackFcn(app, @N3BButtonPushed, true);
            app.N3B.Text = 'N3';
            app.totEp = uilabel(app.SCORINGPanel);
            app.totEp.Text = 'TOTAL';
            app.HypnoP = uipanel(app.SleepScore);
            app.HypnoP.Enable = 'off';
            app.HypnoP.Title = 'Advance only all Epochs';
            app.hypSw = uiswitch(app.HypnoP, 'slider');
            app.hypSw.ValueChangedFcn = createCallbackFcn(app, @hypSwValueChanged, true);
            app.EEG_extra = uipanel(app.SleepScore);
            app.EEG_extra.Enable = 'off';
            app.EEG_extra.Title = 'Additional information';
            app.NotesTextAreaLabel = uilabel(app.EEG_extra);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.notesN1 = uitextarea(app.EEG_extra);
            app.ArousalSpinnerLabel = uilabel(app.EEG_extra);
            app.ArousalSpinnerLabel.Text = 'Arousal #';
            app.arouseN1 = uispinner(app.EEG_extra);
            app.ZoominSecondsGaugeLabel = uilabel(app.SleepScore);
            app.ZoominSecondsGaugeLabel.Text = 'Zoom in Seconds';
            app.zoomIN = uigauge(app.SleepScore, 'linear');
            app.zoomIN.Limits = [30 240];
            app.zoomIN.MajorTicks = [30 60 120 180 240];
            app.zoomIN.MinorTicks = [];
            app.zoomIN.Value = 30;
            app.Electrophysiology = uipanel(app.SleepScore);
            app.GridLayout = uigridlayout(app.Electrophysiology);
            app.EEG1 = uiaxes(app.GridLayout);
            app.EEG1.XTick = [];
            app.EEG1.YTick = [];
            app.EEG2 = uiaxes(app.GridLayout);
            app.EEG2.XTick = [];
            app.EEG2.YTick = [];
            app.EEG3 = uiaxes(app.GridLayout);
            app.EEG3.XTick = [];
            app.EEG3.YTick = [];
            app.EEG4 = uiaxes(app.GridLayout);
            app.EEG4.XTick = [];
            app.EEG4.YTick = [];
            app.EEG5 = uiaxes(app.GridLayout);
            app.EEG5.XTick = [];
            app.EEG5.YTick = [];
            app.EEGElectrodesPanel = uipanel(app.SleepScore);
            app.EEGElectrodesPanel.Title = 'EEG Electrodes';
            app.eeg1dd = uidropdown(app.EEGElectrodesPanel);
            app.eeg1dd.Items = {'EEG1', 'EEG2', 'EEG3', 'EEG4', 'EEG5', 'EEG6'};
            app.eeg1dd.Value = 'EEG1';
            app.eeg3dd = uidropdown(app.EEGElectrodesPanel);
            app.eeg3dd.Items = {'EEG1', 'EEG2', 'EEG3', 'EEG4', 'EEG5', 'EEG6'};
            app.eeg3dd.Value = 'EEG3';
            app.eeg4dd = uidropdown(app.EEGElectrodesPanel);
            app.eeg4dd.Items = {'EEG1', 'EEG2', 'EEG3', 'EEG4', 'EEG5', 'EEG6'};
            app.eeg4dd.Value = 'EEG4';
            app.eeg2dd = uidropdown(app.EEGElectrodesPanel);
            app.eeg2dd.Items = {'EEG1', 'EEG2', 'EEG3', 'EEG4', 'EEG5', 'EEG6'};
            app.eeg2dd.Value = 'EEG2';
            app.eeg5dd = uidropdown(app.EEGElectrodesPanel);
            app.eeg5dd.Items = {'EEG1', 'EEG2', 'EEG3', 'EEG4', 'EEG5', 'EEG6'};
            app.eeg5dd.Value = 'EEG5';
            app.subjectLpanel = uipanel(app.SleepScore);
            app.subjectLpanel.Enable = 'off';
            app.subjectLpanel.Title = 'Subject List';
            app.subjectL = uilistbox(app.subjectLpanel);
            app.subjectL.Items = {'Subjects...', ''};
            app.subjectL.ValueChangedFcn = createCallbackFcn(app, @subjectLValueChanged, true);
            app.subjectL.Value = 'Subjects...';
            app.stScore = uibutton(app.SleepScore, 'push');
            app.stScore.ButtonPushedFcn = createCallbackFcn(app, @stScoreButtonPushed, true);
            app.stScore.Enable = 'off';
            app.stScore.Text = 'Start Scoring';
            app.USEKEYSSwitchLabel = uilabel(app.SleepScore);
            app.USEKEYSSwitchLabel.Text = 'USE KEYS';
            app.useKEYS = uiswitch(app.SleepScore, 'slider');
            app.SAVEDATA = uibutton(app.SleepScore, 'push');
            app.SAVEDATA.ButtonPushedFcn = createCallbackFcn(app, @SAVEDATAButtonPushed, true);
            app.SAVEDATA.Text = 'SAVE DATA';
            app.MicrovoltsPanel = uipanel(app.SleepScore);
            app.MicrovoltsPanel.Title = 'Microvolts';
            app.YaxisZoomSpinnerLabel = uilabel(app.MicrovoltsPanel);
            app.YaxisZoomSpinnerLabel.Text = 'Y-axis Zoom';
            app.mv1 = uispinner(app.MicrovoltsPanel);
            app.mv1.Limits = [1 4];
            app.mv1.Value = 1;
            app.YaxisZoomSpinnerLabel_2 = uilabel(app.MicrovoltsPanel);
            app.YaxisZoomSpinnerLabel_2.Text = 'Y-axis Zoom';
            app.mv2 = uispinner(app.MicrovoltsPanel);
            app.mv2.Limits = [1 4];
            app.mv2.Value = 1;
            app.YaxisZoomSpinnerLabel_3 = uilabel(app.MicrovoltsPanel);
            app.YaxisZoomSpinnerLabel_3.Text = 'Y-axis Zoom';
            app.mv3 = uispinner(app.MicrovoltsPanel);
            app.mv3.Limits = [1 4];
            app.mv3.Value = 1;
            app.YaxisZoomSpinnerLabel_4 = uilabel(app.MicrovoltsPanel);
            app.YaxisZoomSpinnerLabel_4.Text = 'Y-axis Zoom';
            app.mv4 = uispinner(app.MicrovoltsPanel);
            app.mv4.Limits = [1 4];
            app.mv4.Value = 1;
            app.YaxisZoomSpinnerLabel_5 = uilabel(app.MicrovoltsPanel);
            app.YaxisZoomSpinnerLabel_5.Text = 'Y-axis Zoom';
            app.mv5 = uispinner(app.MicrovoltsPanel);
            app.mv5.Limits = [1 4];
            app.mv5.Value = 1;
            app.VIDEOPlayBackTab = uitab(app.TabGroup);
            app.VIDEOPlayBackTab.Title = 'VIDEO PlayBack';
            app.vidAXES = uiaxes(app.VIDEOPlayBackTab);
            app.vidAXES.XTick = [];
            app.vidAXES.YTick = [];
            app.VIDEEGaxes = uiaxes(app.VIDEOPlayBackTab);
            app.VIDEEGaxes.XTick = [];
            app.VIDEEGaxes.YTick = [];
            app.vidEpochPanel = uipanel(app.VIDEOPlayBackTab);
            app.vidEpochPanel.Enable = 'off';
            app.vidEpochPanel.Title = 'EPOCH Panel';
            app.EPOCHSpinnerLabel_2 = uilabel(app.vidEpochPanel);
            app.EPOCHSpinnerLabel_2.Text = 'EPOCH';
            app.curVIDepoch = uispinner(app.vidEpochPanel);
            app.curVIDepoch.Limits = [1 Inf];
            app.curVIDepoch.ValueChangedFcn = createCallbackFcn(app, @curVIDepochValueChanged, true);
            app.curVIDepoch.Value = 1;
            app.PlaybackPanel = uipanel(app.VIDEOPlayBackTab);
            app.PlaybackPanel.Enable = 'off';
            app.PlaybackPanel.Title = 'Playback Panel';
            app.PlayButton = uibutton(app.PlaybackPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = 'vidPlay.png';
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.PlaybackPanel, 'push');
            app.StopButton.Icon = 'vidStop.png';
            app.StopButton.Enable = 'off';
            app.StopButton.Visible = 'off';
            app.StopButton.Text = 'Stop';
            app.playSpeed = uibuttongroup(app.VIDEOPlayBackTab);
            app.playSpeed.SelectionChangedFcn = createCallbackFcn(app, @playSpeedSelectionChanged, true);
            app.playSpeed.Enable = 'off';
            app.playSpeed.Title = 'Playback Speed';
            app.oneX = uitogglebutton(app.playSpeed);
            app.oneX.Text = '1x';
            app.oneX.Value = true;
            app.twoX = uitogglebutton(app.playSpeed);
            app.twoX.Text = '2x';
            app.thrX = uitogglebutton(app.playSpeed);
            app.thrX.Text = '3x';
            app.PSGvPanel = uipanel(app.VIDEOPlayBackTab);
            app.PSGvPanel.Enable = 'off';
            app.PSGvPanel.Title = 'PSG Data Selection';
            app.PSGSIGNALDropDownLabel = uilabel(app.PSGvPanel);
            app.PSGSIGNALDropDownLabel.Text = 'PSG SIGNAL';
            app.PSGvSel = uidropdown(app.PSGvPanel);
            app.PSGvSel.Items = {'EEG1', 'EEG2', 'EEG3', 'EEG4', 'EEG5', 'EEG6'};
            app.PSGvSel.ValueChangedFcn = createCallbackFcn(app, @PSGvSelValueChanged, true);
            app.PSGvSel.Value = 'EEG1';
            app.SummaryData = uitab(app.TabGroup);
            app.SummaryData.Title = 'Summary Data';
            app.sumSleep = uiaxes(app.SummaryData);
            zlabel(app.sumSleep, 'Z')
            app.sumSleep.XTick = [];
            app.sumSleep.YTick = [1 2 3 4 5];
            app.sumSleep.YTickLabel = {'''N3'''; '''N2'''; '''N1'''; '''R'''; '''W'''};
            app.LWSummaryPanel = uipanel(app.SummaryData);
            app.LWSummaryPanel.Title = 'LW Summary';
            app.WLabel = uilabel(app.LWSummaryPanel);
            app.WLabel.Text = 'W %';
            app.WLabel_2 = uilabel(app.LWSummaryPanel);
            app.WLabel_2.Text = 'W #';
            app.lw_wp = uilabel(app.LWSummaryPanel);
            app.lw_wp.Text = '0';
            app.lw_wn = uilabel(app.LWSummaryPanel);
            app.lw_wn.Text = '0';
            app.RLabel = uilabel(app.LWSummaryPanel);
            app.RLabel.Text = 'R %';
            app.RLabel_2 = uilabel(app.LWSummaryPanel);
            app.RLabel_2.Text = 'R #';
            app.lw_rp = uilabel(app.LWSummaryPanel);
            app.lw_rp.Text = '0';
            app.lw_rn = uilabel(app.LWSummaryPanel);
            app.lw_rn.Text = '0';
            app.N1Label = uilabel(app.LWSummaryPanel);
            app.N1Label.Text = 'N1 %';
            app.N1Label_2 = uilabel(app.LWSummaryPanel);
            app.N1Label_2.Text = 'N1 #';
            app.lw_n1p = uilabel(app.LWSummaryPanel);
            app.lw_n1p.Text = '0';
            app.lw_n1n = uilabel(app.LWSummaryPanel);
            app.lw_n1n.Text = '0';
            app.N2Label = uilabel(app.LWSummaryPanel);
            app.N2Label.Text = 'N2 %';
            app.N2Label_2 = uilabel(app.LWSummaryPanel);
            app.N2Label_2.Text = 'N2 #';
            app.lw_n2p = uilabel(app.LWSummaryPanel);
            app.lw_n2p.Text = '0';
            app.lw_n2n = uilabel(app.LWSummaryPanel);
            app.lw_n2n.Text = '0';
            app.N3Label = uilabel(app.LWSummaryPanel);
            app.N3Label.Text = 'N3 %';
            app.N3Label_2 = uilabel(app.LWSummaryPanel);
            app.N3Label_2.Text = 'N3 #';
            app.lw_n3p = uilabel(app.LWSummaryPanel);
            app.lw_n3p.Text = '0';
            app.lw_n3n = uilabel(app.LWSummaryPanel);
            app.lw_n3n.Text = '0';
            app.STSummaryPanel = uipanel(app.SummaryData);
            app.STSummaryPanel.Title = 'ST Summary';
            app.WLabel_3 = uilabel(app.STSummaryPanel);
            app.WLabel_3.Text = 'W %';
            app.WLabel_4 = uilabel(app.STSummaryPanel);
            app.WLabel_4.Text = 'W #';
            app.st_wp = uilabel(app.STSummaryPanel);
            app.st_wp.Text = '0';
            app.st_wn = uilabel(app.STSummaryPanel);
            app.st_wn.Text = '0';
            app.RLabel_3 = uilabel(app.STSummaryPanel);
            app.RLabel_3.Text = 'R %';
            app.RLabel_4 = uilabel(app.STSummaryPanel);
            app.RLabel_4.Text = 'R #';
            app.st_rp = uilabel(app.STSummaryPanel);
            app.st_rp.Text = '0';
            app.st_rn = uilabel(app.STSummaryPanel);
            app.st_rn.Text = '0';
            app.N1Label_3 = uilabel(app.STSummaryPanel);
            app.N1Label_3.Text = 'N1 %';
            app.N1Label_4 = uilabel(app.STSummaryPanel);
            app.N1Label_4.Text = 'N1 #';
            app.st_n1p = uilabel(app.STSummaryPanel);
            app.st_n1p.Text = '0';
            app.st_n1n = uilabel(app.STSummaryPanel);
            app.st_n1n.Text = '0';
            app.N2Label_3 = uilabel(app.STSummaryPanel);
            app.N2Label_3.Text = 'N2 %';
            app.N2Label_4 = uilabel(app.STSummaryPanel);
            app.N2Label_4.Text = 'N2 #';
            app.st_n2p = uilabel(app.STSummaryPanel);
            app.st_n2p.Text = '0';
            app.st_n2n = uilabel(app.STSummaryPanel);
            app.st_n2n.Text = '0';
            app.N3Label_3 = uilabel(app.STSummaryPanel);
            app.N3Label_3.Text = 'N3 %';
            app.N3Label_4 = uilabel(app.STSummaryPanel);
            app.N3Label_4.Text = 'N3 #';
            app.st_n3p = uilabel(app.STSummaryPanel);
            app.st_n3p.Text = '0';
            app.st_n3n = uilabel(app.STSummaryPanel);
            app.st_n3n.Text = '0';
            app.CKSummaryPanel = uipanel(app.SummaryData);
            app.CKSummaryPanel.Title = 'CK Summary';
            app.WLabel_5 = uilabel(app.CKSummaryPanel);
            app.WLabel_5.Text = 'W %';
            app.WLabel_6 = uilabel(app.CKSummaryPanel);
            app.WLabel_6.Text = 'W #';
            app.ck_wp = uilabel(app.CKSummaryPanel);
            app.ck_wp.Text = '0';
            app.ck_wn = uilabel(app.CKSummaryPanel);
            app.ck_wn.Text = '0';
            app.RLabel_5 = uilabel(app.CKSummaryPanel);
            app.RLabel_5.Text = 'R %';
            app.RLabel_6 = uilabel(app.CKSummaryPanel);
            app.RLabel_6.Text = 'R #';
            app.ck_rp = uilabel(app.CKSummaryPanel);
            app.ck_rp.Text = '0';
            app.ck_rn = uilabel(app.CKSummaryPanel);
            app.ck_rn.Text = '0';
            app.N1Label_5 = uilabel(app.CKSummaryPanel);
            app.N1Label_5.Text = 'N1 %';
            app.N1Label_6 = uilabel(app.CKSummaryPanel);
            app.N1Label_6.Text = 'N1 #';
            app.ck_n1p = uilabel(app.CKSummaryPanel);
            app.ck_n1p.Text = '0';
            app.ck_n1n = uilabel(app.CKSummaryPanel);
            app.ck_n1n.Text = '0';
            app.N2Label_5 = uilabel(app.CKSummaryPanel);
            app.N2Label_5.Text = 'N2 %';
            app.N2Label_6 = uilabel(app.CKSummaryPanel);
            app.N2Label_6.Text = 'N2 #';
            app.ck_n2p = uilabel(app.CKSummaryPanel);
            app.ck_n2p.Text = '0';
            app.ck_n2n = uilabel(app.CKSummaryPanel);
            app.ck_n2n.Text = '0';
            app.N3Label_5 = uilabel(app.CKSummaryPanel);
            app.N3Label_5.Text = 'N3 %';
            app.N3Label_6 = uilabel(app.CKSummaryPanel);
            app.N3Label_6.Text = 'N3 #';
            app.ck_n3p = uilabel(app.CKSummaryPanel);
            app.ck_n3p.Text = '0';
            app.ck_n3n = uilabel(app.CKSummaryPanel);
            app.ck_n3n.Text = '0';
            app.MSSummaryPanel = uipanel(app.SummaryData);
            app.MSSummaryPanel.Title = 'MS Summary';
            app.WLabel_7 = uilabel(app.MSSummaryPanel);
            app.WLabel_7.Text = 'W %';
            app.WLabel_8 = uilabel(app.MSSummaryPanel);
            app.WLabel_8.Text = 'W #';
            app.ms_wp = uilabel(app.MSSummaryPanel);
            app.ms_wp.Text = '0';
            app.ms_wn = uilabel(app.MSSummaryPanel);
            app.ms_wn.Text = '0';
            app.RLabel_7 = uilabel(app.MSSummaryPanel);
            app.RLabel_7.Text = 'R %';
            app.RLabel_8 = uilabel(app.MSSummaryPanel);
            app.RLabel_8.Text = 'R #';
            app.ms_rp = uilabel(app.MSSummaryPanel);
            app.ms_rp.Text = '0';
            app.ms_rn = uilabel(app.MSSummaryPanel);
            app.ms_rn.Text = '0';
            app.N1Label_7 = uilabel(app.MSSummaryPanel);
            app.N1Label_7.Text = 'N1 %';
            app.N1Label_8 = uilabel(app.MSSummaryPanel);
            app.N1Label_8.Text = 'N1 #';
            app.ms_n1p = uilabel(app.MSSummaryPanel);
            app.ms_n1p.Text = '0';
            app.ms_n1n = uilabel(app.MSSummaryPanel);
            app.ms_n1n.Text = '0';
            app.N2Label_7 = uilabel(app.MSSummaryPanel);
            app.N2Label_7.Text = 'N2 %';
            app.N2Label_8 = uilabel(app.MSSummaryPanel);
            app.N2Label_8.Text = 'N2 #';
            app.ms_n2p = uilabel(app.MSSummaryPanel);
            app.ms_n2p.Text = '0';
            app.ms_n2n = uilabel(app.MSSummaryPanel);
            app.ms_n2n.Text = '0';
            app.N3Label_7 = uilabel(app.MSSummaryPanel);
            app.N3Label_7.Text = 'N3 %';
            app.N3Label_8 = uilabel(app.MSSummaryPanel);
            app.N3Label_8.Text = 'N3 #';
            app.ms_n3p = uilabel(app.MSSummaryPanel);
            app.ms_n3p.Text = '0';
            app.ms_n3n = uilabel(app.MSSummaryPanel);
            app.ms_n3n.Text = '0';
            app.FINALSummaryPanel = uipanel(app.SummaryData);
            app.FINALSummaryPanel.Title = 'FINAL Summary';
            app.WLabel_9 = uilabel(app.FINALSummaryPanel);
            app.WLabel_9.Text = 'W %';
            app.WLabel_10 = uilabel(app.FINALSummaryPanel);
            app.WLabel_10.Text = 'W #';
            app.f_wp = uilabel(app.FINALSummaryPanel);
            app.f_wp.Text = '0';
            app.f_wn = uilabel(app.FINALSummaryPanel);
            app.f_wn.Text = '0';
            app.RLabel_9 = uilabel(app.FINALSummaryPanel);
            app.RLabel_9.Text = 'R %';
            app.RLabel_10 = uilabel(app.FINALSummaryPanel);
            app.RLabel_10.Text = 'R #';
            app.f_rp = uilabel(app.FINALSummaryPanel);
            app.f_rp.Text = '0';
            app.f_rn = uilabel(app.FINALSummaryPanel);
            app.f_rn.Text = '0';
            app.N1Label_9 = uilabel(app.FINALSummaryPanel);
            app.N1Label_9.Text = 'N1 %';
            app.N1Label_10 = uilabel(app.FINALSummaryPanel);
            app.N1Label_10.Text = 'N1 #';
            app.f_n1p = uilabel(app.FINALSummaryPanel);
            app.f_n1p.Text = '0';
            app.f_n1n = uilabel(app.FINALSummaryPanel);
            app.f_n1n.Text = '0';
            app.N2Label_9 = uilabel(app.FINALSummaryPanel);
            app.N2Label_9.Text = 'N2 %';
            app.N2Label_10 = uilabel(app.FINALSummaryPanel);
            app.N2Label_10.Text = 'N2 #';
            app.f_n2p = uilabel(app.FINALSummaryPanel);
            app.f_n2p.Text = '0';
            app.f_n2n = uilabel(app.FINALSummaryPanel);
            app.f_n2n.Text = '0';
            app.N3Label_9 = uilabel(app.FINALSummaryPanel);
            app.N3Label_9.Text = 'N3 %';
            app.N3Label_10 = uilabel(app.FINALSummaryPanel);
            app.N3Label_10.Text = 'N3 #';
            app.f_n3p = uilabel(app.FINALSummaryPanel);
            app.f_n3p.Text = '0';
            app.f_n3n = uilabel(app.FINALSummaryPanel);
            app.f_n3n.Text = '0';
            app.FinalSummaryButton = uibutton(app.SummaryData, 'push');
            app.FinalSummaryButton.ButtonPushedFcn = createCallbackFcn(app, @FinalSummaryButtonPushed, true);
            app.FinalSummaryButton.Text = 'Final Summary';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SleepStage_Consensus_v6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end