classdef vidTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        LoadvideoButton    matlab.ui.control.Button
        PlayButton         matlab.ui.control.Button
        EpochSpinnerLabel  matlab.ui.control.Label
        EpochSpinner       matlab.ui.control.Spinner
        UIAxes             matlab.ui.control.UIAxes
    end


    properties (Access = private)
        vidFile % Description
        vidLoc
        vidEpoch
        totalFrame
        VideoFile
        fRate
        frPerEp
        numEpochs
        VidINDs
        curEpoch
        Fwidth
        Fheight
        vidFlist

    end

    methods (Access = private)

        function vidEpoch = grabVidEpoch(app , epochN)

            vidEpoch = load(app.vidFlist{epochN});

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadvideoButton
        function LoadvideoButtonPushed(app, event)


            app.vidLoc = uigetdir();

            cd(app.vidLoc)

            vidDir = dir('*.mat');
            vidDir1 = {vidDir.name};
            app.vidFlist = vidDir1;
            app.vidFile = vidDir1{1};
            
            app.numEpochs = length(app.vidFlist);

            app.vidEpoch = grabVidEpoch(app , 1);

            app.curEpoch = 1;
            app.EpochSpinner.Value = 1;

            image(app.UIAxes, app.vidEpoch.mov(1).cdata)
            colormap(app.UIAxes,'gray')
            axis(app.UIAxes, 'equal')


        end

        % Value changed function: EpochSpinner
        function EpochSpinnerValueChanged(app, event)
            app.curEpoch = app.EpochSpinner.Value;

            if app.curEpoch < 1
                app.curEpoch = 1;
                app.EpochSpinner.Value = 1;
                return
            end

            if app.curEpoch > app.numEpochs
                app.curEpoch = app.numEpochs;
                app.EpochSpinner.Value = app.numEpochs;
                return
            end

            app.vidEpoch = grabVidEpoch(app , app.curEpoch);
            image(app.UIAxes, app.vidEpoch.mov(1).cdata)
            colormap(app.UIAxes,'gray')

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)


            for i = 1:length(app.vidEpoch.mov)
                image(app.UIAxes, app.vidEpoch.mov(i).cdata)
                
                tIND = i + (210*(app.curEpoch - 1));


                text(app.UIAxes,260,7,['Frame ' num2str(tIND)],...
                    'FontSize',30,'Color','r','FontWeight',"normal")
                
                
                colormap(app.UIAxes,'gray')
                pause(1/7)
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadvideoButton = uibutton(app.UIFigure, 'push');
            app.LoadvideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadvideoButtonPushed, true);
            app.LoadvideoButton.Text = 'Load video';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.EpochSpinnerLabel = uilabel(app.UIFigure);
            app.EpochSpinnerLabel.Text = 'Epoch';
            app.EpochSpinner = uispinner(app.UIFigure);
            app.EpochSpinner.ValueChangedFcn = createCallbackFcn(app, @EpochSpinnerValueChanged, true);
            app.EpochSpinner.Value = 1;
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vidTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end