classdef SleepDataTTcreate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        FileMenu     matlab.ui.container.Menu
        LoadDate     matlab.ui.container.Menu
        ChannelMenu  matlab.ui.container.Menu
        Panel        matlab.ui.container.Panel
        GridLayout   matlab.ui.container.GridLayout
    end

    
    properties (Access = private)
        fileLOC    % Sleep data Folder directory
        fileLIST   % List of .mat files for entire night 
        chanlist   % AO names for channels on ECoG module
        labelTab     % Label Table
        eegTTraw      % Time table for EEG 
        eogTTraw      % Time table for EOG
        emgTTraw      % Time table for EMG
        eegTTpp
        eogTTpp
        emgTTpp
        eegMODsf
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: LoadDate
        function LoadDateMenuSelected(app, event)
            app.fileLOC = uigetdir();
            
            cd(app.fileLOC)
            
            mdir = dir('*.mat');
            mdir2 = {mdir.name};
            app.fileLIST = mdir2;
            
            % Initiate progress bar
            progBAR = uiprogressdlg(app.UIFigure,'Title','Combining Files...',...
                'Message','1');
            drawnow

            % Use Matfile function to import the appropriate files
            
            matob = matfile('F210110-0001.mat');
            varlist = who(matob);
            app.chanlist = varlist(contains(varlist,'CEEG'));
            %             ttllist = varlist(contains(varlist,'CDIG'));

            chanNum = 1:16;
            chanLab = {'EEG','EEG','LFP','EEG','EEG','EEG','LFP','EEG',...
                'EEG','LFP','EOG','EOG','EMG','EMG','EMG','LFP'};
            chanID = {'F3','Fz','D0','A1','C3','Cz','D1','A2','O1','D2',...
                'EOG1','EOG2','Chin1','Chin2','ChinZ','D3'};
            
            app.labelTab = table(chanNum,chanLab,chanID,'VariableNames',{'Number',...
                'Label','ID'});
            
            chanlNum1 = cellfun(@(x) strsplit(x,'_'), app.chanlist,'UniformOutput',false);
            chanlNum2 = cellfun(@(x) str2double(x{3}), chanlNum1, 'UniformOutput', true);
            
            % Convert to Time tables with appropriate sample frequencies
            chansleep = {'EEG','EOG','EMG'};
            chanNUMb = zeros(1,3);
            for cNi = 1:length(chansleep)
                chanNUMb(cNi) = length(app.labelTab.Number(ismember(app.labelTab.Label,chansleep{cNi})));
            end
            
            app.eegMODsf = 1375;
        
            tmpEEGarray = zeros(60*60*12*app.eegMODsf,chanNUMb(1));
            tmpEOGarray = zeros(60*60*12*app.eegMODsf,chanNUMb(2));
            tmpEMGarray = zeros(60*60*12*app.eegMODsf,chanNUMb(3));
            chanTabLabs = cell(1,3);
            for fi = 1:length(app.fileLIST)
                
                % Update progress, report current estimate
                progBAR.Value = fi/length(app.fileLIST);
                progBAR.Message = sprintf('%1.0f',fi);
                
                % file name
                curFile = app.fileLIST{fi};
                % Load all channels [MAY NEED TO UPDATE with MICRODRIVE]
                load(curFile,app.chanlist{:});
                
                for ci = 1:length(chansleep)
                    
                    chanChoose = chansleep{ci};
                    chanTabLabs{ci} = app.labelTab.ID(ismember(app.labelTab.Label,chanChoose));
                    % Extract variables relevant to channel
                    % Raw data and sampling frequency
                    % Get channel list
                    chanNums = app.labelTab.Number(ismember(app.labelTab.Label,chanChoose));
                    
                    for chi = 1:length(chanNums)

                        tmpChan = ismember(chanlNum2,chanNums(chi));
                        tmpRows = app.chanlist(tmpChan);
                        rawRow = tmpRows{1};
                        
                        dat1 = transpose(eval(rawRow));
                        switch chanChoose
                            case 'EEG'
                                
                                lastLOC = find(tmpEEGarray(:,length(chanNums)),1,'last');
                                
                                if isempty(lastLOC)
                                    lastLOC = 0 + 1;
                                else
                                    lastLOC = lastLOC + 1;
                                end
                                
                                curLeng = length(dat1)-1;
                                tmpEEGarray(lastLOC:lastLOC+curLeng ,chi) = dat1;
                                
                            case 'EOG'
                                
                                lastLOC = find(tmpEOGarray(:,length(chanNums)),1,'last');
                                
                                if isempty(lastLOC)
                                    lastLOC = 0 + 1;
                                else
                                    lastLOC = lastLOC + 1;
                                end
                                
                                curLeng = length(dat1)-1;
                                tmpEOGarray(lastLOC:lastLOC+curLeng ,chi) = dat1;
                                
                            case 'EMG'
                                
                                lastLOC = find(tmpEMGarray(:,length(chanNums)),1,'last');
                                
                                if isempty(lastLOC)
                                    lastLOC = 0 + 1;
                                else
                                    lastLOC = lastLOC + 1;
                                end
                                
                                curLeng = length(dat1)-1;
                                tmpEMGarray(lastLOC:lastLOC+curLeng ,chi) = dat1;
   
                        end
                    end
                end
            end
            % Close the dialog box
            close(progBAR)

            % Clean up array EEG
            lastZEEG = find(tmpEEGarray(:,1),1,'last') + 1;
            tmpEEGarray = tmpEEGarray(1:lastZEEG,:);
            
            % Clean up array EOG
            lastZEOG = find(tmpEOGarray(:,1),1,'last') + 1;
            tmpEOGarray = tmpEOGarray(1:lastZEOG,:);
            
            % Clean up array EMG
            lastZEMG = find(tmpEMGarray(:,1),1,'last') + 1;
            tmpEMGarray = tmpEMGarray(1:lastZEMG,:);
            
            % Create Timetables
            
            % EEG
            eegPB = uiprogressdlg(app.UIFigure,'Title','Processing EEG',...
                'Indeterminate','on');
            drawnow
           
            tmpEEGtab = array2table(tmpEEGarray);
            tmpEEGtab.Properties.VariableNames = chanTabLabs{1};
            app.eegTTraw = table2timetable(tmpEEGtab,'SampleRate',app.eegMODsf);
            
            % Normalize
            eegPB.Message = sprintf('%s','Normalize');
            eegTTrawN = normalize(app.eegTTraw);
            % Low Filter
            eegPB.Message = sprintf('%s','Low Pass Filter');
            eegTTppLp = lowpass(eegTTrawN,0.2);
            % Downsample
            eegPB.Message = sprintf('%s','Downsample 100Hz');
            eegTTppDs = retime(eegTTppLp,'regular','mean','SampleRate',100);
            % Preprocess Signal
            app.eegTTpp = eegTTppDs;

            close(eegPB)
            
            
            % EOG
            eogPB = uiprogressdlg(app.UIFigure,'Title','Processing EOG',...
                'Indeterminate','on');
            drawnow
            
            tmpEOGtab = array2table(tmpEOGarray);
            tmpEOGtab.Properties.VariableNames = chanTabLabs{2};
            app.eogTTraw = table2timetable(tmpEOGtab,'SampleRate',app.eegMODsf);
            
            % Normalize
            eogPB.Message = sprintf('%s','Normalize');
            eogTTrawN = normalize(app.eogTTraw);
            % Low Filter
            eogPB.Message = sprintf('%s','Low Pass Filter');
            eogTTppLp = lowpass(eogTTrawN,0.2);
            % Downsample
            eogPB.Message = sprintf('%s','Downsample 100Hz');
            eogTTppDs = retime(eogTTppLp,'regular','mean','SampleRate',100);
            % Preprocess Signal
            app.eogTTpp = eogTTppDs;

            close(eogPB)
            
            
            % EMG
            emgPB = uiprogressdlg(app.UIFigure,'Title','Processing EMG',...
                'Indeterminate','on');
            drawnow

            tmpEMGtab = array2table(tmpEMGarray);
            tmpEMGtab.Properties.VariableNames = chanTabLabs{3};
            app.emgTTraw = table2timetable(tmpEMGtab,'SampleRate',app.eegMODsf);

            % Normalize
            emgPB.Message = sprintf('%s','Normalize');
            emgTTrawN = normalize(app.emgTTraw);
            % Low Filter
            emgPB.Message = sprintf('%s','Low Pass Filter');
            emgTTppLp = lowpass(emgTTrawN,0.2);
            % Downsample
            emgPB.Message = sprintf('%s','Downsample 100Hz');
            emgTTppDs = retime(emgTTppLp,'regular','mean','SampleRate',100);
            % Preprocess Signal
            app.emgTTpp = emgTTppDs;
            
            close(emgPB)
            
            % PUT INTO BINS - 30 seconds
            
%             % For future binning
%             tstart = seconds(2000)
%             tend = seconds(4000)
%             timeLimits = timerange(tstart,tend)
            
            % SAVE OUT 

            
            app.ChannelTypeMenu.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadDate = uimenu(app.FileMenu);
            app.LoadDate.MenuSelectedFcn = createCallbackFcn(app, @LoadDateMenuSelected, true);
            app.LoadDate.Text = 'Load Date';
            app.ChannelMenu = uimenu(app.UIFigure);
            app.ChannelMenu.Text = 'Channel';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.GridLayout = uigridlayout(app.Panel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SleepDataTTcreate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end