classdef SleepStage_InitialReview_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SETD                     matlab.ui.container.Menu
        NewSub                   matlab.ui.container.Menu
        EXITMenu                 matlab.ui.container.Menu
        TabGroup                 matlab.ui.container.TabGroup
        SleepScore               matlab.ui.container.Tab
        USERIDPanel              matlab.ui.container.Panel
        MS_l                     matlab.ui.control.Label
        ST_l                     matlab.ui.control.Label
        LW_l                     matlab.ui.control.Label
        CK_l                     matlab.ui.control.Label
        MicrovoltsPanel          matlab.ui.container.Panel
        mv6                      matlab.ui.control.Spinner
        YaxisZoomSpinnerLabel_6  matlab.ui.control.Label
        mv5                      matlab.ui.control.Spinner
        YaxisZoomSpinnerLabel_5  matlab.ui.control.Label
        mv4                      matlab.ui.control.Spinner
        YaxisZoomSpinnerLabel_4  matlab.ui.control.Label
        mv3                      matlab.ui.control.Spinner
        YaxisZoomSpinnerLabel_3  matlab.ui.control.Label
        mv2                      matlab.ui.control.Spinner
        YaxisZoomSpinnerLabel_2  matlab.ui.control.Label
        mv1                      matlab.ui.control.Spinner
        YaxisZoomSpinnerLabel    matlab.ui.control.Label
        SAVEDATA                 matlab.ui.control.Button
        useKEYS                  matlab.ui.control.Switch
        USEKEYSSwitchLabel       matlab.ui.control.Label
        stScore                  matlab.ui.control.Button
        subjectLpanel            matlab.ui.container.Panel
        subjectL                 matlab.ui.control.ListBox
        smoothPanel              matlab.ui.container.Panel
        smP6                     matlab.ui.control.DropDown
        smP5                     matlab.ui.control.DropDown
        smP4                     matlab.ui.control.DropDown
        smP3                     matlab.ui.control.DropDown
        smP2                     matlab.ui.control.DropDown
        smP1                     matlab.ui.control.DropDown
        EEGElectrodesPanel       matlab.ui.container.Panel
        eeg6dd                   matlab.ui.control.DropDown
        eeg5dd                   matlab.ui.control.DropDown
        eeg2dd                   matlab.ui.control.DropDown
        eeg4dd                   matlab.ui.control.DropDown
        eeg3dd                   matlab.ui.control.DropDown
        eeg1dd                   matlab.ui.control.DropDown
        Electrophysiology        matlab.ui.container.Panel
        GridLayout               matlab.ui.container.GridLayout
        EEG6                     matlab.ui.control.UIAxes
        EEG5                     matlab.ui.control.UIAxes
        EEG4                     matlab.ui.control.UIAxes
        EEG3                     matlab.ui.control.UIAxes
        EEG2                     matlab.ui.control.UIAxes
        EEG1                     matlab.ui.control.UIAxes
        zoomIN                   matlab.ui.control.LinearGauge
        ZoominSecondsGaugeLabel  matlab.ui.control.Label
        EEG_extra                matlab.ui.container.Panel
        arouseN1                 matlab.ui.control.Spinner
        ArousalSpinnerLabel      matlab.ui.control.Label
        notesN1                  matlab.ui.control.TextArea
        NotesTextAreaLabel       matlab.ui.control.Label
        SCORINGPanel             matlab.ui.container.Panel
        totEp                    matlab.ui.control.Label
        N3B                      matlab.ui.control.Button
        N2B                      matlab.ui.control.Button
        N1B                      matlab.ui.control.Button
        RB                       matlab.ui.control.Button
        WB                       matlab.ui.control.Button
        EPOCHSpin                matlab.ui.control.Spinner
        EPOCHSpinnerLabel        matlab.ui.control.Label
        epochF                   matlab.ui.control.Button
        epochB                   matlab.ui.control.Button
        HYPg                     matlab.ui.control.UIAxes
        VIDEOPlayBackTab         matlab.ui.container.Tab
        PSGvPanel                matlab.ui.container.Panel
        PSGvSel                  matlab.ui.control.DropDown
        PSGSIGNALDropDownLabel   matlab.ui.control.Label
        playSpeed                matlab.ui.container.ButtonGroup
        thrX                     matlab.ui.control.ToggleButton
        twoX                     matlab.ui.control.ToggleButton
        oneX                     matlab.ui.control.ToggleButton
        PlaybackPanel            matlab.ui.container.Panel
        StopButton               matlab.ui.control.Button
        PlayButton               matlab.ui.control.Button
        vidEpochPanel            matlab.ui.container.Panel
        curVIDepoch              matlab.ui.control.Spinner
        EPOCHSpinnerLabel_2      matlab.ui.control.Label
        VIDEEGaxes               matlab.ui.control.UIAxes
        vidAXES                  matlab.ui.control.UIAxes
    end

    %%%% WORKING ON
    % e. Add slider to top of hypnogram - CONSIDER for later version
    % 1. Check Notes added [end and start]
    % 2. Check Video Stop
    % 3. 
    % 4. Check Save
    % 5. Check Load
    % 6. Update appropriate window size
    
    properties (Access = private)
        inDlcs % Internal directory locations
        subSel % Selected subject to work on
        subData % Primary sleep data
        dataParams % structure to characterize data
        curEpoch % Current Epoch
        zoomNOW
        zoomINDex
        EEGbutIDS
        videoLOC
        VidSelname
        VidSelLOC
        VidSelIndLcs
        VidNumInd
        vidEpoch
        vEEGbutID
        curVIDdat
        CURframe
        ScoreID
    end
    
    methods (Access = private)
        
        function updateParams(app , stage)
            
            % Determine how to update parameters
            switch stage
                % Initialize parameters - ADD stuff to table if needed
                case 1
                    % Number of 30 second epochs
                    app.dataParams.epochS = height(app.subData);
                    % Set up Hypnogram plot
                    app.HYPg.XLim = [1, app.dataParams.epochS];
                    % ADD TEXT to EPOCH spinner
                    app.totEp.Text = [num2str(app.dataParams.epochS), ' Epochs'];
                    % Number of EEG electrodes
                    standardEEG = {'F3','Fz','A1','C3','Cz','A2','O1','EOG1','EOG2','Chin1','Chin2','ChinZ'};
                    tmpCols = app.subData.Properties.VariableNames;
                    app.dataParams.EEGnum = sum(contains(tmpCols,standardEEG));
                    app.dataParams.EEGid = tmpCols(contains(tmpCols,standardEEG));
                    % ID of EEG electrodes
                    eegID = cell(app.dataParams.EEGnum,1);
                    eegIND = zeros(app.dataParams.EEGnum,1);
                    for ei = 1:app.dataParams.EEGnum
                        tmpEEGid = app.dataParams.EEGid{ei};
                        tmpEEGind = ismember(tmpCols,tmpEEGid);
                        eegID{ei} = tmpCols{tmpEEGind};
                        eegIND(ei) = find(tmpEEGind);
                    end
                    app.dataParams.EEGtab = table(eegIND, eegID);
                    app.dataParams.SleepStg = app.subData.(app.ScoreID); % check if repopulate when crash
                    app.dataParams.sampPts = height(app.subData.(eegID{1}){1});
                    
                    % Actual Time
                    app.dataParams.Atime =  app.subData.ActTime;
                    
                    % CHECK if Arousal column is available
                    if ~ismember('arousaln',app.subData.Properties.VariableNames)
                        app.subData.arousaln = zeros(height(app.subData),1);
                        app.dataParams.aroCt = app.subData.arousaln;
                    else
                        app.dataParams.aroCt = app.subData.arousaln;
                    end
                    
                    % CHECK if notes column is avialable
                    if ~ismember('notes',app.subData.Properties.VariableNames)
                        app.subData.notes = repmat({''},height(app.subData),1);
                        app.dataParams.notes = app.subData.notes;
                    else
                        app.dataParams.notes = app.subData.notes;
                    end
                    
                    % Initialize ZoomIndex
                    app.zoomINDex = 1;
            end
        end
        
        function updateEEGplot(app , stage)
            
            switch stage
                case 1 % Initialize
                    app.curEpoch = 1; % Reset current epoch to 1
                    app.curVIDepoch.Value = 1;
                    app.EPOCHSpin.Value = app.curEpoch; 
                    for st1 = 1:6  % NUMBER 
                        % Determine how to select the correct column vector
                        % based on the EEG electrode from dropdown

                        getEEGbuttons(app)
                        
                        colID1 = app.EEGbutIDS(st1);
                        colID2 = app.dataParams.EEGtab.eegIND(ismember(app.dataParams.EEGtab.eegID,colID1));
                        
                        vecCell = app.subData{app.curEpoch,colID2};
                        eegVec = vecCell{1};

                        % Fix time vector
                        timeVEC = linspace(1,app.dataParams.sampPts/100,app.dataParams.sampPts);
                        switch st1
                            case 1
                                ylabel(app.EEG1,colID1{1})
                                smEegVec2 = smoothdata(eegVec , 'movmean', str2double(app.smP1.Value));
                                plot(app.EEG1,timeVEC,smEegVec2)
                                [yTmax , yTmin] = getYlimVs(app,smEegVec2,st1);
                                yTmax = yTmax*app.mv1.Value;
                                yTmin = yTmin*app.mv1.Value;
                                ylim(app.EEG1,[yTmin , yTmax])
                            case 2
                                ylabel(app.EEG2,colID1{1})
                                smEegVec2 = smoothdata(eegVec , 'movmean', str2double(app.smP2.Value));
                                plot(app.EEG2,timeVEC,smEegVec2)
                                [yTmax , yTmin] = getYlimVs(app,smEegVec2,st1);
                                yTmax = yTmax*app.mv2.Value;
                                yTmin = yTmin*app.mv2.Value;
                                ylim(app.EEG2,[yTmin , yTmax])
                            case 3
                                ylabel(app.EEG3,colID1{1})
                                smEegVec2 = smoothdata(eegVec , 'movmean', str2double(app.smP3.Value));
                                plot(app.EEG3,timeVEC,smEegVec2)
                                [yTmax , yTmin] = getYlimVs(app,smEegVec2,st1);
                                yTmax = yTmax*app.mv3.Value;
                                yTmin = yTmin*app.mv3.Value;
                                ylim(app.EEG3,[yTmin , yTmax])
                            case 4
                                ylabel(app.EEG4,colID1{1})
                                smEegVec2 = smoothdata(eegVec , 'movmean', str2double(app.smP4.Value));
                                plot(app.EEG4,timeVEC,smEegVec2)
                                [yTmax , yTmin] = getYlimVs(app,smEegVec2,st1);
                                yTmax = yTmax*app.mv4.Value;
                                yTmin = yTmin*app.mv4.Value;
                                ylim(app.EEG4,[yTmin , yTmax])
                            case 5
                                ylabel(app.EEG5,colID1{1})
                                smEegVec2 = smoothdata(eegVec , 'movmean', str2double(app.smP5.Value));
                                plot(app.EEG5,timeVEC,smEegVec2)
                                [yTmax , yTmin] = getYlimVs(app,smEegVec2,st1);
                                yTmax = yTmax*app.mv5.Value;
                                yTmin = yTmin*app.mv5.Value;
                                ylim(app.EEG5,[yTmin , yTmax])
                            case 6
                                ylabel(app.EEG6,colID1{1})
                                smEegVec2 = smoothdata(eegVec , 'movmean', str2double(app.smP6.Value));
                                plot(app.EEG6,timeVEC,smEegVec2)
                                [yTmax , yTmin] = getYlimVs(app,smEegVec2,st1);
                                yTmax = yTmax*app.mv6.Value;
                                yTmin = yTmin*app.mv6.Value;
                                ylim(app.EEG6,[yTmin , yTmax])
                        end

                        if  contains(app.dataParams.EEGtab.eegID{st1},'EMG')
                            udpateXticktime(app , st1 , stage, yTmax, yTmin)
                            continue
                        else

                            % NEW SUBtitle
                            if isempty(app.dataParams.SleepStg{app.curEpoch})
                                curSlepStg = 'NONE';
                            else
                                curSlepStg = app.dataParams.SleepStg{app.curEpoch};
                            end

                            epochNN = num2str(app.curEpoch);
                            tIME = datestr(timeofday(app.dataParams.Atime(app.curEpoch)));
                            txt = ['Stage:  ',curSlepStg,'         EPOCH: ',epochNN...
                                '          TIME: ' ,tIME];

                            switch st1
                                case 1
                                    subtitle(app.EEG1,txt)
                                    app.EEG1.TitleHorizontalAlignment = 'left';
                                case 2
                                    subtitle(app.EEG2,txt)
                                    app.EEG2.TitleHorizontalAlignment = 'left';
                                case 3
                                    subtitle(app.EEG3,txt)
                                    app.EEG3.TitleHorizontalAlignment = 'left';
                                case 4
                                    subtitle(app.EEG4,txt)
                                    app.EEG4.TitleHorizontalAlignment = 'left';
                                case 5
                                    subtitle(app.EEG5,txt)
                                    app.EEG5.TitleHorizontalAlignment = 'left';
                                case 6
                                    subtitle(app.EEG6,txt)
                                    app.EEG6.TitleHorizontalAlignment = 'left';
                            end
                        end
                        
                        % Update x-axis tick-marks
                        udpateXticktime(app , st1 , stage, yTmax, yTmin)
                        
                    end
                    
                case 2 % Move back or forward
                    for st2 = 1:6 % NUMBER of EEG PLOTS - WAS app.dataParams.EEGnum %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        getEEGbuttons(app)
                        
                        colID1 = app.EEGbutIDS(st2);
                        colID2 = app.dataParams.EEGtab.eegIND(ismember(app.dataParams.EEGtab.eegID,colID1));
                        
                        vecCell = app.subData{app.curEpoch,colID2};
                        eegVec = vecCell{1};
                        % Fix time vector
                        timeVEC = linspace(1,app.dataParams.sampPts/100,app.dataParams.sampPts);

                        switch st2
                            case 1
                                ylabel(app.EEG1,colID1{1})
                                smEegVec2 = smoothdata(eegVec , 'movmean', str2double(app.smP1.Value));
                                plot(app.EEG1,timeVEC, smEegVec2)
                                [yTmax , yTmin] = getYlimVs(app,smEegVec2,st2);

                                yTmax = yTmax*app.mv1.Value;
                                yTmin = yTmin*app.mv1.Value;
                                ylim(app.EEG1,[yTmin , yTmax])
                            case 2
                                ylabel(app.EEG2,colID1{1})
                                smEegVec2 = smoothdata(eegVec , 'movmean', str2double(app.smP2.Value));
                                plot(app.EEG2,timeVEC, smEegVec2)
                                [yTmax , yTmin] = getYlimVs(app,smEegVec2,st2);

                                yTmax = yTmax*app.mv2.Value;
                                yTmin = yTmin*app.mv2.Value;
                                ylim(app.EEG2,[yTmin , yTmax])
                            case 3
                                ylabel(app.EEG3,colID1{1})
                                smEegVec2 = smoothdata(eegVec , 'movmean', str2double(app.smP3.Value));
                                plot(app.EEG3,timeVEC, smEegVec2)
                                [yTmax , yTmin] = getYlimVs(app,smEegVec2,st2);

                                yTmax = yTmax*app.mv3.Value;
                                yTmin = yTmin*app.mv3.Value;
                                ylim(app.EEG3,[yTmin , yTmax])
                            case 4
                                ylabel(app.EEG4,colID1{1})
                                smEegVec2 = smoothdata(eegVec , 'movmean', str2double(app.smP4.Value));
                                plot(app.EEG4,timeVEC, smEegVec2)
                                [yTmax , yTmin] = getYlimVs(app,smEegVec2,st2);

                                yTmax = yTmax*app.mv4.Value;
                                yTmin = yTmin*app.mv4.Value;
                                ylim(app.EEG4,[yTmin , yTmax])
                            case 5
                                ylabel(app.EEG5,colID1{1})
                                smEegVec2 = smoothdata(eegVec , 'movmean', str2double(app.smP5.Value));
                                plot(app.EEG5,timeVEC, smEegVec2)
                                [yTmax , yTmin] = getYlimVs(app,smEegVec2,st2);

                                yTmax = yTmax*app.mv5.Value;
                                yTmin = yTmin*app.mv5.Value;
                                ylim(app.EEG5,[yTmin , yTmax])
                            case 6
                                ylabel(app.EEG6,colID1{1})
                                smEegVec2 = smoothdata(eegVec , 'movmean', str2double(app.smP6.Value));
                                plot(app.EEG6,timeVEC, smEegVec2)
                                [yTmax , yTmin] = getYlimVs(app,smEegVec2,st2);

                                yTmax = yTmax*app.mv6.Value;
                                yTmin = yTmin*app.mv6.Value;
                                ylim(app.EEG6,[yTmin , yTmax])

                        end
                     
                        if  contains(app.dataParams.EEGtab.eegID{st2},'EMG')
                            udpateXticktime(app , st2 , stage, yTmax, yTmin)
                            continue
                        else
                            % NEW SUBtitle
                            if isempty(app.dataParams.SleepStg{app.curEpoch})
                                curSlepStg = 'NONE';
                            else
                                curSlepStg = app.dataParams.SleepStg{app.curEpoch};
                            end

                            epochNN = num2str(app.curEpoch);
                            tIME = datestr(timeofday(app.dataParams.Atime(app.curEpoch)));
                            txt = ['Stage:  ',curSlepStg,'         EPOCH: ',epochNN...
                                '          TIME: ' ,tIME];


                            subtitle(app.(['EEG',num2str(st2)]),txt)
                            app.(['EEG',num2str(st2)]).TitleHorizontalAlignment = 'left';
                            

                        end
                        
                        % Update x-axis tick-marks
                        udpateXticktime(app , st2, stage, yTmax, yTmin)
                        
                    end
                    
                case 3 % Show more than one epoch
                    numEpochs2u = round(app.zoomIN.Value/30) - 1;
                    actEpochs2u = app.curEpoch + numEpochs2u;
                    
                    getEEGbuttons(app)
                    
                    for st3 = 1:6 % NUMBER of EEG PLOTS - WAS app.dataParams.EEGnum %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        
                        colID1 = app.EEGbutIDS(st3);
                        colID2 = app.dataParams.EEGtab.eegIND(ismember(app.dataParams.EEGtab.eegID,colID1));
                        
                        aggVec = [];
                        for ag = app.curEpoch:actEpochs2u
                            aggVec = [aggVec ; app.subData{ag ,colID2}{:}];
                        end
                        
                        vecCell = aggVec;
                        
%                         smEegVec = smoothdata(vecCell);
                        smEegVec2 = smoothdata(vecCell , 'movmean', str2double(app.(['smP',num2str(st3)]).Value));
                        
                        ylabel(app.(['EEG',num2str(st3)]),colID1{1})
                        
                        numEpochs = round(app.zoomIN.Value/30);
                        zoomPoints = (app.dataParams.sampPts*numEpochs);
                        timeVEC = linspace(1,zoomPoints/100,zoomPoints);
                        
                        plot(app.(['EEG',num2str(st3)]),timeVEC, smEegVec2)
                        [yTmax , yTmin] = getYlimVs(app,smEegVec2,st3);
                        
                        yTmax = yTmax*app.(['mv',num2str(st3)]).Value;
                        yTmin = yTmin*app.(['mv',num2str(st3)]).Value;
                        
                        ylim(app.(['EEG',num2str(st3)]),[yTmin , yTmax])
                        
                        
                        if  contains(app.dataParams.EEGtab.eegID{st3},'EMG')
                            udpateXticktime(app , st3 , stage, yTmax, yTmin)
                            continue
                        else

                            epochNN = num2str(app.curEpoch);

                            txt = ['EPOCH: ',epochNN];
                            subtitle(app.(['EEG',num2str(st3)]),txt)
                            app.(['EEG',num2str(st3)]).TitleHorizontalAlignment = 'left';
                            
                        end
                        
                        % Update x-axis tick-marks
                        udpateXticktime(app , st3 , stage, yTmax, yTmin)
                        
                    end
                    
            end
            
        end
        
        function [yMaxR , yMinR] = getYlimVs(app,vecOfint,si)
            
            yMax = max(vecOfint);
            yMin = abs(min(vecOfint));
            if yMax > yMin
                yMaxR = yMax;
                yMinR = yMax*-1;
                switch si
                    case 1
                        ylim(app.EEG1,[-1*yMax , yMax])
                    case 2
                        ylim(app.EEG2,[-1*yMax , yMax])
                    case 3
                        ylim(app.EEG3,[-1*yMax , yMax])
                    case 4
                        ylim(app.EEG4,[-1*yMax , yMax])
                    case 5
                        ylim(app.EEG5,[-1*yMax , yMax])
                    case 6
                        ylim(app.EEG6,[-1*yMax , yMax])
                end

            else
                yMaxR = yMin;
                yMinR = yMin*-1;
                switch si
                    case 1
                        ylim(app.EEG1,[-1*yMin , yMin])
                    case 2
                        ylim(app.EEG2,[-1*yMin , yMin])
                    case 3
                        ylim(app.EEG3,[-1*yMin , yMin])
                    case 4
                        ylim(app.EEG4,[-1*yMin , yMin])
                    case 5
                        ylim(app.EEG5,[-1*yMin , yMin])
                    case 6
                        ylim(app.EEG6,[-1*yMin , yMin])
                end

            end
            
        end
        
        function updatePltTxt(app,yTmin,yTmax,si,stage)
            
            switch stage
                
                case 1
                    % Update Epoch Text
                    % Update Sleep Stage Text
                    if isempty(app.dataParams.SleepStg{app.curEpoch})
                        curSlepStg = 'NONE';
                    else
                        curSlepStg = app.dataParams.SleepStg{app.curEpoch};
                    end
                    % Update Time
                    strTime = datestr(timeofday(app.dataParams.Atime(app.curEpoch)));
                    switch si
                        case 1
                            text(app.EEG1,27,...
                                yTmin + round((yTmin*2*-1)*0.1),...
                                ['Epoch ',num2str(app.curEpoch)],'Color','r')

                            text(app.EEG1,1,...
                                yTmax - round((yTmax*2)*0.1),...
                                curSlepStg,'Color','k',"FontWeight","bold","FontSize",15)

                            text(app.EEG1,1.5,...
                                yTmin + round((yTmin*2*-1)*0.1),...
                                strTime,'Color','r',"FontWeight","bold")
                        case 2
                            text(app.EEG2,27,...
                                yTmin + round((yTmin*2*-1)*0.1),...
                                ['Epoch ',num2str(app.curEpoch)],'Color','r')

                            text(app.EEG2,1,...
                                yTmax - round((yTmax*2)*0.1),...
                                curSlepStg,'Color','k',"FontWeight","bold","FontSize",15)

                            text(app.EEG2,1.5,...
                                yTmin + round((yTmin*2*-1)*0.1),...
                                strTime,'Color','r',"FontWeight","bold")
                        case 3
                            text(app.EEG3,27,...
                                yTmin + round((yTmin*2*-1)*0.1),...
                                ['Epoch ',num2str(app.curEpoch)],'Color','r')

                            text(app.EEG3,1,...
                                yTmax - round((yTmax*2)*0.1),...
                                curSlepStg,'Color','k',"FontWeight","bold","FontSize",15)

                            text(app.EEG3,1.5,...
                                yTmin + round((yTmin*2*-1)*0.1),...
                                strTime,'Color','r',"FontWeight","bold")
                        case 4
                            text(app.EEG4,27,...
                                yTmin + round((yTmin*2*-1)*0.1),...
                                ['Epoch ',num2str(app.curEpoch)],'Color','r')

                            text(app.EEG4,1,...
                                yTmax - round((yTmax*2)*0.1),...
                                curSlepStg,'Color','k',"FontWeight","bold","FontSize",15)

                            text(app.EEG4,1.5,...
                                yTmin + round((yTmin*2*-1)*0.1),...
                                strTime,'Color','r',"FontWeight","bold")
                        case 5
                            text(app.EEG5,27,...
                                yTmin + round((yTmin*2*-1)*0.1),...
                                ['Epoch ',num2str(app.curEpoch)],'Color','r')

                            text(app.EEG5,1,...
                                yTmax - round((yTmax*2)*0.1),...
                                curSlepStg,'Color','k',"FontWeight","bold","FontSize",15)

                            text(app.EEG5,1.5,...
                                yTmin + round((yTmin*2*-1)*0.1),...
                                strTime,'Color','r',"FontWeight","bold")
                        case 6
                            text(app.EEG6,27,...
                                yTmin + round((yTmin*2*-1)*0.1),...
                                ['Epoch ',num2str(app.curEpoch)],'Color','r')

                            text(app.EEG6,1,...
                                yTmax - round((yTmax*2)*0.1),...
                                curSlepStg,'Color','k',"FontWeight","bold","FontSize",15)

                            text(app.EEG6,1.5,...
                                yTmin + round((yTmin*2*-1)*0.1),...
                                strTime,'Color','r',"FontWeight","bold")
                    end

                    
                case 2
                    % Update Epoch Text
                    switch si
                        case 1
                            text(app.EEG1,...
                                1, yTmax - round((yTmax*2)*0.1),...
                                ['Epoch ',num2str(app.curEpoch)],'Color','r',"FontWeight","bold")
                        case 2
                            text(app.EEG2,...
                                1, yTmax - round((yTmax*2)*0.1),...
                                ['Epoch ',num2str(app.curEpoch)],'Color','r',"FontWeight","bold")
                        case 3
                            text(app.EEG3,...
                                1, yTmax - round((yTmax*2)*0.1),...
                                ['Epoch ',num2str(app.curEpoch)],'Color','r',"FontWeight","bold")
                        case 4
                            text(app.EEG4,...
                                1, yTmax - round((yTmax*2)*0.1),...
                                ['Epoch ',num2str(app.curEpoch)],'Color','r',"FontWeight","bold")
                        case 5
                            text(app.EEG5,...
                                1, yTmax - round((yTmax*2)*0.1),...
                                ['Epoch ',num2str(app.curEpoch)],'Color','r',"FontWeight","bold")
                        case 6
                            text(app.EEG6,...
                                1, yTmax - round((yTmax*2)*0.1),...
                                ['Epoch ',num2str(app.curEpoch)],'Color','r',"FontWeight","bold")
                    end

                    
            end
            
        end
        
        function updateButtonAct(app , stageB)
            
            % ADD a EPOCH NUMBER CHECK
            if app.curEpoch > app.dataParams.epochS
                uialert(app.UIFigure,'SCORED LAST EPOCH!','SCORING complete!','Icon','info');
                return
            end
            
            % Update Sleep Stage Cell Array
            app.dataParams.SleepStg{app.curEpoch} = stageB;
            % Update Hypnogram plot
            updateHYPlot(app)
            % Advance Epoch
            app.curEpoch = app.curEpoch + 1;
            app.curVIDepoch.Value = app.curVIDepoch.Value + 1;
            
            % ADD a EPOCH NUMBER CHECK
            if app.curEpoch > app.dataParams.epochS
                uialert(app.UIFigure,'SCORED LAST EPOCH!','SCORING complete!','Icon','info');
                return
            end

            app.EPOCHSpin.Value = app.curEpoch;
            % Update EEG plot
            updateEEGplot(app,2)
            % Update EEG extra
            updateEEGextra(app,1)
            % Update Video View
            updateVIDep(app)
            % Update VIDEO EEG Plot
            updateVIDEEGplot(app, 1)
            
        end
        
        function updateHYPlot(app)
            
            stageS = {'N3','N2','N1','R','W'};
            cla(app.HYPg)
            tmpHyp = nan(app.dataParams.epochS,1);
            baseHYPcols = nan(app.dataParams.epochS,3);
            % REM = 4 = RED
 
            for i = 1:5
                tmpHyp(ismember(app.dataParams.SleepStg,stageS{i})) = i;
                
                if i == 4
                    baseHYPcols(ismember(app.dataParams.SleepStg,stageS{i}),:) =...
                        repmat([1,0,0],sum(ismember(app.dataParams.SleepStg,stageS{i})),1);
                else
                    baseHYPcols(ismember(app.dataParams.SleepStg,stageS{i}),:) =...
                        repmat([0,0,0],sum(ismember(app.dataParams.SleepStg,stageS{i})),1);
                end
                
            end
            xPoints = transpose(1:app.dataParams.epochS);
            hold(app.HYPg,'on')
            scatter(app.HYPg,xPoints,tmpHyp,5,baseHYPcols,"filled")
            hold(app.HYPg,'off')
            line(app.HYPg,[app.curEpoch app.curEpoch], [0 6], 'Color','g','LineStyle','--')    
        end
        
        % UPDATE ANNOTATION PLOT
        %%% Five states it needs to work
        % Done#1. Button press left and right
        % Done#2. Key press arrow left and right
        % Done#3. Sleep Stage button press - forward
        % Done#4. Sleep key press - forward
        % 5. Change epoch in spinner
        function updateEEGextra(app , FoB)
            
            % FoB = 1 = forward
            % FoB = ~1 = backward
            
            if FoB
                lastEpoch = app.curEpoch - 1;
            else
                lastEpoch = app.curEpoch + 1;
            end
            % Save current state
            app.dataParams.aroCt(lastEpoch) = app.arouseN1.Value;
            app.dataParams.notes(lastEpoch) = app.notesN1.Value;
            % Show current state
            app.arouseN1.Value = app.dataParams.aroCt(app.curEpoch);
            app.notesN1.Value = app.dataParams.notes(app.curEpoch);
        end
        
        function updateZoom(app , UD)
            zoomVals = [30, 60, 120, 180, 240];
            curIND = app.zoomINDex;
            switch UD
                case 1
                    newIND = curIND + 1;
                    
                    if newIND > 5
                        return
                    else
                        app.zoomINDex = newIND;
                        app.zoomIN.Value = zoomVals(newIND);
                        % Update zoom plot
                        updateEEGplot(app , 3)
                    end
                    
                case 2
                    newIND = curIND - 1;
                    
                    if newIND < 1
                        return
                    else
                        app.zoomINDex = newIND;
                        app.zoomIN.Value = zoomVals(newIND);
                        % Update zoom plot
                        updateEEGplot(app , 3)
                    end
            end
            
            if app.zoomINDex ~= 1
                % turn stuff off
                app.EEG_extra.Enable = 'off';
                app.SCORINGPanel.Enable = 'off';
            else
                % turn stuff on
                app.EEG_extra.Enable = 'on';
                app.SCORINGPanel.Enable = 'on';
            end
            
            
        end
        
        function udpateXticktime(app , si , stage , yTmax, yTmin)
            
            xLIMmax = app.zoomIN.Value;
            
            % Update Xlim
            xlim(app.(['EEG',num2str(si)]),...
                [1 xLIMmax])

            switch stage
                case {1,2}
                    % Update Xticks
                    xticks(app.(['EEG',num2str(si)]),...
                        floor(linspace(1,xLIMmax,4))) % for 10 second blocks

                    numOFlines10 = round(30/10) - 1;
                    sec10ind = floor(linspace(1,30,4));
                    sec10vals = sec10ind((1:numOFlines10) + 1);
                    
                    numOFlines5 = round(30/5) - 1;
                    sec5ind = floor(linspace(1,30,7));
                    sec5vals = sec5ind((1:numOFlines5) + 1);
                    sec5valsRM = sec5vals(~ismember(sec5vals,sec10vals));
                    fnumOfl5vals = length(sec5valsRM);
                    
                    numOFlines1 = round(30/1) - 1;
                    sec1ind = floor(linspace(1,30,30));
                    sec1vals = sec1ind((1:numOFlines1-1) + 1);
                    sec1valsRM = sec1vals(~ismember(sec1vals,[sec10vals sec5vals]));
                    fnumOfl1vals = length(sec1valsRM);
                    
                    % 1 second
                    line(app.(['EEG',num2str(si)]),...
                        [sec1valsRM;...
                        sec1valsRM],...
                        [repmat(yTmin,1,fnumOfl1vals);repmat(yTmax,1,fnumOfl1vals)],...
                        'Color',[0.85 0.85 0.85],'LineStyle','-','LineWidth',0.25)
                    % 5 seconds
                    line(app.(['EEG',num2str(si)]),...
                        [sec5valsRM; sec5valsRM],...
                        [repmat(yTmin,1,fnumOfl5vals);repmat(yTmax,1,fnumOfl5vals)],...
                        'Color',[0.25 0.25 0.25],'LineStyle','--','LineWidth',0.5)
                    % 10 seconds
                    line(app.(['EEG',num2str(si)]),...
                        [sec10vals; sec10vals],...
                        [repmat(yTmin,1,numOFlines10);repmat(yTmax,1,numOFlines10)],...
                        'Color',[0 0 0],'LineStyle','-.','LineWidth',0.75)

                case 3
                    numEpochs = round(app.zoomIN.Value/30);

                    % Update Xticks
                    xticks(app.(['EEG',num2str(si)]),...
                        floor(linspace(1,xLIMmax,1 + (2*numEpochs)))) % for 15 second blocks
 
                    % 10 second
                    numOFlines10 = round(app.zoomIN.Value/10) - 1;
                    sec10ind = floor(linspace(1,app.zoomIN.Value,round(app.zoomIN.Value/10) + 1));
                    sec10vals = sec10ind((1:numOFlines10) + 1);
                    % 5 seconds
                    numOFlines5 = round(app.zoomIN.Value/5) - 1;
                    sec5ind = floor(linspace(1,app.zoomIN.Value,round(app.zoomIN.Value/5) + 1));
                    sec5vals = sec5ind((1:numOFlines5) + 1);
                    sec5valsRM = sec5vals(~ismember(sec5vals,sec10vals));
                    fnumOfl5vals = length(sec5valsRM);
                    % 1 seconds
                    numOFlines1 = round(app.zoomIN.Value/1) - 1;
                    sec1ind = floor(linspace(1,app.zoomIN.Value,round(app.zoomIN.Value/1)));
                    sec1vals = sec1ind((1:numOFlines1-1) + 1);
                    sec1valsRM = sec1vals(~ismember(sec1vals,[sec10vals sec5vals]));
                    fnumOfl1vals = length(sec1valsRM);

                    % 10 seconds
                    line(app.(['EEG',num2str(si)]),...
                        [sec10vals; sec10vals],...
                        [repmat(yTmin,1,numOFlines10);repmat(yTmax,1,numOFlines10)],...
                        'Color',[0 0 0],'LineStyle','-.','LineWidth',0.75)
                    % 5 seconds
                    line(app.(['EEG',num2str(si)]),...
                        [sec5valsRM; sec5valsRM],...
                        [repmat(yTmin,1,fnumOfl5vals);repmat(yTmax,1,fnumOfl5vals)],...
                        'Color',[0.25 0.25 0.25],'LineStyle','--','LineWidth',0.5)
                    % 1 second
                    line(app.(['EEG',num2str(si)]),...
                        [sec1valsRM;sec1valsRM],...
                        [repmat(yTmin,1,fnumOfl1vals);repmat(yTmax,1,fnumOfl1vals)],...
                        'Color',[0.85 0.85 0.85],'LineStyle','-','LineWidth',0.25)
    
            end    
            
        end
        
        function getEEGbuttons(app)
            
            app.EEGbutIDS = cell(6,1);
            
            for ei = 1:6
                app.EEGbutIDS{ei} = app.(['eeg',num2str(ei),'dd']).Value;
            end
            
        end
        
        function vidEpoch = grabVidEpoch(app)
            
            epochNIND = app.VidNumInd == app.curVIDepoch.Value;
            
            vidEpoch = load(app.VidSelIndLcs{epochNIND}); 
            
        end
        
        function updateVIDep(app)
            
            app.vidEpoch = grabVidEpoch(app);
            image(app.vidAXES, app.vidEpoch.mov(1).cdata)
            colormap(app.vidAXES,'gray')
            axis(app.vidAXES, 'equal')
            
            tIND = 1 + (210*(app.curEpoch - 1));
            % Obtain timestamp for Epoch
            strTime = datestr(timeofday(app.dataParams.Atime(app.curEpoch)));
            
            % Plot Frame number
            text(app.vidAXES, 260, 7, ['Frame ' num2str(tIND)],...
                'FontSize', 20,'Color','r','FontWeight',"normal")
            % Plot Time [Epoch]
            text(app.vidAXES, 260, 15, strTime,'Color','r',...
                "FontWeight","normal","FontSize",20)
            
        end
        
        function updateVIDEEGplot(app , STAGE)
            
            switch STAGE
                case 1
                    
                    app.vEEGbutID = app.PSGvSel.Value;
                    
                    colID1 = app.vEEGbutID;
                    colID2 = app.dataParams.EEGtab.eegIND(ismember(app.dataParams.EEGtab.eegID,colID1));
                    
                    vecCell = app.subData{app.curEpoch,colID2};
                    eegVec = vecCell{1};
                    ylabel(app.VIDEEGaxes,colID1)
                    % Fix time vector
                    timeVEC = linspace(1,app.dataParams.sampPts/100,app.dataParams.sampPts);
                    plot(app.VIDEEGaxes ,timeVEC, eegVec)

                    yMax = max(eegVec);
                    yMin = abs(min(eegVec));
                    if yMax > yMin
                        yMaxR = yMax;
                        yMinR = yMax*-1;
                        ylim(app.VIDEEGaxes,[-1*yMax , yMax])
                    else
                        yMaxR = yMin;
                        yMinR = yMin*-1;
                        ylim(app.VIDEEGaxes,[-1*yMin , yMin])
                    end
                    
                    ylim(app.VIDEEGaxes,[yMinR , yMaxR])
                    
                    % NEW SUBtitle
                    if isempty(app.dataParams.SleepStg{app.curEpoch})
                        curSlepStg = 'NONE';
                    else
                        curSlepStg = app.dataParams.SleepStg{app.curEpoch};
                    end
                    
                    epochNN = num2str(app.curEpoch);
                    tIME = datestr(timeofday(app.dataParams.Atime(app.curEpoch)));
                    txt = ['Stage:  ',curSlepStg,'         EPOCH: ',epochNN...
                        '          TIME: ' ,tIME];
                    
                    subtitle(app.VIDEEGaxes,txt)
                    app.VIDEEGaxes.TitleHorizontalAlignment = 'left';
                    
                    % Update x-axis tick-marks
                    % Update Xlim
                    xlim(app.VIDEEGaxes,[1 30])
                    
                    % Update Xticks
                    xticks(app.VIDEEGaxes,...
                        floor(linspace(1,30,4))) % for 10 second blocks
                    
                    numOFlines10 = round(30/10) - 1;
                    sec10ind = floor(linspace(1,30,4));
                    sec10vals = sec10ind((1:numOFlines10) + 1);
                    
                    numOFlines5 = round(30/5) - 1;
                    sec5ind = floor(linspace(1,30,7));
                    sec5vals = sec5ind((1:numOFlines5) + 1);
                    sec5valsRM = sec5vals(~ismember(sec5vals,sec10vals));
                    fnumOfl5vals = length(sec5valsRM);
                    
                    numOFlines1 = round(30/1) - 1;
                    sec1ind = floor(linspace(1,30,30));
                    sec1vals = sec1ind((1:numOFlines1-1) + 1);
                    sec1valsRM = sec1vals(~ismember(sec1vals,[sec10vals sec5vals]));
                    fnumOfl1vals = length(sec1valsRM);
                    
                    % 1 second
                    line(app.VIDEEGaxes,...
                        [sec1valsRM;...
                        sec1valsRM],...
                        [repmat(yMinR,1,fnumOfl1vals);repmat(yMaxR,1,fnumOfl1vals)],...
                        'Color',[0.85 0.85 0.85],'LineStyle','-','LineWidth',0.25)
                    % 5 seconds
                    line(app.VIDEEGaxes,...
                        [sec5valsRM; sec5valsRM],...
                        [repmat(yMinR,1,fnumOfl5vals);repmat(yMaxR,1,fnumOfl5vals)],...
                        'Color',[0.25 0.25 0.25],'LineStyle','--','LineWidth',0.5)
                    % 10 seconds
                    line(app.VIDEEGaxes,...
                        [sec10vals; sec10vals],...
                        [repmat(yMinR,1,numOFlines10);repmat(yMaxR,1,numOFlines10)],...
                        'Color',[0 0 0],'LineStyle','-.','LineWidth',0.75)
                    
                    app.curVIDdat = eegVec;
                    
                    curLINE = round((app.CURframe/210)*30,2) + 1;
                    
                    xline(app.VIDEEGaxes,curLINE,"LineWidth",2,"LineStyle",'--',...
                        "Color","m")
                    
                case 2

                    
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            msg = 'Sleep Score Expert ID';
            title = 'Confirm ID';
            selection = uiconfirm(app.UIFigure,msg,title,...
                'Options',{'C_Kushida','S_Tang','L_West','M_Summers'},...
                'DefaultOption',1,'Icon','question');
            
            switch selection
                case 'C_Kushida'
                    app.ScoreID = 'CK';
                    app.CK_l.FontColor = [0 0 0];
                case 'S_Tang'
                    app.ScoreID = 'ST';
                    app.ST_l.FontColor = [0 0 0];
                case 'L_West'
                    app.ScoreID = 'LW';
                    app.LW_l.FontColor = [0 0 0];
                case 'M_Summers'
                    app.ScoreID = 'MS';
                    app.MS_l.FontColor = [0 0 0];
            end

            
        end

        % Menu selected function: SETD
        function SETDMenuSelected(app, event)
            % Disenable Set directory menu
            app.SETD.Enable = 'off';

            % Select folder with .txt file
            fileFIND = 1;
            
            while fileFIND
                [txtFile,txtFold] = uigetfile('*.txt','LOCATE UH3NS11376A1_dsLOC.txt');
                if strcmp(txtFile,'UH3NS11376A1_dsLOC.txt')
                    uialert(app.UIFigure,"You have successfully located the data folder!","Data Folder","Icon","info")
                    fileFIND = 0;
                end
            end
 
            % locate hard coded DS directory
            app.inDlcs.dsLOC1 = txtFold;
            
            % Navigate to DS and populate List
            cd(app.inDlcs.dsLOC1)
            % Idenitify available recordings
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % [FUTURE - use FILE NAME to populate info] - WILL BE .MAT %%%%
            subDir = dir('*.mat');
            subDirn = {subDir.name};
            app.subjectL.Items = ['...', subDirn];   
            
            % Set VideoDir
            app.videoLOC = [app.inDlcs.dsLOC1 , 'VIDEOdata'];
            app.subjectLpanel.Enable = 'on';
            
        end

        % Menu selected function: NewSub
        function NewSubMenuSelected(app, event)
            
            % Reset EEG list
            % Clear plots
            for ei = 1:4
                app.(['eeg',num2str(ei),'dd']).Items = {'EEG1','EEG2','EEG3','EEG4'};
                app.(['eeg',num2str(ei),'dd']).Value = ['EEG',num2str(ei)];
                app.(['EEG',num2str(ei)]).Visible = 'off';
                cla(app.(['EEG',num2str(ei)]))
            end
            % Turn subject list back on
            app.subjectL.Enable = 'on';
            % Turn off new subject
            app.NewSub.Enable = 'off';
            
            %% Sleep Score Panel
            % Turn off Scoring Panel
            app.SCORINGPanel.Enable = 'off';
            % Turn off Hypnogram
            app.HypnoP.Enable = 'off';
            
        end

        % Menu selected function: EXITMenu
        function EXITMenuSelected(app, event)
            
            selection = uiconfirm(app.UIFigure,'Close Program?','Confirm Close',...
                'Icon','warning');
            switch selection
                case 'OK'
                    delete(app)
                case 'Cancel'
                    return
            end
            
        end

        % Button pushed function: epochB
        function epochBButtonPushed(app, event)
            app.curEpoch = app.curEpoch - 1;
            if app.curEpoch < 1
                app.curEpoch = 1;
                return
            else
                app.EPOCHSpin.Value = app.curEpoch;
                app.curVIDepoch.Value = app.curEpoch;
                updateEEGplot(app, 2)
                
                % Update Hypnogram
                updateHYPlot(app)
                
                % Update Video view
                updateVIDep(app)
                
                % Update VID EEG plot
                updateVIDEEGplot(app, 1)
                
                % Update EEG extra data
                updateEEGextra(app,0)
            end
        end

        % Button pushed function: epochF
        function epochFButtonPushed(app, event)
            app.curEpoch = app.curEpoch + 1;
            if app.curEpoch > app.dataParams.epochS
                app.curEpoch = app.dataParams.epochS;
                return
            else
                app.EPOCHSpin.Value = app.curEpoch;
                app.curVIDepoch.Value = app.curEpoch;
                updateEEGplot(app, 2)
                
                % Update Hypnogram
                updateHYPlot(app)
                
                % Update Video view
                updateVIDep(app)
                
                % Update VID EEG plot
                updateVIDEEGplot(app, 1)
                
                % Update EEG extra data
                updateEEGextra(app,1)
            end
        end

        % Value changed function: EPOCHSpin
        function EPOCHSpinValueChanged(app, event)
            lastEpoch = app.curEpoch;
            app.curEpoch = app.EPOCHSpin.Value;
            if app.curEpoch >= app.dataParams.epochS || app.curEpoch <= 1
                return
            else
                app.EPOCHSpin.Value = app.curEpoch;
                app.curVIDepoch.Value = app.curEpoch;
                
                updateEEGplot(app, 2)
                
                % Update Hypnogram
                updateHYPlot(app)
                
                % Update Video view
                updateVIDep(app)
                
                % Update VID EEG plot
                updateVIDEEGplot(app, 1)
                
                if lastEpoch < app.curEpoch
                    updateEEGextra(app,2)
                else
                    updateEEGextra(app,1)
                end
            end
            
        end

        % Callback function
        function smP1ValueChanged(app, event)
            updateEEGplot(app , 2)
        end

        % Callback function
        function smP2ValueChanged(app, event)
            updateEEGplot(app , 2)
            
        end

        % Callback function
        function smP3ValueChanged(app, event)
            updateEEGplot(app , 2)
            
        end

        % Callback function
        function smP4ValueChanged(app, event)
            updateEEGplot(app , 2)
        end

        % Button pushed function: WB
        function WBButtonPushed(app, event)
            updateButtonAct(app,'W')
        end

        % Button pushed function: RB
        function RBButtonPushed(app, event)
            updateButtonAct(app,'R')
        end

        % Button pushed function: N1B
        function N1BButtonPushed(app, event)
            updateButtonAct(app,'N1')
        end

        % Button pushed function: N2B
        function N2BButtonPushed(app, event)
            updateButtonAct(app,'N2')
        end

        % Button pushed function: N3B
        function N3BButtonPushed(app, event)
            updateButtonAct(app,'N3')
        end

        % Callback function
        function hypSwValueChanged(app, event)
            
            updateHYPlot(app)
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            if strcmp(app.useKEYS.Value,'Off')
                return
            else
                
                switch key
                    case '1'
                        if app.zoomINDex == 1
                            updateButtonAct(app,'N1')
                        else
                            return
                        end
                    case '2'
                        if app.zoomINDex == 1
                            updateButtonAct(app,'N2')
                        else
                            return
                        end
                    case '3'
                        if app.zoomINDex == 1
                            updateButtonAct(app,'N3')
                        else
                            return
                        end
                    case 'r'
                        if app.zoomINDex == 1
                            updateButtonAct(app,'R')
                        else
                            return
                        end
                    case 'w'
                        if app.zoomINDex == 1
                            updateButtonAct(app,'W')
                        else
                            return
                        end
                    case 'uparrow'
                        updateZoom(app , 1)
                    case 'downarrow'
                        updateZoom(app , 2)
                    case 'leftarrow'
                        
                        
                        app.curEpoch = app.curEpoch - 1;
                        if app.curEpoch < 1
                            app.curEpoch = 1;
                            return
                        else
                            if app.zoomINDex == 1
                                app.EPOCHSpin.Value = app.curEpoch;
                                app.curVIDepoch.Value = app.curEpoch;
                                updateEEGplot(app, 2)
                                updateHYPlot(app)
                                updateEEGextra(app,0)
                                % Update Video view
                                updateVIDep(app)
                                % Update VID EEG plot
                                updateVIDEEGplot(app, 1)
                            else
                                app.EPOCHSpin.Value = app.curEpoch;
                                app.curVIDepoch.Value = app.curEpoch;
                                updateEEGplot(app, 3)
                                % Update Video view
                                updateVIDep(app)
                                % Update VID EEG plot
                                updateVIDEEGplot(app ,1)
                            end
                        end
                        
                        
                    case 'rightarrow'
                        
                        app.curEpoch = app.curEpoch + 1;
                        if app.curEpoch > app.dataParams.epochS
                            app.curEpoch = app.dataParams.epochS;
                            return
                        else
                            if app.zoomINDex == 1
                                app.EPOCHSpin.Value = app.curEpoch;
                                app.curVIDepoch.Value = app.curEpoch;
                                updateEEGplot(app, 2)
                                updateHYPlot(app)
                                updateEEGextra(app,1)
                                % Update Video view
                                updateVIDep(app)
                                % Update VID EEG plot
                                updateVIDEEGplot(app, 1)
                            else
                                app.EPOCHSpin.Value = app.curEpoch;
                                app.curVIDepoch.Value = app.curEpoch;
                                updateEEGplot(app, 3)
                                % Update Video view
                                updateVIDep(app)
                                % Update VID EEG plot
                                updateVIDEEGplot(app, 1)
                            end
                        end
                        
                    otherwise
                        return
                end
            end
        end

        % Value changed function: subjectL
        function subjectLValueChanged(app, event)
            app.subSel = app.subjectL.Value;
            
            if strcmp(app.subSel,'...')
                return
            end
            
            % ADD progress bar
            subSelprog = uiprogressdlg(app.UIFigure,'Title','LOADING SUBJECT',...
                'Indeterminate','on');
            drawnow
            
            %  app.subData = edfread('SC4002E0-PSG.edf');
            cd(app.inDlcs.dsLOC1)
            tmpLoad = load(app.subjectL.Value,'TT');
            app.subData = tmpLoad.TT;
            % Get and Load Video Folder for subject
            % Extract case name
            app.VidSelname = extractBefore(app.subSel,".");
            app.VidSelLOC = [app.videoLOC , filesep, app.VidSelname];
            % CD to folder
            cd(app.VidSelLOC)
            % Create cell array of file names
            vidMatD = dir('*.mat');
            vidMatDn = transpose({vidMatD.name});
            % Create cell array of folder locs
            app.VidNumInd = cellfun(@(x) str2double(x),...
                extractAfter(extractBefore(vidMatDn,'.'),'_'),'UniformOutput',true);
            app.VidSelIndLcs = join([repmat(app.VidSelLOC, numel(vidMatDn) ,1) ,...
                join([repmat(string(filesep),numel(vidMatDn),1) , string(vidMatDn)],'')],'');
            % Trim the number of TT epochs to match the number of video
            % epochs %%%% UPDATE with FINAL TTL [FUTURE] %%%%%%%%%%%%%%%%%%
            maxVIDePOCH = max(app.VidNumInd);
            maxEEGePOCH = height(app.subData);
            
            if maxVIDePOCH < maxEEGePOCH
                app.subData = app.subData(1:maxVIDePOCH,:);
            else
                remIND = transpose(maxEEGePOCH+1:1:maxVIDePOCH);
                remBool = ~ismember(app.VidNumInd,remIND);
                app.VidNumInd = app.VidNumInd(remBool,:);
                app.VidSelIndLcs = app.VidSelIndLcs(remBool,:);
            end

            updateParams(app , 1)
            
            app.EEGElectrodesPanel.Enable = 'on';
            % Update EEG Panel buttons
            for ebi = 1:6
                app.(['eeg',num2str(ebi),'dd']).Enable = 'on';
                app.(['eeg',num2str(ebi),'dd']).Items = table2array(app.dataParams.EEGtab(:,2));
                app.(['eeg',num2str(ebi),'dd']).Value = app.dataParams.EEGtab{ebi,2};
            end
            
            % Update VIDeeg DropDown button options
            app.PSGvSel.Items = table2array(app.dataParams.EEGtab(:,2));
            app.PSGvSel.Value = app.dataParams.EEGtab{1,2};
            
            % Set button list
            getEEGbuttons(app)
            % Turn on New Subject
            app.NewSub.Enable = 'on';
            % Dis-enable Subject List
            app.subjectL.Enable = 'off';
            % Turn on Start Score button
            app.stScore.Enable = 'on';
            % Current Epoch start
            app.curEpoch = 1;
            
            % CLOSE PROGRESS
            close(subSelprog)
            
        end

        % Button pushed function: stScore
        function stScoreButtonPushed(app, event)
            % Update EEG Panel buttons
            for ebi = 1:6 % NUMBER of EEG PLOTS - WAS app.dataParams.EEGnum  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                app.(['EEG',num2str(ebi)]).Visible = 'on';
            end
            
            % Plot First Epoch of Data
            updateEEGplot(app, 1)
            
            % Plot First Epoch of VidEEG
            app.CURframe = 1;
            updateVIDEEGplot(app , 1)
            
            % Open Panels
            app.SCORINGPanel.Enable = 'on';
            app.EEG_extra.Enable = 'on';
            
            % Turn off start scoring button
            app.stScore.Enable = 'off';
            
            % Turn on smooth plot
            app.smoothPanel.Enable = 'on';
            
            for si = 1:6 % NUMBER of EEG PLOTS - WAS app.dataParams.EEGnum %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                app.(['smP',num2str(si)]).Enable = 'on';
            end
            
            % If any hypodata is present - added 2/9/2021
            updateHYPlot(app)
            
            % Update EEG extra data If any hypodata is present - added 2/9/2021
            updateEEGextra(app,0)
            
            % Open Video Panels
            app.PlaybackPanel.Enable = 'on';
            app.vidEpochPanel.Enable = 'on';
            app.playSpeed.Enable = 'on';
            app.PSGvPanel.Enable = 'on';
            
            % Plot first Video view
            updateVIDep(app)


        end

        % Button pushed function: SAVEDATA
        function SAVEDATAButtonPushed(app, event)
            
            saveDLG = uiprogressdlg(app.UIFigure,'Title','SAVING DATA...Please Wait!',...
                'Indeterminate','on');
            drawnow

            % Get Folder Loc
            cd(app.inDlcs.dsLOC1)
            % Save out data
            app.subData.(app.ScoreID) = app.dataParams.SleepStg; % has sleep stages
            TT = app.subData;
            save(app.subSel,"TT");
            
            % Message that data has been saved
            close(saveDLG)
            uialert(app.UIFigure,'Data SAVED!','Save File Confirm','Icon',"success");
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            for i = 1:length(app.vidEpoch.mov)
                app.CURframe = i;
                % Plot image
                image(app.vidAXES, app.vidEpoch.mov(i).cdata)
                % Obtain frame number
                tIND = i + (210*(app.curEpoch - 1));
                % Obtain timestamp for Epoch
                strTime = datestr(timeofday(app.dataParams.Atime(app.curEpoch)));
                % Obtain seconds
                secsText = [num2str(round((i/210)*30,2),2) ,' secs'];
                
                % Plot Frame number
                text(app.vidAXES, 260, 7, ['Frame ' num2str(tIND)],...
                    'FontSize', 20,'Color','r','FontWeight',"normal")
                % Plot Time [Epoch]
                text(app.vidAXES, 260, 15, strTime,'Color','r',...
                    "FontWeight","normal","FontSize",20)
                % Plot seconds
                text(app.vidAXES, 260, 23, secsText,'Color','r',...
                    "FontWeight","normal","FontAngle","italic","FontSize",...
                    20)

                colormap(app.vidAXES,'gray')
                
                % Play line 
                updateVIDEEGplot(app, 1)
                
                % Update using SPEED BUTTONS [7 fps , 14 fps , 21 fps]
                switch app.playSpeed.SelectedObject.Text
                    case '1x'
                        pause(1/30)
                    case '5x'
                        pause(1/2000)
                    case '10x'
                        pause(1/30)
                end
                
                
            end
            app.CURframe = 1;
            
        end

        % Value changed function: curVIDepoch
        function curVIDepochValueChanged(app, event)
            lastEpoch = app.curEpoch;
            app.curEpoch = app.curVIDepoch.Value;
            if app.curEpoch >= app.dataParams.epochS || app.curEpoch <= 1
                return
            else
                app.curVIDepoch.Value = app.curEpoch;
                app.EPOCHSpin.Value = app.curEpoch;
                
                updateEEGplot(app, 2)
                
                updateHYPlot(app)
                
                % Update Video view
                updateVIDep(app)
                
                % Update VID EEG plot
                updateVIDEEGplot(app, 1)
                
                if lastEpoch < app.curEpoch
                    updateEEGextra(app,2)
                else
                    updateEEGextra(app,1)
                end
            end

        end

        % Selection changed function: playSpeed
        function playSpeedSelectionChanged(app, event)
%             selectedButton = app.playSpeed.SelectedObject;
            
        end

        % Value changed function: PSGvSel
        function PSGvSelValueChanged(app, event)
            
            updateVIDEEGplot(app, 1)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.SETD = uimenu(app.UIFigure);
            app.SETD.MenuSelectedFcn = createCallbackFcn(app, @SETDMenuSelected, true);
            app.SETD.Text = 'SET Directory';
            app.NewSub = uimenu(app.UIFigure);
            app.NewSub.MenuSelectedFcn = createCallbackFcn(app, @NewSubMenuSelected, true);
            app.NewSub.Enable = 'off';
            app.NewSub.Text = 'New Subject';
            app.EXITMenu = uimenu(app.UIFigure);
            app.EXITMenu.MenuSelectedFcn = createCallbackFcn(app, @EXITMenuSelected, true);
            app.EXITMenu.Text = 'EXIT';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SleepScore = uitab(app.TabGroup);
            app.SleepScore.Title = 'SleepScore';
            app.HYPg = uiaxes(app.SleepScore);
            app.HYPg.YLim = [0 6];
            app.HYPg.XTick = [];
            app.HYPg.XTickLabel = '';
            app.HYPg.YTick = [1 2 3 4 5];
            app.HYPg.YTickLabel = {'N3'; 'N2'; 'N1'; 'R'; 'W'};
            app.SCORINGPanel = uipanel(app.SleepScore);
            app.SCORINGPanel.Enable = 'off';
            app.SCORINGPanel.Title = 'SCORING Panel';
            app.epochB = uibutton(app.SCORINGPanel, 'push');
            app.epochB.ButtonPushedFcn = createCallbackFcn(app, @epochBButtonPushed, true);
            app.epochB.Text = '<- Epoch';
            app.epochF = uibutton(app.SCORINGPanel, 'push');
            app.epochF.ButtonPushedFcn = createCallbackFcn(app, @epochFButtonPushed, true);
            app.epochF.Text = 'Epoch ->';
            app.EPOCHSpinnerLabel = uilabel(app.SCORINGPanel);
            app.EPOCHSpinnerLabel.Text = 'EPOCH';
            app.EPOCHSpin = uispinner(app.SCORINGPanel);
            app.EPOCHSpin.ValueChangedFcn = createCallbackFcn(app, @EPOCHSpinValueChanged, true);
            app.EPOCHSpin.Value = 1;
            app.WB = uibutton(app.SCORINGPanel, 'push');
            app.WB.ButtonPushedFcn = createCallbackFcn(app, @WBButtonPushed, true);
            app.WB.Text = 'W';
            app.RB = uibutton(app.SCORINGPanel, 'push');
            app.RB.ButtonPushedFcn = createCallbackFcn(app, @RBButtonPushed, true);
            app.RB.Text = 'R';
            app.N1B = uibutton(app.SCORINGPanel, 'push');
            app.N1B.ButtonPushedFcn = createCallbackFcn(app, @N1BButtonPushed, true);
            app.N1B.Text = 'N1';
            app.N2B = uibutton(app.SCORINGPanel, 'push');
            app.N2B.ButtonPushedFcn = createCallbackFcn(app, @N2BButtonPushed, true);
            app.N2B.Text = 'N2';
            app.N3B = uibutton(app.SCORINGPanel, 'push');
            app.N3B.ButtonPushedFcn = createCallbackFcn(app, @N3BButtonPushed, true);
            app.N3B.Text = 'N3';
            app.totEp = uilabel(app.SCORINGPanel);
            app.totEp.Text = 'TOTAL';
            app.EEG_extra = uipanel(app.SleepScore);
            app.EEG_extra.Enable = 'off';
            app.EEG_extra.Title = 'Additional information';
            app.NotesTextAreaLabel = uilabel(app.EEG_extra);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.notesN1 = uitextarea(app.EEG_extra);
            app.ArousalSpinnerLabel = uilabel(app.EEG_extra);
            app.ArousalSpinnerLabel.Text = 'Arousal #';
            app.arouseN1 = uispinner(app.EEG_extra);
            app.ZoominSecondsGaugeLabel = uilabel(app.SleepScore);
            app.ZoominSecondsGaugeLabel.Text = 'Zoom in Seconds';
            app.zoomIN = uigauge(app.SleepScore, 'linear');
            app.zoomIN.Limits = [30 240];
            app.zoomIN.MajorTicks = [30 60 120 180 240];
            app.zoomIN.MinorTicks = [];
            app.zoomIN.Value = 30;
            app.Electrophysiology = uipanel(app.SleepScore);
            app.GridLayout = uigridlayout(app.Electrophysiology);
            app.EEG1 = uiaxes(app.GridLayout);
            app.EEG1.XTick = [];
            app.EEG1.YTick = [];
            app.EEG2 = uiaxes(app.GridLayout);
            app.EEG2.XTick = [];
            app.EEG2.YTick = [];
            app.EEG3 = uiaxes(app.GridLayout);
            app.EEG3.XTick = [];
            app.EEG3.YTick = [];
            app.EEG4 = uiaxes(app.GridLayout);
            app.EEG4.XTick = [];
            app.EEG4.YTick = [];
            app.EEG5 = uiaxes(app.GridLayout);
            app.EEG5.XTick = [];
            app.EEG5.YTick = [];
            app.EEG6 = uiaxes(app.GridLayout);
            app.EEG6.XTick = [];
            app.EEG6.YTick = [];
            app.EEGElectrodesPanel = uipanel(app.SleepScore);
            app.EEGElectrodesPanel.Title = 'EEG Electrodes';
            app.eeg1dd = uidropdown(app.EEGElectrodesPanel);
            app.eeg1dd.Items = {'EEG1', 'EEG2', 'EEG3', 'EEG4', 'EEG5', 'EEG6'};
            app.eeg1dd.Value = 'EEG1';
            app.eeg3dd = uidropdown(app.EEGElectrodesPanel);
            app.eeg3dd.Items = {'EEG1', 'EEG2', 'EEG3', 'EEG4', 'EEG5', 'EEG6'};
            app.eeg3dd.Value = 'EEG3';
            app.eeg4dd = uidropdown(app.EEGElectrodesPanel);
            app.eeg4dd.Items = {'EEG1', 'EEG2', 'EEG3', 'EEG4', 'EEG5', 'EEG6'};
            app.eeg4dd.Value = 'EEG4';
            app.eeg2dd = uidropdown(app.EEGElectrodesPanel);
            app.eeg2dd.Items = {'EEG1', 'EEG2', 'EEG3', 'EEG4', 'EEG5', 'EEG6'};
            app.eeg2dd.Value = 'EEG2';
            app.eeg5dd = uidropdown(app.EEGElectrodesPanel);
            app.eeg5dd.Items = {'EEG1', 'EEG2', 'EEG3', 'EEG4', 'EEG5', 'EEG6'};
            app.eeg5dd.Value = 'EEG5';
            app.eeg6dd = uidropdown(app.EEGElectrodesPanel);
            app.eeg6dd.Items = {'EEG1', 'EEG2', 'EEG3', 'EEG4', 'EEG5', 'EEG6'};
            app.eeg6dd.Value = 'EEG6';
            app.smoothPanel = uipanel(app.SleepScore);
            app.smoothPanel.Enable = 'off';
            app.smoothPanel.Title = 'Smooth';
            app.smP1 = uidropdown(app.smoothPanel);
            app.smP1.Items = {'1', '10', '25', '30'};
            app.smP1.Enable = 'off';
            app.smP1.Value = '1';
            app.smP2 = uidropdown(app.smoothPanel);
            app.smP2.Items = {'1', '10', '25', '30'};
            app.smP2.Enable = 'off';
            app.smP2.Value = '1';
            app.smP3 = uidropdown(app.smoothPanel);
            app.smP3.Items = {'1', '10', '25', '30'};
            app.smP3.Enable = 'off';
            app.smP3.Value = '1';
            app.smP4 = uidropdown(app.smoothPanel);
            app.smP4.Items = {'1', '10', '25', '30'};
            app.smP4.Enable = 'off';
            app.smP4.Value = '1';
            app.smP5 = uidropdown(app.smoothPanel);
            app.smP5.Items = {'1', '10', '25', '30'};
            app.smP5.Enable = 'off';
            app.smP5.Value = '1';
            app.smP6 = uidropdown(app.smoothPanel);
            app.smP6.Items = {'1', '10', '25', '30'};
            app.smP6.Enable = 'off';
            app.smP6.Value = '1';
            app.subjectLpanel = uipanel(app.SleepScore);
            app.subjectLpanel.Enable = 'off';
            app.subjectLpanel.Title = 'Subject List';
            app.subjectL = uilistbox(app.subjectLpanel);
            app.subjectL.Items = {'Subjects...', ''};
            app.subjectL.ValueChangedFcn = createCallbackFcn(app, @subjectLValueChanged, true);
            app.subjectL.Value = 'Subjects...';
            app.stScore = uibutton(app.SleepScore, 'push');
            app.stScore.ButtonPushedFcn = createCallbackFcn(app, @stScoreButtonPushed, true);
            app.stScore.Enable = 'off';
            app.stScore.Text = 'Start Scoring';
            app.USEKEYSSwitchLabel = uilabel(app.SleepScore);
            app.USEKEYSSwitchLabel.Text = 'USE KEYS';
            app.useKEYS = uiswitch(app.SleepScore, 'slider');
            app.SAVEDATA = uibutton(app.SleepScore, 'push');
            app.SAVEDATA.ButtonPushedFcn = createCallbackFcn(app, @SAVEDATAButtonPushed, true);
            app.SAVEDATA.Text = 'SAVE DATA';
            app.MicrovoltsPanel = uipanel(app.SleepScore);
            app.MicrovoltsPanel.Title = 'Microvolts';
            app.YaxisZoomSpinnerLabel = uilabel(app.MicrovoltsPanel);
            app.YaxisZoomSpinnerLabel.Text = 'Y-axis Zoom';
            app.mv1 = uispinner(app.MicrovoltsPanel);
            app.mv1.Limits = [1 4];
            app.mv1.Value = 1;
            app.YaxisZoomSpinnerLabel_2 = uilabel(app.MicrovoltsPanel);
            app.YaxisZoomSpinnerLabel_2.Text = 'Y-axis Zoom';
            app.mv2 = uispinner(app.MicrovoltsPanel);
            app.mv2.Limits = [1 4];
            app.mv2.Value = 1;
            app.YaxisZoomSpinnerLabel_3 = uilabel(app.MicrovoltsPanel);
            app.YaxisZoomSpinnerLabel_3.Text = 'Y-axis Zoom';
            app.mv3 = uispinner(app.MicrovoltsPanel);
            app.mv3.Limits = [1 4];
            app.mv3.Value = 1;
            app.YaxisZoomSpinnerLabel_4 = uilabel(app.MicrovoltsPanel);
            app.YaxisZoomSpinnerLabel_4.Text = 'Y-axis Zoom';
            app.mv4 = uispinner(app.MicrovoltsPanel);
            app.mv4.Limits = [1 4];
            app.mv4.Value = 1;
            app.YaxisZoomSpinnerLabel_5 = uilabel(app.MicrovoltsPanel);
            app.YaxisZoomSpinnerLabel_5.Text = 'Y-axis Zoom';
            app.mv5 = uispinner(app.MicrovoltsPanel);
            app.mv5.Limits = [1 4];
            app.mv5.Value = 1;
            app.YaxisZoomSpinnerLabel_6 = uilabel(app.MicrovoltsPanel);
            app.YaxisZoomSpinnerLabel_6.Text = 'Y-axis Zoom';
            app.mv6 = uispinner(app.MicrovoltsPanel);
            app.mv6.Limits = [1 4];
            app.mv6.Value = 1;
            app.USERIDPanel = uipanel(app.SleepScore);
            app.USERIDPanel.Title = 'USER ID';
            app.CK_l = uilabel(app.USERIDPanel);
            app.CK_l.Text = 'CK';
            app.LW_l = uilabel(app.USERIDPanel);
            app.LW_l.Text = 'LW';
            app.ST_l = uilabel(app.USERIDPanel);
            app.ST_l.Text = 'ST';
            app.MS_l = uilabel(app.USERIDPanel);
            app.MS_l.Text = 'MS';
            app.VIDEOPlayBackTab = uitab(app.TabGroup);
            app.VIDEOPlayBackTab.Title = 'VIDEO PlayBack';
            app.vidAXES = uiaxes(app.VIDEOPlayBackTab);
            app.vidAXES.XTick = [];
            app.vidAXES.YTick = [];
            app.VIDEEGaxes = uiaxes(app.VIDEOPlayBackTab);
            app.VIDEEGaxes.XTick = [];
            app.VIDEEGaxes.YTick = [];
            app.vidEpochPanel = uipanel(app.VIDEOPlayBackTab);
            app.vidEpochPanel.Enable = 'off';
            app.vidEpochPanel.Title = 'EPOCH Panel';
            app.EPOCHSpinnerLabel_2 = uilabel(app.vidEpochPanel);
            app.EPOCHSpinnerLabel_2.Text = 'EPOCH';
            app.curVIDepoch = uispinner(app.vidEpochPanel);
            app.curVIDepoch.Limits = [1 Inf];
            app.curVIDepoch.ValueChangedFcn = createCallbackFcn(app, @curVIDepochValueChanged, true);
            app.curVIDepoch.Value = 1;
            app.PlaybackPanel = uipanel(app.VIDEOPlayBackTab);
            app.PlaybackPanel.Enable = 'off';
            app.PlaybackPanel.Title = 'Playback Panel';
            app.PlayButton = uibutton(app.PlaybackPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = 'vidPlay.png';
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.PlaybackPanel, 'push');
            app.StopButton.Icon = 'vidStop.png';
            app.StopButton.Enable = 'off';
            app.StopButton.Visible = 'off';
            app.StopButton.Text = 'Stop';
            app.playSpeed = uibuttongroup(app.VIDEOPlayBackTab);
            app.playSpeed.SelectionChangedFcn = createCallbackFcn(app, @playSpeedSelectionChanged, true);
            app.playSpeed.Enable = 'off';
            app.playSpeed.Title = 'Playback Speed';
            app.oneX = uitogglebutton(app.playSpeed);
            app.oneX.Text = '1x';
            app.oneX.Value = true;
            app.twoX = uitogglebutton(app.playSpeed);
            app.twoX.Text = '5x';
            app.thrX = uitogglebutton(app.playSpeed);
            app.thrX.Text = '10x';
            app.PSGvPanel = uipanel(app.VIDEOPlayBackTab);
            app.PSGvPanel.Enable = 'off';
            app.PSGvPanel.Title = 'PSG Data Selection';
            app.PSGSIGNALDropDownLabel = uilabel(app.PSGvPanel);
            app.PSGSIGNALDropDownLabel.Text = 'PSG SIGNAL';
            app.PSGvSel = uidropdown(app.PSGvPanel);
            app.PSGvSel.Items = {'EEG1', 'EEG2', 'EEG3', 'EEG4', 'EEG5', 'EEG6'};
            app.PSGvSel.ValueChangedFcn = createCallbackFcn(app, @PSGvSelValueChanged, true);
            app.PSGvSel.Value = 'EEG1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SleepStage_InitialReview_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end