classdef SleepDataCreateTT_ALL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        ChannelMenu    matlab.ui.container.Menu
        dataOptions    matlab.ui.container.ButtonGroup
        LFPnoFEEG      matlab.ui.control.ToggleButton
        LFPnoF         matlab.ui.control.ToggleButton
        EEGLFP         matlab.ui.control.ToggleButton
        LFPOnly        matlab.ui.control.ToggleButton
        EEGOnly        matlab.ui.control.ToggleButton
        StatusPanel    matlab.ui.container.Panel
        datE           matlab.ui.control.Label
        tasK           matlab.ui.control.Label
        INFOLabel      matlab.ui.control.Label
        PROCESSButton  matlab.ui.control.Button
        LOAD           matlab.ui.control.Button
        OptionsPanel   matlab.ui.container.Panel
    end


    properties (Access = private)
        fileLOC    % Sleep data Folder directory
        fileLIST   % List of .mat files for entire night
        chanlist   % AO names for channels on ECoG module
        chanlist_LFP
        labelTab     % Label Table
        labelTab_LFP
        eegTTraw      % Time table for EEG
        eogTTraw      % Time table for EOG
        emgTTraw      % Time table for EMG
        lfpTTraw
        eegTTpp
        eogTTpp
        emgTTpp
        lfpTTpp
        lfpTTNdnS
        lfpTTrawNF
        eegMODsf
        lfpMODsf
        saveLOC
        fname
        fnameMAT
        eegProc
        chanNUMb
        chanNUMb_LFP
        chansleep
        chansleep_LFP
        varlist
        recSTART
        outP
    end

    methods (Access = private)


        function outTTab = createBINS(app,chan,chanLABS,SF)

            switch chan
                case 'emg'
                    rawDAT = app.emgTTpp;
                case 'eog'
                    rawDAT = app.eogTTpp;
                case 'eeg'
                    rawDAT = app.eegTTpp;
                case 'lfp'
                    rawDAT = app.lfpTTpp;
                case 'lfpr'
                    rawDAT = app.lfpTTNdnS;

            end

            binMG = uiprogressdlg(app.UIFigure,'Title',['Creating Bins ',chan],...
                'Indeterminate','on');
            drawnow

            num30bins = floor(height(rawDAT)/SF/30);
            tstart = seconds(0);
            tend = seconds(30);
            outBINS = cell(num30bins,size(rawDAT,2));
            for si = 1:num30bins

                timeLimits = timerange(tstart,tend);

                tmpT = rawDAT(timeLimits,:);
                tt2t = timetable2table(tmpT);
                t2c = table2cell(tt2t);
                cleanCel = cell2mat(t2c(:,2:end));

                for cchi = 1:size(rawDAT,2)
                    outBINS{si,cchi} = cleanCel(:,cchi);
                end

                tstart = tend;
                tend = tstart + seconds(30);

                disp([num2str(si) ,' out of ' , num2str(num30bins)])

            end

            % Create new TimeTable
            % Time
            timeBINS = transpose(seconds(0:30:30*num30bins-1));
            % Data
            finalTT = cell2table(outBINS);
            % Variable names
            varNAMES = chanLABS;
            % Create Table
            finalTT.Properties.VariableNames = varNAMES;
            finalTT.Time = timeBINS;

            outTTab = table2timetable(finalTT);

            close(binMG)
        end

        function selectSignalProcess(app, signAl)

            switch signAl
                case 'psg'
                    app.tasK.Text = 'FULL';

                    progBAR = uiprogressdlg(app.UIFigure,'Title','Combining Files...',...
                        'Message','1');
                    drawnow

                    tmpEEGarray = zeros(60*60*16*app.eegMODsf,app.chanNUMb(1)); % 16 Hours
                    tmpEOGarray = zeros(60*60*16*app.eegMODsf,app.chanNUMb(2)); % 16 Hours
                    tmpEMGarray = zeros(60*60*16*app.eegMODsf,app.chanNUMb(3)); % 16 Hours

                    chanTabLabs = cell(1,3);
                    aoTabLabs = cell(1,3);

                    for fi = 1:length(app.fileLIST)

                        % Update progress, report current estimate
                        progBAR.Value = fi/length(app.fileLIST);
                        progBAR.Message = sprintf('%1.0f',fi);

                        % file name
                        curFile = app.fileLIST{fi};
                        % Load all channels [MAY NEED TO UPDATE with MICRODRIVE]
                        load(curFile,app.varlist{:});

                        for ci = 1:length(app.chansleep)

                            chanChoose = app.chansleep{ci};
                            chanTabLabs{ci} = app.labelTab.ID(ismember(app.labelTab.Label,chanChoose));
                            aoTabLabs{ci} = app.labelTab.AO_ID(ismember(app.labelTab.Label,chanChoose));

                            % Extract variables relevant to channel
                            % Raw data and sampling frequency
                            % Get channel list
                            for chi = 1:length(aoTabLabs{ci})

                                tmpChan = aoTabLabs{ci}(ismember(aoTabLabs{ci},aoTabLabs{ci}(chi)));
                                tmpRows = app.varlist(contains(app.varlist,tmpChan));
                                rawRow = tmpRows{1};

                                chanNums = 1:length(chanTabLabs{ci});

                                dat1 = transpose(eval(rawRow));
                                switch chanChoose
                                    case 'EEG'
                                        progBAR.Title = 'EEG Data...';

                                        lastLOC = find(tmpEEGarray(:,length(chanNums)),1,'last');

                                        if isempty(lastLOC)
                                            lastLOC = 0 + 1;
                                        else
                                            lastLOC = lastLOC + 1;
                                        end

                                        % trim off sample points prior to TTL
                                        if fi == 1

                                            timeOFFset = CDIG_IN_1_TimeBegin - CDBS_0_TimeBegin;
                                            ttLSamp = round(timeOFFset*1375);
                                            dat1 = dat1(ttLSamp:numel(dat1));

                                        end

                                        curLeng = length(dat1)-1;
                                        tmpEEGarray(lastLOC:lastLOC+curLeng ,chi) = dat1;

                                    case 'EOG'
                                        progBAR.Title = 'EOG Data...';

                                        lastLOC = find(tmpEOGarray(:,length(chanNums)),1,'last');

                                        if isempty(lastLOC)
                                            lastLOC = 0 + 1;
                                        else
                                            lastLOC = lastLOC + 1;
                                        end

                                        % trim off sample points prior to TTL
                                        if fi == 1

                                            timeOFFset = CDIG_IN_1_TimeBegin - CDBS_0_TimeBegin;
                                            ttLSamp = round(timeOFFset*1375);
                                            dat1 = dat1(ttLSamp:numel(dat1));

                                        end

                                        curLeng = length(dat1)-1;
                                        tmpEOGarray(lastLOC:lastLOC+curLeng ,chi) = dat1;

                                    case 'EMG'
                                        progBAR.Title = 'EMG Data...';

                                        lastLOC = find(tmpEMGarray(:,length(chanNums)),1,'last');

                                        if isempty(lastLOC)
                                            lastLOC = 0 + 1;
                                        else
                                            lastLOC = lastLOC + 1;
                                        end

                                        % trim off sample points prior to TTL
                                        if fi == 1

                                            timeOFFset = CDIG_IN_1_TimeBegin - CDBS_0_TimeBegin;
                                            ttLSamp = round(timeOFFset*1375);
                                            dat1 = dat1(ttLSamp:numel(dat1));

                                        end

                                        curLeng = length(dat1)-1;
                                        tmpEMGarray(lastLOC:lastLOC+curLeng ,chi) = dat1;

                                end
                            end
                        end
                    end
                    % Close the dialog box
                    close(progBAR)

                    % Clean up array EEG
                    lastZEEG = find(tmpEEGarray(:,1),1,'last') + 1;
                    tmpEEGarray = tmpEEGarray(1:lastZEEG,:);

                    % Clean up array EOG
                    lastZEOG = find(tmpEOGarray(:,1),1,'last') + 1;
                    tmpEOGarray = tmpEOGarray(1:lastZEOG,:);

                    % Clean up array EMG
                    lastZEMG = find(tmpEMGarray(:,1),1,'last') + 1;
                    tmpEMGarray = tmpEMGarray(1:lastZEMG,:);

                    % Create Timetables -------------------------------------------

                    % EEG ********************************************************
                    eegPB = uiprogressdlg(app.UIFigure,'Title','Processing EEG',...
                        'Indeterminate','on');
                    drawnow

                    tmpEEGtab = array2table(tmpEEGarray);
                    tmpEEGtab.Properties.VariableNames = chanTabLabs{1};
                    app.eegTTraw = table2timetable(tmpEEGtab,'SampleRate',app.eegMODsf);
                    app.eegProc = app.eegTTraw;

                    % Normalize
                    eegPB.Message = sprintf('%s','Normalize');
                    eegTTrawN = normalize(app.eegTTraw);
                    % Band Stop notch
                    eegPB.Message = sprintf('%s','Notch Filter');
                    eegNOTCH = eegTTrawN;
                    for li = 1:size(eegTTrawN,2)
                        tmpEEGraw = table2array(eegTTrawN(:,li));
                        tmpEEGnotch = spectrumInterpolation(tmpEEGraw, app.eegMODsf, 60, 3, 1);
                        tmpEEGntab = array2table(tmpEEGnotch);
                        eegNOTCH(:,li) = tmpEEGntab;
                    end

                    % High Filter
                    eegPB.Message = sprintf('%s','High Pass Filter');

                    eegTTppHp = eegNOTCH;
                    for li = 1:size(eegTTppHp,2)
                        eegTTpTmp = table2array(eegTTppHp(:,li));
                        eegTTppHp{:,li} = highpass(eegTTpTmp,0.2,app.eegMODsf,'ImpulseResponse','iir','Steepness',0.8);
                    end
                    % Low Filter
                    eegPB.Message = sprintf('%s','Low Pass Filter');
                    eegTTppLp = lowpass(eegTTppHp,35); % Only for RC+S
                    eegTTppSm = eegTTppLp;

                    % Downsample
                    eegPB.Message = sprintf('%s','Downsample 100Hz');
                    eegTTppDs = retime(eegTTppSm,'regular','mean','SampleRate',100);

                    % Preprocess Signal
                    app.eegTTpp = eegTTppDs;
                    close(eegPB)


                    % EOG ********************************************************
                    eogPB = uiprogressdlg(app.UIFigure,'Title','Processing EOG',...
                        'Indeterminate','on');
                    drawnow

                    tmpEOGtab = array2table(tmpEOGarray);
                    tmpEOGtab.Properties.VariableNames = chanTabLabs{2};
                    app.eogTTraw = table2timetable(tmpEOGtab,'SampleRate',app.eegMODsf);

                    % Normalize
                    eogPB.Message = sprintf('%s','Normalize');
                    eogTTrawN = normalize(app.eogTTraw);
                    % Band Stop notch
                    eogPB.Message = sprintf('%s','Notch Filter');

                    eogNOTCH = eogTTrawN;
                    for li = 1:size(eogTTrawN,2)
                        tmpEOGraw = table2array(eogTTrawN(:,li));
                        tmpEOGnotch = spectrumInterpolation(tmpEOGraw, app.eegMODsf, 60, 3, 1);
                        tmpEOGntab = array2table(tmpEOGnotch);
                        eogNOTCH(:,li) = tmpEOGntab;
                    end

                    % Low Filter
                    eogPB.Message = sprintf('%s','Low Pass Filter');
                    eogTTppLp = lowpass(eogNOTCH,35);
  
                    % High Filter
                    eogPB.Message = sprintf('%s','High Pass Filter');
                    %                     lfpTTppHp = highpass(lfpNOTCH,0.2); % Only for RC+S

                    eogTTppHp = eogTTppLp;
                    for li = 1:size(eogTTppHp,2)
                        eogTTpTmp = table2array(eogTTppHp(:,li));
                        eogTTppHp{:,li} = highpass(eogTTpTmp,0.2,app.eegMODsf,'ImpulseResponse','iir','Steepness',0.8);
                    end
                    eogTTppSm = eogTTppLp;

                    % Downsample
                    eogPB.Message = sprintf('%s','Downsample 100Hz');
                    eogTTppDs = retime(eogTTppSm,'regular','mean','SampleRate',100);
                    % Preprocess Signal
                    app.eogTTpp = eogTTppDs;

                    close(eogPB)

                    % EMG ********************************************************
                    emgPB = uiprogressdlg(app.UIFigure,'Title','Processing EMG',...
                        'Indeterminate','on');
                    drawnow

                    tmpEMGtab = array2table(tmpEMGarray);
                    tmpEMGtab.Properties.VariableNames = chanTabLabs{3};
                    app.emgTTraw = table2timetable(tmpEMGtab,'SampleRate',app.eegMODsf);
                    % Normalize
                    emgPB.Message = sprintf('%s','Normalize');
                    emgTTrawN = normalize(app.emgTTraw);
                    % Band Stop notch
                    emgPB.Message = sprintf('%s','Notch Filter');
                    emgNOTCH = emgTTrawN;
                    for li = 1:size(emgTTrawN,2)
                        tmpEMGraw = table2array(emgTTrawN(:,li));
                        tmpEMGnotch = spectrumInterpolation(tmpEMGraw, app.eegMODsf, 60, 3, 1);
                        tmpEMGntab = array2table(tmpEMGnotch);
                        emgNOTCH(:,li) = tmpEMGntab;
                    end

                    % Low Filter
                    emgPB.Message = sprintf('%s','Low Pass Filter');
                    emgTTppLp = lowpass(emgNOTCH,100);
                    % High Filter
                    emgPB.Message = sprintf('%s','High Pass Filter');

                    emgTTppHp = emgTTppLp;
                    for li = 1:size(emgTTppHp,2)
                        emgTTpTmp = table2array(emgTTppHp(:,li));
                        emgTTppHp{:,li} = highpass(emgTTpTmp,10,app.eegMODsf,'ImpulseResponse','iir','Steepness',0.8);
                    end
                    emgTTppSm = emgTTppHp;

                    % Downsample
                    emgPB.Message = sprintf('%s','Downsample 100Hz');
                    emgTTppDs = retime(emgTTppSm,'regular','mean','SampleRate',100);
                    % Preprocess Signal
                    app.emgTTpp = emgTTppDs;

                    close(emgPB)

                    % Create FINAL Timetables -------------------------------------
                    eegTABf = createBINS(app,'eeg',chanTabLabs{1},100);
                    eogTABf = createBINS(app,'eog',chanTabLabs{2},100);
                    emgTABf = createBINS(app,'emg',chanTabLabs{3},100);

                    % Create FINAL EEG/EOG/EMG Timetables -------------------------
                    TT = [eegTABf,eogTABf,emgTABf];
                    % Add Sleep Score raters
                    TT.LW = repmat({''},height(TT),1);
                    TT.CK = repmat({''},height(TT),1);
                    TT.ST = repmat({''},height(TT),1);
                    TT.MS = repmat({''},height(TT),1);

                    actimeVec = transpose(0:seconds(30):seconds((height(TT)-1)*30)) + app.recSTART;
                    TT.ActTime = actimeVec;
                    % SAVE data
                    cd(app.saveLOC)
                    app.fname = [app.outP{1},'_',app.outP{2},'_',app.outP{3}];
                    app.fnameMAT = [app.fname,'.mat'];

                    save(app.fnameMAT,'TT')

                case 'lfp'
                    % CD back to raw data
                    cd(app.fileLOC)


                    app.tasK.Text = 'FULL';

                    progBAR = uiprogressdlg(app.UIFigure,'Title','Combining Files...',...
                        'Message','1');
                    drawnow

                    % LFP
                    tmpLFParray = zeros(60*60*16*app.lfpMODsf,app.chanNUMb_LFP(1)); % 14 Hours
                    chanTabLabs_LFP = cell(1,1);
                    aoTabLabs_LFP = cell(1,1);

                    % LFP Processing
                    for fi = 1:length(app.fileLIST)
                        % Update progress, report current estimate
                        progBAR.Value = fi/length(app.fileLIST);
                        progBAR.Message = sprintf('%1.0f',fi);
                        progBAR.Title = 'LFP Data...';
                        % file name
                        curFile = app.fileLIST{fi};
                        % Load all channels
                        load(curFile,app.varlist{:});

                        for ci = 1:length(app.chansleep_LFP)

                            chanChooselfp = app.chansleep_LFP{ci};
                            chanTabLabs_LFP{ci} = app.labelTab_LFP.ID(ismember(app.labelTab_LFP.Label,chanChooselfp));
                            aoTabLabs_LFP{ci} = app.labelTab_LFP.AO_ID(ismember(app.labelTab_LFP.Label,chanChooselfp));

                            for chi = 1:length(aoTabLabs_LFP{ci})

                                tmpChanlfp = aoTabLabs_LFP{ci}(ismember(aoTabLabs_LFP{ci},aoTabLabs_LFP{ci}(chi)));
                                tmpRowslfp = app.varlist(contains(app.varlist,tmpChanlfp));
                                rawRowlfp = tmpRowslfp{1};

                                chanNumslfp = 1:length(chanTabLabs_LFP{ci});

                                lfptemp = transpose(eval(rawRowlfp));

                                lastLOClfp = find(tmpLFParray(:,length(chanNumslfp)),1,'last');

                                if isempty(lastLOClfp)
                                    lastLOClfp = 0 + 1;
                                else
                                    lastLOClfp = lastLOClfp + 1;
                                end

                                % trim off sample points prior to TTL
                                if fi == 1

                                    timeOFFset = CDIG_IN_1_TimeBegin - CDBS_0_TimeBegin;
                                    ttLSamp = round(timeOFFset*1375);
                                    lfptemp = lfptemp(ttLSamp:numel(lfptemp));

                                end

                                curLeng = length(lfptemp)-1;
                                tmpLFParray(lastLOClfp:lastLOClfp+curLeng ,chi) = lfptemp;
                            end
                        end
                    end

                    % Close the dialog box
                    close(progBAR)

                    % Clean up array LFP
                    lastZLFP = find(tmpLFParray(:,1),1,'last') + 1;
                    tmpLFParray = tmpLFParray(1:lastZLFP,:);

                    % LFP ********************************************************
                    lfpPB = uiprogressdlg(app.UIFigure,'Title','Processing LFP',...
                        'Indeterminate','on');
                    drawnow

                    tmpLFPtab = array2table(tmpLFParray);
                    tmpLFPtab.Properties.VariableNames = chanTabLabs_LFP{1};
                    app.lfpTTraw = table2timetable(tmpLFPtab,'SampleRate',app.lfpMODsf);

                    % Normalize
                    lfpPB.Message = sprintf('%s','Normalize');
                    lfpTTrawN = normalize(app.lfpTTraw);
                    % Band Stop notch
                    lfpPB.Message = sprintf('%s','Notch Filter');
                    lfpNOTCH = lfpTTrawN;
                    for li = 1:size(lfpTTrawN,2)
                        tmpLFPraw = table2array(lfpTTrawN(:,li));
                        tmpLFPnotch = spectrumInterpolation(tmpLFPraw, app.lfpMODsf, 60, 3, 1);
                        tmpLFPntab = array2table(tmpLFPnotch);
                        lfpNOTCH(:,li) = tmpLFPntab;
                    end

                    % High Filter
                    lfpPB.Message = sprintf('%s','High Pass Filter');
                    %                     lfpTTppHp = highpass(lfpNOTCH,0.2); % Only for RC+S

                    lfpTTppHp = lfpNOTCH;
                    for li = 1:size(lfpTTppHp,2)
                        lfpTTpTmp = table2array(lfpTTppHp(:,li));
                        lfpTTppHp{:,li} = highpass(lfpTTpTmp,0.2,1375,'ImpulseResponse','iir','Steepness',0.8);
                    end

                    % Low Filter
                    lfpPB.Message = sprintf('%s','Low Pass Filter');
                    lfpTTppLp = lowpass(lfpTTppHp,250); % Only for RC+S
                    lfpTTppSm = lfpTTppLp;

                    % Downsample
                    % CREATE RC+S version
                    % CREATE RAW version - no downsampling
                    lfpPB.Message = sprintf('%s','Downsample 250Hz');
                    lfpTTppDs = retime(lfpTTppSm,'regular','mean','SampleRate',250);
                    % Preprocess Signal
                    app.lfpTTpp = lfpTTppDs;
                    app.lfpTTNdnS = lfpTTppHp;

                    close(lfpPB)

                    lfpTABf = createBINS(app,'lfp',chanTabLabs_LFP{1},250);
                    lfpTABfND = createBINS(app,'lfpr',chanTabLabs_LFP{1},1375);

                    % Add ActTime % USE AO CONVERTER TO GET START TIME OF FIRST
                    % FILE

                    % Create FINAL LFP Timetables -------------------------
                    LFPTT = lfpTABf;

                    actimeVec = transpose(0:seconds(30):seconds((height(LFPTT)-1)*30)) + app.recSTART;

                    LFPTT.ActTime = actimeVec;
                    LFPTTRaw = lfpTABfND;
                    LFPTTRaw.ActTime = actimeVec;
                    % SAVE OUT
                    cd(app.saveLOC)

                    app.fname = [app.outP{1},'_',app.outP{2},'_',app.outP{3}];
                    fnameMATlfp = [app.fname,'_LFP.mat'];
                    fnameMATlfpR = [app.fname,'_LFPraw.mat'];

                    save(fnameMATlfp,'LFPTT')
                    save(fnameMATlfpR,'LFPTTRaw','-v7.3')

                    app.INFOLabel.Text = "DONE";
                    app.tasK.Text = 'DONE';

                case 'lfpNF'
                    % CD back to raw data
                    cd(app.fileLOC)


                    app.tasK.Text = 'FULL';

                    progBAR = uiprogressdlg(app.UIFigure,'Title','Combining Files...',...
                        'Message','1');
                    drawnow

                    % LFP
                    tmpLFParray = zeros(60*60*16*app.lfpMODsf,app.chanNUMb_LFP(1)); % 14 Hours
                    chanTabLabs_LFP = cell(1,1);
                    aoTabLabs_LFP = cell(1,1);

                    % LFP Processing
                    for fi = 1:length(app.fileLIST)
                        % Update progress, report current estimate
                        progBAR.Value = fi/length(app.fileLIST);
                        progBAR.Message = sprintf('%1.0f',fi);
                        progBAR.Title = 'LFP Data...';
                        % file name
                        curFile = app.fileLIST{fi};
                        % Load all channels
                        load(curFile,app.varlist{:});

                        for ci = 1:length(app.chansleep_LFP)

                            chanChooselfp = app.chansleep_LFP{ci};
                            chanTabLabs_LFP{ci} = app.labelTab_LFP.ID(ismember(app.labelTab_LFP.Label,chanChooselfp));
                            aoTabLabs_LFP{ci} = app.labelTab_LFP.AO_ID(ismember(app.labelTab_LFP.Label,chanChooselfp));

                            for chi = 1:length(aoTabLabs_LFP{ci})

                                tmpChanlfp = aoTabLabs_LFP{ci}(ismember(aoTabLabs_LFP{ci},aoTabLabs_LFP{ci}(chi)));
                                tmpRowslfp = app.varlist(contains(app.varlist,tmpChanlfp));
                                rawRowlfp = tmpRowslfp{1};

                                chanNumslfp = 1:length(chanTabLabs_LFP{ci});

                                lfptemp = transpose(eval(rawRowlfp));

                                lastLOClfp = find(tmpLFParray(:,length(chanNumslfp)),1,'last');

                                if isempty(lastLOClfp)
                                    lastLOClfp = 0 + 1;
                                else
                                    lastLOClfp = lastLOClfp + 1;
                                end

                                % trim off sample points prior to TTL
                                if fi == 1

                                    timeOFFset = CDIG_IN_1_TimeBegin - CDBS_0_TimeBegin;
                                    ttLSamp = round(timeOFFset*1375);
                                    lfptemp = lfptemp(ttLSamp:numel(lfptemp));

                                end

                                curLeng = length(lfptemp)-1;
                                tmpLFParray(lastLOClfp:lastLOClfp+curLeng ,chi) = lfptemp;
                            end
                        end
                    end

                    % Close the dialog box
                    close(progBAR)

                    % Clean up array LFP
                    lastZLFP = find(tmpLFParray(:,1),1,'last') + 1;
                    tmpLFParray = tmpLFParray(1:lastZLFP,:);

                    % LFP ********************************************************
                    lfpPB = uiprogressdlg(app.UIFigure,'Title','Processing LFP',...
                        'Indeterminate','on');
                    drawnow

                    tmpLFPtab = array2table(tmpLFParray);
                    tmpLFPtab.Properties.VariableNames = chanTabLabs_LFP{1};
                    app.lfpTTrawNF = table2timetable(tmpLFPtab,'SampleRate',app.lfpMODsf);
                    
                    lfpPB.Message = sprintf('%s','Downsample 250Hz');

                    app.lfpTTNdnS = app.lfpTTrawNF;

                    close(lfpPB)

                    lfpTABfND = createBINS(app,'lfpr',chanTabLabs_LFP{1},1375);
                    LFPTTRaw = lfpTABfND;
                    actimeVec = transpose(0:seconds(30):seconds((height(LFPTTRaw)-1)*30)) + app.recSTART;
                    LFPTTRaw.ActTime = actimeVec;

                    app.fname = [app.outP{1},'_',app.outP{2},'_',app.outP{3}];
                    fnameMATlfpRnf = [app.fname,'_LFPrawNF.mat'];

                    save(fnameMATlfpRnf,'LFPTTRaw','-v7.3')

                    app.INFOLabel.Text = "DONE";
                    app.tasK.Text = 'DONE';


            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LOAD
        function LOADButtonPushed(app, event)
            app.fileLOC = uigetdir();

            cd(app.fileLOC)

            mdir = dir('*.mat');
            mdir2 = {mdir.name};
            app.fileLIST = mdir2;

            % Use Matfile function to import the appropriate files
            matob = matfile(app.fileLIST{1});
            app.varlist = who(matob);

            % DETERIME ID for EEG , EOG , EMG -----------------------------
            chanLab = {'EEG','EEG','EEG','EEG','EEG','EEG','EEG','EEG','EEG',...
                'EEG','EEG','EOG','EOG','EMG','EMG','EMG'};
            chanID = {'F3','Fz','F4','A1','C3','Cz','C4','A2','O1','Oz','O2',...
                'EOG1','EOG2','Chin1','Chin2','ChinZ'};
            aoID = {'F3','Fz','F4','A1','C3','Cz','C4','A2','O1','Oz','O2',...
                'CEOG_1','CEOG_2','CChin_1','CChin_2','CChin_Z'};

            app.labelTab = table(transpose(aoID),transpose(chanLab),...
                transpose(chanID),'VariableNames',{'AO_ID',...
                'Label','ID'});

            % Convert to Time tables with appropriate sample frequencies
            app.chansleep = {'EEG','EOG','EMG'};
            app.chanNUMb = zeros(1,3);
            for cNi = 1:length(app.chansleep)
                app.chanNUMb(cNi) = sum(ismember(app.labelTab.Label,app.chansleep{cNi}));
            end

            app.eegMODsf = 1375;

            % DETERMINE ID for LFP ----------------------------------------
            chanLab_LFP = {'DBS','DBS','DBS','DBS'};
            chanID_LFP = {'0','1','2','3'};
            aoID_LFP = {'CDBS_0','CDBS_1','CDBS_2','CDBS_3'};

            app.labelTab_LFP = table(transpose(aoID_LFP),transpose(chanLab_LFP),...
                transpose(chanID_LFP),'VariableNames',{'AO_ID',...
                'Label','ID'});

            % Convert to Time tables with appropriate sample frequencies
            app.chansleep_LFP = {'DBS'};
            app.chanNUMb_LFP = zeros(1,1);
            for cNi = 1:length(app.chansleep_LFP)
                app.chanNUMb_LFP(cNi) = sum(ismember(app.labelTab_LFP.Label,app.chansleep_LFP{cNi}));
            end

            app.lfpMODsf = 1375;

            app.INFOLabel.Text = "File LOADED";

            fileLOCparts = strsplit(app.fileLOC,'\');
            foldName = fileLOCparts{end};
            app.datE.Text = foldName;

            % Creat actual time
            promptT = {'Create Act Time'};
            dlgtitleT = {'Year', 'Month','Day','Hour','min','sec'};
            definputT = {'2021','1','1','14','32','54'};
            dimsT = [1 15; 1 15; 1 15 ; 1 15 ; 1 15; 1 15];
            actTIME = inputdlg(dlgtitleT,promptT{1},dimsT,definputT);
            actNm = cellfun(@(x) str2double(x), actTIME);
            app.recSTART = datetime(actNm(1),actNm(2),actNm(3),actNm(4),actNm(5),actNm(6));

            app.saveLOC = uigetdir();

            prompt = {'Enter file name'};
            dlgtitle = {'PatientID', 'Institution','Night#'};
            definput = {'1','UNMC','1'};
            dims = [1 50; 1 50; 1 50];
            app.outP = inputdlg(dlgtitle,prompt{1},dims,definput);

        end

        % Button pushed function: PROCESSButton
        function PROCESSButtonPushed(app, event)
            % Initiate progress bar

                getCurOpt = app.dataOptions.SelectedObject.Text;
                switch getCurOpt
                    case 'EEG Only'
                        selectSignalProcess(app, 'psg')
                    case 'LFP Only'
                        selectSignalProcess(app, 'lfp')
                    case 'EEG + LFP'
                        selectSignalProcess(app, 'psg')
                        selectSignalProcess(app, 'lfp')
                    case 'LFP No filter'
                        selectSignalProcess(app, 'lfpNF')
                    case 'LFP NF + EEG'
                        selectSignalProcess(app, 'psg')
                        selectSignalProcess(app, 'lfpNF')
                end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChannelMenu = uimenu(app.UIFigure);
            app.ChannelMenu.Text = 'Channel';
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.LOAD = uibutton(app.UIFigure, 'push');
            app.LOAD.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOAD.Text = 'LOAD';
            app.PROCESSButton = uibutton(app.UIFigure, 'push');
            app.PROCESSButton.ButtonPushedFcn = createCallbackFcn(app, @PROCESSButtonPushed, true);
            app.PROCESSButton.Text = 'PROCESS';
            app.INFOLabel = uilabel(app.UIFigure);
            app.INFOLabel.Text = 'INFO';
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusPanel.Title = 'Status';
            app.tasK = uilabel(app.StatusPanel);
            app.datE = uilabel(app.StatusPanel);
            app.datE.Text = 'Label2';
            app.dataOptions = uibuttongroup(app.UIFigure);
            app.dataOptions.Title = 'Analysis Options';
            app.EEGOnly = uitogglebutton(app.dataOptions);
            app.EEGOnly.Text = 'EEG Only';
            app.EEGOnly.Value = true;
            app.LFPOnly = uitogglebutton(app.dataOptions);
            app.LFPOnly.Text = 'LFP Only';
            app.EEGLFP = uitogglebutton(app.dataOptions);
            app.EEGLFP.Text = 'EEG + LFP';
            app.LFPnoF = uitogglebutton(app.dataOptions);
            app.LFPnoF.Text = 'LFP No filter';
            app.LFPnoFEEG = uitogglebutton(app.dataOptions);
            app.LFPnoFEEG.Text = 'LFP NF + EEG';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SleepDataCreateTT_ALL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end