classdef vidTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        EpochSpinner       matlab.ui.control.Spinner
        EpochSpinnerLabel  matlab.ui.control.Label
        PlayButton         matlab.ui.control.Button
        LoadvideoButton    matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end


    properties (Access = private)
        vidFile % Description
        vidLoc
        vidEpoch
        totalFrame
        VideoFile
        fRate
        frPerEp
        numBins
        VidINDs
        curEpoch
        Fwidth
        Fheight
        
    end
    
    methods (Access = private)
        
        function vidEpoch = grabVidEpoch(app , epochN)
            
            vidEpoch = zeros(app.Fwidth,app.Fheight,app.frPerEp,'uint8');

            startF = app.VidINDs(epochN,1);
            stopF = app.VidINDs(epochN,2);

            tmpFrames = read(app.VideoFile,[startF stopF]);

            for ti = 1:app.frPerEp

                ttFrm = im2gray(tmpFrames(:,:,:,ti));
                vidEpoch(:,:,ti) = ttFrm;

            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadvideoButton
        function LoadvideoButtonPushed(app, event)

            
            app.vidLoc = uigetdir();

            cd(app.vidLoc)

            vidDir = dir('*.mp4');
            vidDir1 = {vidDir.name};
            app.vidFile = vidDir1{1};
            
            app.VideoFile = VideoReader(app.vidFile);
            pause(0.2)

            % Video File info
            % Number of frames
            app.totalFrame = app.VideoFile.NumFrames;
            % Frame rate
            app.fRate = 7;
            % Frames per epoch
            app.frPerEp = app.fRate*30;

            % Total number of Epoch Bins
            app.numBins = ceil(app.totalFrame/app.frPerEp);
            binSTOP = app.numBins - 1;
            maxWoBin = app.frPerEp*binSTOP;

            startS = transpose(1:app.frPerEp:maxWoBin);
            stopS = [startS(2:end) - 1 ; maxWoBin];
            allVinds = [startS , stopS];
            allVinds(end+1,1) = allVinds(end,2) + 1;
            allVinds(end,2) = app.totalFrame;
            app.VidINDs = allVinds;
            
            % Get frame width and height
            sampleFrame = read(app.VideoFile,1);
            
            app.Fwidth = size(sampleFrame,1);
            app.Fheight = size(sampleFrame,2);
            
            app.vidEpoch = grabVidEpoch(app , 1);
            
            app.curEpoch = 1;
            app.EpochSpinner.Value = 1;
            
            image(app.UIAxes,app.vidEpoch(:,:,1))
            colormap(app.UIAxes,'gray')


        end

        % Value changed function: EpochSpinner
        function EpochSpinnerValueChanged(app, event)
            app.curEpoch = app.EpochSpinner.Value;
            
            if app.curEpoch < 1
                app.curEpoch = 1;
                app.EpochSpinner.Value = 1;
                return
            end
            
            if app.curEpoch > app.totalFrame
                app.curEpoch = app.totalFrame;
                app.EpochSpinner.Value = app.totalFrame;
                return
            end
                
            app.vidEpoch = grabVidEpoch(app , app.curEpoch);

            image(app.UIAxes,app.vidEpoch(:,:,1))
            colormap(app.UIAxes,'gray')

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            
            for vi = 1:size(app.vidEpoch,3)
                vidFrame = app.vidEpoch(:,:,vi);
                image(app.UIAxes, vidFrame);
                pause(1/7);
            end
  
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LoadvideoButton = uibutton(app.UIFigure, 'push');
            app.LoadvideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadvideoButtonPushed, true);
            app.LoadvideoButton.Text = 'Load video';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.EpochSpinnerLabel = uilabel(app.UIFigure);
            app.EpochSpinnerLabel.Text = 'Epoch';
            app.EpochSpinner = uispinner(app.UIFigure);
            app.EpochSpinner.ValueChangedFcn = createCallbackFcn(app, @EpochSpinnerValueChanged, true);
            app.EpochSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vidTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end